classdef mpcfailing_control101 < matlab.apps.AppBase
    %MPCFAILING_CONTROL101
    %   This virtual laboratory allows the investigation finite horizon MPC
    %   on some specific case studies with challenging dynamics. The
    %   intention is to allow users to visualise how closed-loop behaviour
    %   can be highly sensitive to horizon choices and weights and thus
    %   decisions and understanding on what constitute good choices are
    %   very important.
    %   
    %   It is also an indication of scenarios where finite horizon MPC may
    %   be a poor choice.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        UncertaintyDropDown            matlab.ui.control.DropDown
        UncertaintyDropDownLabel       matlab.ui.control.Label
        DisturbancesDropDown           matlab.ui.control.DropDown
        DisturbancesDropDownLabel      matlab.ui.control.Label
        ModelchoiceDropDown            matlab.ui.control.DropDown
        ModelchoiceDropDownLabel       matlab.ui.control.Label
        ConfigurationLabel             matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        Weightingpower10pSlider        matlab.ui.control.Slider
        Weightingpower10pSliderLabel   matlab.ui.control.Label
        ControlhorizonSlider           matlab.ui.control.Slider
        ControlhorizonSliderLabel      matlab.ui.control.Label
        PredictionhorizonSlider        matlab.ui.control.Slider
        PredictionhorizonSliderLabel   matlab.ui.control.Label
        ControllersettingsLabel        matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        RefreshButton                  matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        SuminputchangesEditField       matlab.ui.control.NumericEditField
        SuminputchangesEditFieldLabel  matlab.ui.control.Label
        SumoutputerrorsEditField       matlab.ui.control.NumericEditField
        SumoutputerrorsEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GPCwithchallengingdynamicsLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalsize % Description
        
        legstring=cell(1)
        nlegstring=1;
    end
    
    methods (Access = private)
        
        function  disablebuttons(app)
            set(app.SimulateButton,'Enable','off')
            set(app.ModelchoiceDropDown, 'Enable', 'off');
             set(app.DisturbancesDropDown, 'Enable', 'off');
            set(app.UncertaintyDropDown, 'Enable', 'off');
            
        end
        
        function enablebuttons(app)
            set(app.SimulateButton,'Enable','on')
            set(app.ModelchoiceDropDown, 'Enable', 'on');
             set(app.DisturbancesDropDown, 'Enable', 'on');
            set(app.UncertaintyDropDown, 'Enable', 'on');
        end


        % Forms optimal MPC control law and the associated predictions at
        % the current sample
        function [Duopt,uopt,ypredall,ypred] = getpredictions(app,a,b,ny,nu,lambda,ny2,y,Du,r,u,k)
            
            na = length(a);
            nb=length(b);
            [H,P,Q] = mpc_predmat_toeplitz_control101(a,b,ny);   % pred over horizon
            [H2,P2,Q2] = mpc_predmat_toeplitz_control101(a,b,ny2);   % full prediction
            HH=tril(ones(nu,nu));
            [Nk,Dk,Pr,S,X,Prlong] = mpc_law_control101(H,P,Q,nu,lambda,1,1);
            
            % Find input and output prediction
            ypast=y(k:-1:k-na+1)';
            Dupast = Du(k-1:-1:k-nb+1)';
            Duopt = Pr*r(k+1) -Nk*ypast-Dk*Dupast;
            uopt=HH*Duopt+u(k-1);
            ypredall = H2(:,1:nu)*Duopt+Q2*ypast+P2*Dupast;
            ypred = H(:,1:nu)*Duopt+Q*ypast+P*Dupast;
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.AutoResizeChildren='off';   
            movegui(app.UIFigure)
            
            % Open the manual
            if exist('mpcfailing_control101_manual_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open mpcfailing_control101_manual_mycopy.mlx
            else
                p1 = which('mpcfailing_control101_manual.mlx');
                p2 = strcat(pwd,'/mpcfailing_control101_manual_mycopy.mlx');
                copyfile(p1,p2)
                open mpcfailing_control101_manual_mycopy.mlx
                clear p1 p2
            end
            

            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);
          
            if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
           position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0;
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.legstring=cell(1);
            app.legstring{1}='Reference';
            app.nlegstring=1;
            hold(app.UIAxes2,'off');
            plot(app.UIAxes2,1,1,'w')
            hold(app.UIAxes4,'off')
            plot(app.UIAxes4,1,1,'w')            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           

            % Controller settings and external signals
            ny=app.PredictionhorizonSlider.Value;
            nu=app.ControlhorizonSlider.Value;
            lambda = 10^(app.Weightingpower10pSlider.Value);
            runtime=100;
            init=5;
            ref = [zeros(1,init),ones(1,runtime-init)];
            dist=[zeros(1,init+2),ones(1,runtime-init-2)*0.2];
            noise = [zeros(1,runtime)];
            tt=1:30;
            % Check for no disturbances
            disttext=', dist=N, ';
            if strcmp(app.DisturbancesDropDown.Value,'no'); 
                dist=dist*0;
                else
                disttext=', dist=Y, ';
            end

            % initialise plot
            if app.nlegstring==1;
                aa=plot(app.UIAxes4,tt,ref(tt),'r--','LineWidth',2);
                hold(app.UIAxes4,'on')
                app.legstring{1}='Reference';
            end

            % upload model
            switch app.ModelchoiceDropDown.Value
                case "Unstable"
                    a=[1 -1.2]; b=[0.2 0]; na=2;nb=2;
                    ap=[1 -1.22]; bp=[0.22, 0];
                case "Oscillatory"
                    a=[1,-1.5, 0.95]; b=[0.1 0.1]; na=3; nb=2;
                    ap=[1, -1.4, 0.95]; bp=[0.12, 0.11];
                    dist=dist/2;
                case "Stiff"
                    a=poly([0.98,0.6,0.5]); b=[0.05 0.03]; na=4;nb=2;
                    ap=poly([0.98,0.6,0.5]); bp=[0.04, 0.025];
                    dist=dist/4;
                case "Non-minimum phase"
                    a=poly([0.8,0.6,0.5]); b=[-0.2 0.3]/10; na=4;nb=2;
                    ap=poly([0.8,0.6,0.5]); bp=[-0.2, 0.25]/10;
                    dist=dist/4;
            end
            % Check for no uncertainty
            uncertext='uncert=N';
            if strcmp(app.UncertaintyDropDown.Value,'no'); 
                ap=a;bp=b;
            else
                uncertext='uncert=Y';
            end
            na=length(a);  % number of parameter values
            nb=length(b);

            %%% Compute and plot MPC closed-loop behaviour for 30 samples
            %%% Set point, disturbance and noise
            
            [ycl,ucl,Ducl,r] = mpc_simulate_limited_control101(b,a,bp,ap,nu,ny,lambda,1,ref,dist,noise);
            aaa=plot(app.UIAxes4,tt,ycl(tt),'LineWidth',2);
            stairs(app.UIAxes2,tt,ucl(tt),'LineWidth',2,'Color',aaa.Color);
            hold(app.UIAxes2,'on')

            % Update prediction costs
                    sumerrors = norm(r-ycl)^2;
                    suminputs = norm(Ducl)^2;
                    app.SumoutputerrorsEditField.Value=sumerrors;
                    app.SuminputchangesEditField.Value=suminputs;
                    J(app.nlegstring)=round(sumerrors+lambda*suminputs,5,'significant');

            % update legend
            lam=round(lambda,3,'significant');
            app.nlegstring=app.nlegstring+1;
            app.legstring{app.nlegstring}=['ny=',num2str(ny),...
                ', nu=',num2str(nu),', \lambda=',num2str(lambda),...
                ', J=',num2str(J(app.nlegstring-1)),disttext,uncertext];
            legend(app.UIAxes4,app.legstring,'Location','southeast','FontSize',10)


        end

        % Value changed function: PredictionhorizonSlider
        function PredictionhorizonSliderValueChanged(app, event)
            value = app.PredictionhorizonSlider.Value;
            value =round(value);
            if value<app.ControlhorizonSlider.Value;
                value =app.ControlhorizonSlider.Value+1;
            end
            app.PredictionhorizonSlider.Value=value;
            if value<5;
                app.PredictionhorizonSlider.Value=5;
            end

        end

        % Value changed function: ControlhorizonSlider
        function ControlhorizonSliderValueChanged(app, event)
            value = app.ControlhorizonSlider.Value;
            value =round(value);
            if value>app.PredictionhorizonSlider.Value;
                app.PredictionhorizonSlider.Value=value+1;
            end
            app.ControlhorizonSlider.Value=value;

        end

        % Value changed function: Weightingpower10pSlider
        function Weightingpower10pSliderValueChanged(app, event)
            value = app.Weightingpower10pSlider.Value;
            value = round(value *2)/2;
            app.Weightingpower10pSlider.Value=value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Closed-loop output')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Output')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Closed-loop input')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Input')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GPCwithchallengingdynamicsLabel = uilabel(app.GridLayout4);
            app.GPCwithchallengingdynamicsLabel.Text = 'GPC with challenging dynamics';
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.SumoutputerrorsEditFieldLabel = uilabel(app.GridLayout9);
            app.SumoutputerrorsEditFieldLabel.Text = 'Sum output errors';
            app.SumoutputerrorsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.SuminputchangesEditFieldLabel = uilabel(app.GridLayout9);
            app.SuminputchangesEditFieldLabel.Text = 'Sum input changes';
            app.SuminputchangesEditField = uieditfield(app.GridLayout9, 'numeric');
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SimulateButton = uibutton(app.GridLayout5, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.RefreshButton = uibutton(app.GridLayout5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout8);
            app.ControllersettingsLabel = uilabel(app.GridLayout3);
            app.ControllersettingsLabel.Text = 'Controller settings';
            app.PredictionhorizonSliderLabel = uilabel(app.GridLayout3);
            app.PredictionhorizonSliderLabel.Text = 'Prediction horizon';
            app.PredictionhorizonSlider = uislider(app.GridLayout3);
            app.PredictionhorizonSlider.ValueChangedFcn = createCallbackFcn(app, @PredictionhorizonSliderValueChanged, true);
            app.PredictionhorizonSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.PredictionhorizonSlider.Step = 10;
            app.PredictionhorizonSlider.Value = 10;
            app.ControlhorizonSliderLabel = uilabel(app.GridLayout3);
            app.ControlhorizonSliderLabel.Text = 'Control horizon';
            app.ControlhorizonSlider = uislider(app.GridLayout3);
            app.ControlhorizonSlider.Limits = [1 6];
            app.ControlhorizonSlider.MajorTicks = [1 2 3 4 5 6];
            app.ControlhorizonSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6'};
            app.ControlhorizonSlider.ValueChangedFcn = createCallbackFcn(app, @ControlhorizonSliderValueChanged, true);
            app.ControlhorizonSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6];
            app.ControlhorizonSlider.Value = 1;
            app.Weightingpower10pSliderLabel = uilabel(app.GridLayout3);
            app.Weightingpower10pSliderLabel.Text = 'Weighting power (10^p)';
            app.Weightingpower10pSlider = uislider(app.GridLayout3);
            app.Weightingpower10pSlider.Limits = [-3 3];
            app.Weightingpower10pSlider.ValueChangedFcn = createCallbackFcn(app, @Weightingpower10pSliderValueChanged, true);
            app.Weightingpower10pSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GridLayout7 = uigridlayout(app.GridLayout8);
            app.ConfigurationLabel = uilabel(app.GridLayout7);
            app.ConfigurationLabel.Text = 'Configuration';
            app.ModelchoiceDropDownLabel = uilabel(app.GridLayout7);
            app.ModelchoiceDropDownLabel.Text = 'Model choice';
            app.ModelchoiceDropDown = uidropdown(app.GridLayout7);
            app.ModelchoiceDropDown.Items = {'Unstable', 'Oscillatory', 'Stiff', 'Non-minimum phase'};
            app.ModelchoiceDropDown.Value = 'Unstable';
            app.DisturbancesDropDownLabel = uilabel(app.GridLayout7);
            app.DisturbancesDropDownLabel.Text = 'Disturbances';
            app.DisturbancesDropDown = uidropdown(app.GridLayout7);
            app.DisturbancesDropDown.Items = {'yes', 'no'};
            app.DisturbancesDropDown.Value = 'no';
            app.UncertaintyDropDownLabel = uilabel(app.GridLayout7);
            app.UncertaintyDropDownLabel.Text = 'Uncertainty';
            app.UncertaintyDropDown = uidropdown(app.GridLayout7);
            app.UncertaintyDropDown.Items = {'yes', 'no'};
            app.UncertaintyDropDown.Value = 'no';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mpcfailing_control101

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end