classdef conical_tank_control_control101 < matlab.apps.AppBase
    %CONICAL_TANK_CONTROL_CONTROL101
    %   This resource focuses on non-linear behaviour which will arise from
    %   some common non-linear components: 
    %   Input saturation   (inputs always have rate and absolute limits)
    %   Measurement noise
    %   parameterisation errors
    %   Anti-windup in a PID compensator
    %   
    %   The easiest way to explore this is using this app interface (see
    %   section 3), although the livescript manual (opened automatically)
    %   provides source code that users will find helpful to take more
    %   explicit control of the simulation. 
    %   
    %   The intention is to expose users to the non-linear components and
    %   their impact on closed-loop behaviour.   Underlying the virtual
    %   laboratory (app file) is a simulink file 
    %   nonlinear_tank_system_fbk_control101.slx  which users could open
    %   for interest. Take a copy in your own area if you want to edit this
    %   for further study.
    %   
    %   File written by J.A.Rossiter, University of Sheffield
    %        See website for more files like this.
    %       
    %   https://sites.google.com/sheffield.ac.uk/controleducation/mainindex
    %   
    %   A group of similar files can be downloaded
    %   through MATLAB add-ons with the toolbox name:  control101

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        BlockageButton                  matlab.ui.control.Button
        AntiwindupButton                matlab.ui.control.Button
        MeasurementnoiseButton          matlab.ui.control.Button
        SaturationButton                matlab.ui.control.Button
        SimulationparametersredmeansnotincludedLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        RefreshfigureButton             matlab.ui.control.Button
        CreateresponsesButton           matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ImpactofnonlinearcomponentsonapositioncontrolsystemLabel  matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        DisturbanceflowltrsSlider       matlab.ui.control.Slider
        DisturbanceflowltrsSliderLabel  matlab.ui.control.Label
        Inputratelimitltrs2Slider       matlab.ui.control.Slider
        Inputratelimitltrs2SliderLabel  matlab.ui.control.Label
        InputlimitltrsSlider            matlab.ui.control.Slider
        InputlimitltrsSliderLabel       matlab.ui.control.Label
        BlockageSlider                  matlab.ui.control.Slider
        BlockageSliderLabel             matlab.ui.control.Label
        NoisevarianceSlider             matlab.ui.control.Slider
        NoisevarianceSliderLabel        matlab.ui.control.Label
        UncertaintyandnonlinearityLabel  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        InitialdepthmSlider             matlab.ui.control.Slider
        InitialdepthmSliderLabel        matlab.ui.control.Label
        OutletresistanceRSlider         matlab.ui.control.Slider
        OutletresistanceRSliderLabel    matlab.ui.control.Label
        TankangledegSlider              matlab.ui.control.Slider
        TankangledegSliderLabel         matlab.ui.control.Label
        SystemparametersLabel           matlab.ui.control.Label
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

%     File written by J.A.Rossiter, University of Sheffield
%     See website for more files like this.
%     https://sites.google.com/sheffield.ac.uk/controleducation/mainindex
%     A simple interface to compar and contrast different PID auto-tuning algorithms 
%     A group of similar files can be downloaded through MATLAB add-ons
%     with the toolbox name:  control101
%     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%     
%     
    
    % The next section defines all the core variables used in the code and
    % that it may be convenient to have available in the various
    % subfunctions
    properties (Access = private)
        originalsize     % product of width and height for font sizing

        legstring=cell(1); % To store legends
        legstring2=cell(1); % To store legends

      
        % Simulation parameters which are fixed
        Initial_depth = 1;
        Targetchange = 0.5;
        Targetchangetime = 60;
        blockagetime=1800; % time that blockage introduced
        endtime=4200;     % simulation run time
        disturbanceflowtime=1200;
        disturbanceflow=0.002;
        Noise
        qss   % expected steady-state
        hss % initial depth
        switchpidtime=0;   % With or without anti-windup
        switchpid = 1;
        windupstate = 'off'
        blocksize = 0.2;
       
       
        % use for memory and legends
        Dumax;    % rate limits on input
        Dumin;
        umax;      % absolute limits on the input
        umin;
        Blockage 
        noisevalue
        saturationonoff=0  % keep track of input limits
        noiseonoff=0       % keep track of noise
        modellingonoff=0  % to keep track of blocakages
                
        % Model parameters needed by simulink file
        rho=1000;    % water density
        g=10;        % gravity
        A=2;         % tank area true
        Ai           % inverse of A
        Ae = 2.1;    % estimated tank area 
        R=10^4;      % constant needed to determine outflow
        Re = 10500;  %   estimated R
        C
        Ce
        Ctemp
        Cl
        Kp
        Ki
        theta  % tank angle
        theta2 % square of tangent
        tantheta
        tantheta2
        itantheta2
        alpha % linear model parameters
        beta
        

    end
    
    methods (Access = private)
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%% SUBFUNCTION FOR BASIC STEP RESPONSES

        function [yy,uu,tt] = determineresponses(app)
            % This function determines all the closed-loop step responses 
            simIn = Simulink.SimulationInput('conical_tank_system_control101_2023');
            
            simIn = simIn.setVariable('endtime',app.endtime);
            simIn = simIn.setVariable('hss',app.hss);
            simIn = simIn.setVariable('R',app.R);
            simIn = simIn.setVariable('C',app.C);
            simIn = simIn.setVariable('Ctemp',app.Ctemp);
            simIn = simIn.setVariable('qss',app.qss);
            simIn = simIn.setVariable('Ai',1/app.A);
            simIn = simIn.setVariable('Kp',app.Kp);
            simIn = simIn.setVariable('Ki',app.Ki);
            simIn = simIn.setVariable('Initial_target',app.InitialdepthmSlider.Value);
            simIn = simIn.setVariable('Targetchange',app.Targetchange);
            simIn = simIn.setVariable('Targetchangetime',app.Targetchangetime);
            simIn = simIn.setVariable('blockagetime',app.blockagetime); 
            simIn = simIn.setVariable('disturbanceflowtime',app.disturbanceflowtime);
            simIn = simIn.setVariable('endtime',app.endtime);
            simIn = simIn.setVariable('switchpidtime',app.switchpidtime);
            simIn = simIn.setVariable('switchpid',app.switchpid);
            simIn = simIn.setVariable('disturbanceflow',app.DisturbanceflowltrsSlider.Value/1000);
            simIn = simIn.setVariable('tantheta2',app.theta2);
            simIn = simIn.setVariable('itantheta2',app.itantheta2);
            simIn = simIn.setVariable('alpha',app.alpha);
            simIn = simIn.setVariable('beta',app.beta);


            % Apply saturation or not
            if app.saturationonoff==1;
            simIn = simIn.setVariable('umax',app.InputlimitltrsSlider.Value/1000);
            simIn = simIn.setVariable('umin',0);
            simIn = simIn.setVariable('Dumax',app.Inputratelimitltrs2Slider.Value/1000);
            simIn = simIn.setVariable('Dumin',-app.Inputratelimitltrs2Slider.Value/1000);
            app.umax=app.InputlimitltrsSlider.Value;
            app.Dumax=app.Inputratelimitltrs2Slider.Value;
            else % set values large enough to be irrelevant
            simIn = simIn.setVariable('umax',1);
            simIn = simIn.setVariable('umin',0);
            simIn = simIn.setVariable('Dumax',1);
            simIn = simIn.setVariable('Dumin',-1);
            app.umax=inf;
            app.Dumax=inf;
            end

          %  keyboard

            % Apply Noise or not
            if app.noiseonoff==0;
                simIn = simIn.setVariable('Noise',app.Noise*0);
                app.noisevalue=0;
            else 
                simIn = simIn.setVariable('Noise',app.Noise);
                app.noisevalue=app.NoisevarianceSlider.Value;
            end

            
            % Simulation - 
            simdata=sim(simIn);
            tt=simdata.nonlineardata.time;
            yy = simdata.nonlineardata.signals.values; % stored in order simulink file
            uu = simdata.pidoutputs.signals.values; % stored in order as shown in simulink file

            
        end  % end of determineresponses
 

    end  %% end of methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % resize to be in middle of  the window
            movegui(app.UIFigure)
            
            %Open the manual
            if exist('conical_tank_system_simulink_control101_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open conical_tank_system_simulink_control101_mycopy.mlx
            else
                p1 = which('conical_tank_system_simulink_control101_manual.mlx');
                p2 = strcat(pwd,'/conical_tank_system_simulink_control101_mycopy.mlx');
                copyfile(p1,p2)                
                open conical_tank_system_simulink_control101_mycopy.mlx
                clear p1 p2
            end


            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);

            try
                if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                else
                whitebg(app.UIFigure)
                end
            end


        end

        % Button pushed function: CreateresponsesButton
        function CreateresponsesButtonPushed(app, event)
          % Initialise all the modelling data and control design parameters needed

            % Include blockages
            if app.modellingonoff==0;
               app.Blockage=0;
            else
               app.Blockage = app.BlockageSlider.Value;
            end

            
            % run simulation and collect data
            Noise=[];
            Noise(:,1) = [0:app.endtime]';
            Noise(:,2)=randn(1,app.endtime+1)'*app.NoisevarianceSlider.Value;   
            app.Noise= Noise;
            
            tantheta = app.TankangledegSlider.Value*pi/180;  % angle of tank
            tantheta2=tantheta^2*pi;
            app.itantheta2=1/tantheta2;
            app.hss = app.InitialdepthmSlider.Value;  % notional depth for initialisation
            app.C=sqrt(app.rho*app.g)/app.R;
            app.Ce=sqrt(app.rho*app.g)/app.Re;
            app.Ctemp = app.C*(1-app.Blockage);
            app.qss = app.Ce*sqrt(app.InitialdepthmSlider.Value);
            app.Cl=app.Ce/(2*sqrt(app.InitialdepthmSlider.Value));

            % make sure input limits are viable
            if app.qss>app.InputlimitltrsSlider.Value/1000;
                app.InputlimitltrsSlider.Value=app.qss*1200;
            end

            
            % Linear model
            alpha = 1/(app.hss^2*tantheta2);
            beta = -(2/(app.hss^3*tantheta2))*app.qss + app.C*1.5/(app.hss^2.5*tantheta2);
            app.Kp = (-beta/alpha);
            app.Ki = -app.Kp*beta;
            app.tantheta = tantheta;
            app.tantheta2 = tantheta2;
            app.alpha=alpha;
            app.beta = beta;
            
            [yy,uu,tt] = determineresponses(app);

            % manage the legend for overlays or not
            if isempty(app.legstring{1});
                nlegstring=0;
                hold(app.UIAxes2,'on')
                hold(app.UIAxes2_2,'on')
            else
                nlegstring = length(app.legstring);
            end
            
            app.legstring{nlegstring+1}=['u_{max} = ',num2str(app.umax),...
                ', \delta u_{max}=',num2str(app.Dumax),...
                ', Blockage=',num2str(app.Blockage),... 
                ', Anti-wind up ',app.windupstate,...
                ', dist. = ',num2str(app.DisturbanceflowltrsSlider.Value)];

            app.legstring2{nlegstring+1}=['\theta = ',num2str(app.TankangledegSlider.Value),...
                ', R=',num2str(app.R)];
            
                       
           % ploting
           % History plots
           plot(app.UIAxes2,tt/60,yy(:,4))  % output
           legend(app.UIAxes2,app.legstring,'Location','southeast','FontSize',8)
           plot(app.UIAxes2_2,tt/60,yy(:,3)*1000)  % input
           legend(app.UIAxes2_2,app.legstring2,'Location','southeast','FontSize',8)

           % current simulation only to show different signals in the loop
           plot(app.UIAxes2_3,tt/60,[yy(:,[1,4]),yy(:,[4,8])*[1;1],yy(:,5)*app.BlockageSlider.Value]);
           legend(app.UIAxes2_3,'Target','True Depth','Measured depth','Blockage','Location','southwest')
        %   title(app.UIAxes2_3,'Inlet and outlet flow responses')
        %   ylabel(app.UIAxes2_3,'Depth (m)')

          plot(app.UIAxes2_4,tt/60,[yy(:,[2:3,7]),yy(:,3)+yy(:,7)]*1000);
          legend(app.UIAxes2_4,'Desired flow','Actual flow','Disturbance flow','Total flow','Location','southwest')
         %  ylabel(app.UIAxes2_4, 'Flows (m^3 /s)')
         %   xlabel(app.UIAxes2_4, 'Time (min)')
         %   title(app.UIAxes2_4,'Input responses')
          %  ylim(app.UIAxes2_4,[0,0.02])

             
        end

        % Button pushed function: RefreshfigureButton
        function RefreshfigureButtonPushed(app, event)
          %  The idea here is to compare the different control laws, but for a single option
          % define the compensators, sensitivities and closed-loop
          % responses and then create the plots

          hold(app.UIAxes2,'off')
          cla(app.UIAxes2)
         
          hold(app.UIAxes2_2,'off')
          cla(app.UIAxes2_2)

          hold(app.UIAxes2_3,'off')
          cla(app.UIAxes2_3)
         
          hold(app.UIAxes2_4,'off')
          cla(app.UIAxes2_4)

          app.legstring=cell(1);
           app.legstring2=cell(1);
          
             
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0;
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
        end

        % Value changed function: InputlimitltrsSlider
        function InputlimitltrsSliderValueChanged(app, event)
            value = app.InputlimitltrsSlider.Value;
            value=round(value,0);
            
            app.InputlimitltrsSlider.Value=value;
            app.umax = value/1000; % change to m^3/s
            app.umin=-value/1000;
        end

        % Button pushed function: SaturationButton
        function SaturationButtonPushed(app, event)
            Value = app.SaturationButton.BackgroundColor;
            if Value== [0.4,0.8,0.1]
                    app.SaturationButton.BackgroundColor = [1 0 0];
                    app.saturationonoff=0; % saturation inactive
                    app.AntiwindupButton.BackgroundColor = [1 0 0];
                    app.switchpid=1;   %no anti-windup
                    app.windupstate = 'off';
            else
                    app.SaturationButton.BackgroundColor = [0.4,0.8,0.1];
                    app.saturationonoff=1; % saturation active
            end

        end

        % Button pushed function: MeasurementnoiseButton
        function MeasurementnoiseButtonPushed(app, event)
            Value = app.MeasurementnoiseButton.BackgroundColor;
            if Value== [0.4,0.8,0.1]
                    app.MeasurementnoiseButton.BackgroundColor = [1 0 0];
                    app.noiseonoff =0;   % backlash inactive
            else
                    app.MeasurementnoiseButton.BackgroundColor = [0.4,0.8,0.1];
                    app.noiseonoff =1;   % backlash active
            end
        end

        % Button pushed function: AntiwindupButton
        function AntiwindupButtonPushed(app, event)
            Value = app.AntiwindupButton.BackgroundColor;
            if Value== [0.4,0.8,0.1]
                    app.AntiwindupButton.BackgroundColor = [1 0 0];
                    app.switchpid=1;   %no anti-windup
                    app.windupstate = 'off';
                    
            else
                    app.AntiwindupButton.BackgroundColor = [0.4,0.8,0.1];
                    app.switchpid=0;   % With anti-windup
                    app.windupstate = 'on';
                    app.SaturationButton.BackgroundColor = [0.4,0.8,0.1];
                    app.saturationonoff=1; % saturation active
            end
        end

        % Value changed function: Inputratelimitltrs2Slider
        function Inputratelimitltrs2SliderValueChanged(app, event)
            value = app.Inputratelimitltrs2Slider.Value;
            value=round(value,2);
            
            app.Inputratelimitltrs2Slider.Value=value;
            app.Dumax = value/1000; % change to m^3/s
            app.Dumin=-value/1000;
            
        end

        % Value changed function: BlockageSlider
        function BlockageSliderValueChanged2(app, event)
            value = app.BlockageSlider.Value;
            value=round(value,1);
            
            app.BlockageSlider.Value=value;
           
           
        end

        % Value changed function: NoisevarianceSlider
        function NoisevarianceSliderValueChanged2(app, event)
            value = app.NoisevarianceSlider.Value;
            if value<0.01; value=0.01;end  %to avoid warnings
            value=round(value,2);
            
            app.NoisevarianceSlider.Value=value;
            
            
        end

        % Button pushed function: BlockageButton
        function BlockageButtonPushed(app, event)
             Value = app.BlockageButton.BackgroundColor;
            if Value== [0.4,0.8,0.1]
                    app.BlockageButton.BackgroundColor = [1 0 0];
                    app.modellingonoff=0;  % measurement delay inactive
            else
                    app.BlockageButton.BackgroundColor = [0.4,0.8,0.1];
                    app.modellingonoff=1;
            end
        end

        % Value changed function: DisturbanceflowltrsSlider
        function DisturbanceflowltrsSliderValueChanged(app, event)
            value = app.DisturbanceflowltrsSlider.Value;
            value=round(value,1);
            app.DisturbanceflowltrsSlider.Value=value;
            
        end

        % Value changed function: TankangledegSlider
        function TankangledegSliderValueChanged(app, event)
           
            value = app.TankangledegSlider.Value;
            value=round(value,-1);
            app.TankangledegSlider.Value=value;
           
        end

        % Value changed function: OutletresistanceRSlider
        function OutletresistanceRSliderValueChanged(app, event)
            value = app.OutletresistanceRSlider.Value;
            value=round(value,0);
            app.OutletresistanceRSlider.Value=value;
            
        end

        % Value changed function: InitialdepthmSlider
        function InitialdepthmSliderValueChanged(app, event)
            value = app.InitialdepthmSlider.Value;
            value=round(value*2,0)/2;
            app.InitialdepthmSlider.Value=value;

            
            app.blockagetime=1800*value^2; % time that blockage introduced
            app.endtime=4200*value^2;     % simulation run time
            app.disturbanceflowtime=1200*value^2;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.GridLayout);
            title(app.UIAxes2_2, 'Controllable Input response comparisons')
            xlabel(app.UIAxes2_2, 'time (min)')
            ylabel(app.UIAxes2_2, 'Flow (ltr/s)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Output response comparisons')
            xlabel(app.UIAxes2, 'time (min)')
            ylabel(app.UIAxes2, 'Depth (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_3 = uiaxes(app.GridLayout);
            title(app.UIAxes2_3, 'Current depth responses')
            xlabel(app.UIAxes2_3, 'time (min)')
            ylabel(app.UIAxes2_3, 'Depth (m)')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.GridLayout);
            title(app.UIAxes2_4, 'Current input flow responses')
            xlabel(app.UIAxes2_4, 'time (min)')
            ylabel(app.UIAxes2_4, 'Flows (ltr/s)')
            zlabel(app.UIAxes2_4, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SystemparametersLabel = uilabel(app.GridLayout2);
            app.SystemparametersLabel.Text = 'System parameters';
            app.TankangledegSliderLabel = uilabel(app.GridLayout2);
            app.TankangledegSliderLabel.Text = 'Tank angle (deg)';
            app.TankangledegSlider = uislider(app.GridLayout2);
            app.TankangledegSlider.Limits = [0 60];
            app.TankangledegSlider.MajorTicks = [0 10 20 30 40 50 60];
            app.TankangledegSlider.ValueChangedFcn = createCallbackFcn(app, @TankangledegSliderValueChanged, true);
            app.TankangledegSlider.Value = 50;
            app.OutletresistanceRSliderLabel = uilabel(app.GridLayout2);
            app.OutletresistanceRSliderLabel.Text = 'Outlet resistance (R)';
            app.OutletresistanceRSlider = uislider(app.GridLayout2);
            app.OutletresistanceRSlider.Limits = [0 10];
            app.OutletresistanceRSlider.ValueChangedFcn = createCallbackFcn(app, @OutletresistanceRSliderValueChanged, true);
            app.OutletresistanceRSlider.Value = 5;
            app.InitialdepthmSliderLabel = uilabel(app.GridLayout2);
            app.InitialdepthmSliderLabel.Text = 'Initial depth (m)';
            app.InitialdepthmSlider = uislider(app.GridLayout2);
            app.InitialdepthmSlider.Limits = [0 5];
            app.InitialdepthmSlider.MajorTicks = [0 1 2 3 4 5];
            app.InitialdepthmSlider.ValueChangedFcn = createCallbackFcn(app, @InitialdepthmSliderValueChanged, true);
            app.InitialdepthmSlider.Value = 1;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UncertaintyandnonlinearityLabel = uilabel(app.GridLayout3);
            app.UncertaintyandnonlinearityLabel.Text = 'Uncertainty and non linearity';
            app.NoisevarianceSliderLabel = uilabel(app.GridLayout3);
            app.NoisevarianceSliderLabel.Text = 'Noise variance';
            app.NoisevarianceSlider = uislider(app.GridLayout3);
            app.NoisevarianceSlider.Limits = [0 0.1];
            app.NoisevarianceSlider.ValueChangedFcn = createCallbackFcn(app, @NoisevarianceSliderValueChanged2, true);
            app.NoisevarianceSlider.Value = 0.02;
            app.BlockageSliderLabel = uilabel(app.GridLayout3);
            app.BlockageSliderLabel.Text = 'Blockage';
            app.BlockageSlider = uislider(app.GridLayout3);
            app.BlockageSlider.Limits = [0 0.5];
            app.BlockageSlider.ValueChangedFcn = createCallbackFcn(app, @BlockageSliderValueChanged2, true);
            app.BlockageSlider.Value = 0.1;
            app.InputlimitltrsSliderLabel = uilabel(app.GridLayout3);
            app.InputlimitltrsSliderLabel.Text = 'Input limit (ltr/s)';
            app.InputlimitltrsSlider = uislider(app.GridLayout3);
            app.InputlimitltrsSlider.Limits = [0 50];
            app.InputlimitltrsSlider.ValueChangedFcn = createCallbackFcn(app, @InputlimitltrsSliderValueChanged, true);
            app.InputlimitltrsSlider.Value = 20;
            app.Inputratelimitltrs2SliderLabel = uilabel(app.GridLayout3);
            app.Inputratelimitltrs2SliderLabel.Text = 'Input rate limit (ltr/s^2)';
            app.Inputratelimitltrs2Slider = uislider(app.GridLayout3);
            app.Inputratelimitltrs2Slider.Limits = [0 0.2];
            app.Inputratelimitltrs2Slider.ValueChangedFcn = createCallbackFcn(app, @Inputratelimitltrs2SliderValueChanged, true);
            app.Inputratelimitltrs2Slider.Value = 0.1;
            app.DisturbanceflowltrsSliderLabel = uilabel(app.GridLayout3);
            app.DisturbanceflowltrsSliderLabel.Text = 'Disturbance flow (ltr/s)';
            app.DisturbanceflowltrsSlider = uislider(app.GridLayout3);
            app.DisturbanceflowltrsSlider.Limits = [0 4];
            app.DisturbanceflowltrsSlider.ValueChangedFcn = createCallbackFcn(app, @DisturbanceflowltrsSliderValueChanged, true);
            app.DisturbanceflowltrsSlider.Value = 2;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ImpactofnonlinearcomponentsonapositioncontrolsystemLabel = uilabel(app.GridLayout5);
            app.ImpactofnonlinearcomponentsonapositioncontrolsystemLabel.Text = 'Control of a conical tank';
            app.GridLayout14 = uigridlayout(app.GridLayout5);
            app.Image = uiimage(app.GridLayout14);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.Image4 = uiimage(app.GridLayout14);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Logo-UNED.png');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.CreateresponsesButton = uibutton(app.GridLayout6, 'push');
            app.CreateresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateresponsesButtonPushed, true);
            app.CreateresponsesButton.Text = 'Create responses';
            app.RefreshfigureButton = uibutton(app.GridLayout6, 'push');
            app.RefreshfigureButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshfigureButtonPushed, true);
            app.RefreshfigureButton.Text = 'Refresh figure';
            app.GridLayout15 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout15);
            app.SimulationparametersredmeansnotincludedLabel = uilabel(app.GridLayout4);
            app.SimulationparametersredmeansnotincludedLabel.Text = 'Simulation parameters (red means not included)';
            app.SaturationButton = uibutton(app.GridLayout4, 'push');
            app.SaturationButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.SaturationButton.Text = 'Saturation';
            app.MeasurementnoiseButton = uibutton(app.GridLayout4, 'push');
            app.MeasurementnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurementnoiseButtonPushed, true);
            app.MeasurementnoiseButton.Text = 'Measurement noise';
            app.AntiwindupButton = uibutton(app.GridLayout4, 'push');
            app.AntiwindupButton.ButtonPushedFcn = createCallbackFcn(app, @AntiwindupButtonPushed, true);
            app.AntiwindupButton.Text = 'Anti-windup';
            app.BlockageButton = uibutton(app.GridLayout4, 'push');
            app.BlockageButton.ButtonPushedFcn = createCallbackFcn(app, @BlockageButtonPushed, true);
            app.BlockageButton.Text = 'Blockage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conical_tank_control_control101

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end