classdef control101_master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ControlfundamentalsTab          matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        SimulationwithuncertaintyButton  matlab.ui.control.Button
        ThesefilescombineMATLABexamplesandabel  matlab.ui.control.Label
        IntroductiontoPIDtuningButton   matlab.ui.control.Button
        OverlayplotsfrommultiplescenariosButton  matlab.ui.control.Button
        ComparemultiplecompensatorsButton  matlab.ui.control.Button
        ClosedloopoffsetandpolesButton  matlab.ui.control.Button
        ClosedlooptransferfunctionsButton  matlab.ui.control.Button
        EffectsoffeedbackButton         matlab.ui.control.Button
        TransferfunctionsandpolesButton  matlab.ui.control.Button
        TransferfunctionsandbehaviourButton  matlab.ui.control.Button
        StepresponsesButton             matlab.ui.control.Button
        PartialfractionsButton          matlab.ui.control.Button
        LaplaceTransformsButton         matlab.ui.control.Button
        SecondorderresponsesButton      matlab.ui.control.Button
        SecondordermodelsButton         matlab.ui.control.Button
        FirstorderresponsesButton       matlab.ui.control.Button
        FirstordermodelsinmatlabButton  matlab.ui.control.Button
        ODEswithMATLABButton            matlab.ui.control.Button
        ControlfundamentalspleaseuseintheorderpresentedLabel  matlab.ui.control.Label
        PlottingwithmatlabButton        matlab.ui.control.Button
        VirtuallaboratoriesTab          matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TwotankscontrolButton           matlab.ui.control.Button
        RCcircuitbehaviourButton        matlab.ui.control.Button
        SystembehavioursButton          matlab.ui.control.Button
        TankleveldesignstepsButton      matlab.ui.control.Button
        NonlineartankcontrolButton      matlab.ui.control.Button
        PIDtuningButton                 matlab.ui.control.Button
        PItuningButton                  matlab.ui.control.Button
        ConicaltankcontrolButton        matlab.ui.control.Button
        OpenloopvsclosedloopButton      matlab.ui.control.Button
        DCmotorcontrolButton            matlab.ui.control.Button
        InvertedpendulumcontrolButton   matlab.ui.control.Button
        HousetemperaturecontrolButton   matlab.ui.control.Button
        TanklevelcontrolButton          matlab.ui.control.Button
        CruisecontrolwithPIButton       matlab.ui.control.Button
        storderbehavioursButton         matlab.ui.control.Button
        stordermodellingButton          matlab.ui.control.Button
        ManualcontroloftanklevelButton  matlab.ui.control.Button
        ConicaltanksButton              matlab.ui.control.Button
        NonlineartanksButton            matlab.ui.control.Button
        PredatorpreysystemsButton       matlab.ui.control.Button
        DiabetesButton                  matlab.ui.control.Button
        AeroplanelandingButton          matlab.ui.control.Button
        HousetemperatureButton          matlab.ui.control.Button
        MixingtankButton                matlab.ui.control.Button
        HeatexchangerButton             matlab.ui.control.Button
        TankLevelButton                 matlab.ui.control.Button
        CarvelocityButton               matlab.ui.control.Button
        VirtualLaboratoriesbehavioursandcontrolLabel  matlab.ui.control.Label
        StatespacemethodsTab            matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        ThesefilescombineMATLABexampLabel  matlab.ui.control.Label
        ImplementationonSIMULINK3Button  matlab.ui.control.Button
        ImplementationonSIMULINK2Button  matlab.ui.control.Button
        ImplementationonSIMULINK1Button  matlab.ui.control.Button
        DiscreteimplementationsButton   matlab.ui.control.Button
        FeedbackwithestimationButton    matlab.ui.control.Button
        TrackingdesignButton            matlab.ui.control.Button
        ObserverdesignButton            matlab.ui.control.Button
        OptimalstatefeedbackButton      matlab.ui.control.Button
        StatefeedbackButton             matlab.ui.control.Button
        EigenvaluesButton               matlab.ui.control.Button
        StabilityanalysisButton         matlab.ui.control.Button
        ControllabilityObservabilityButton  matlab.ui.control.Button
        EquivalentSSmodelsButton        matlab.ui.control.Button
        PhaseplanebehaviourButton       matlab.ui.control.Button
        BehaviourofSSmodelsButton       matlab.ui.control.Button
        DiscreteSSmodelsButton          matlab.ui.control.Button
        TFfromstatespaceButton          matlab.ui.control.Button
        StatespacefromTFButton          matlab.ui.control.Button
        StatespacemodelsButton          matlab.ui.control.Button
        StatespacemethodslivescriptfilesLabel  matlab.ui.control.Label
        FrequencyresponseTab            matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        DesignfromcriteriaButton        matlab.ui.control.Button
        ThedesignmethodsgivenhereareformulaicLabel  matlab.ui.control.Label
        AeroplanerollexampleButton      matlab.ui.control.Button
        DesignfromcriteriatheoryButton  matlab.ui.control.Button
        LeadlagdesignButton             matlab.ui.control.Button
        LeaddesignButton                matlab.ui.control.Button
        LagdesignButton                 matlab.ui.control.Button
        ProportionaldesignButton        matlab.ui.control.Button
        WhatisfrequencyresponseButton   matlab.ui.control.Button
        StabilityanalysisButton_2       matlab.ui.control.Button
        ImpactofdelaysButton            matlab.ui.control.Button
        LeadlagdesignwithBodeButton     matlab.ui.control.Button
        LeaddesignwithBodeButton        matlab.ui.control.Button
        LagdesignwithBodeButton         matlab.ui.control.Button
        ProportionaldesignBodeButton    matlab.ui.control.Button
        BodeasymptotesButton            matlab.ui.control.Button
        FrequencyresponsebasicsButton   matlab.ui.control.Button
        FrequencyresponsevirtuallaboratoriesLabel  matlab.ui.control.Label
        FrequencyresponselivescriptfilesLabel  matlab.ui.control.Label
        SimulinkTab                     matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        TwotankssystemButton            matlab.ui.control.Button
        SimpleexamplesButton            matlab.ui.control.Button
        LinearisationButton             matlab.ui.control.Button
        ThesimulinkfilesLabel           matlab.ui.control.Label
        SeeseveralmorefilesinthevirtuallaboratoriesTABLabel  matlab.ui.control.Label
        SeethefileswithinthestatespaceTABformoreexamplesLabel  matlab.ui.control.Label
        NonlineartankcontrolButton_2    matlab.ui.control.Button
        NonlineartankbehaviourButton    matlab.ui.control.Button
        NonlinearpositioncontrolButton  matlab.ui.control.Button
        IntroductiontosimulinkmodelsButton  matlab.ui.control.Button
        MyfirstsimulinkmodelButton      matlab.ui.control.Button
        PositioncontrolButton           matlab.ui.control.Button
        SubmersibleButton               matlab.ui.control.Button
        HeatingsystemButton             matlab.ui.control.Button
        CarsuspensionButton             matlab.ui.control.Button
        HydraulicsystemButton           matlab.ui.control.Button
        SimulinkfilesandnonlinearsystemsvirtuallaboratoriesLabel  matlab.ui.control.Label
        SimulinkfilesandnonlinearsystemslivescriptfilesLabel  matlab.ui.control.Label
        SampleddatasystemsTab           matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SignaltypesincomputercontrolButton  matlab.ui.control.Button
        SamplingoperationButton         matlab.ui.control.Button
        SampleddatasystemsButton        matlab.ui.control.Button
        SignalreconstructionButton      matlab.ui.control.Button
        SignaltypeswithsamplingButton   matlab.ui.control.Button
        ClosedloopundersamplingButton   matlab.ui.control.Button
        ContinuousanddiscretemappingButton  matlab.ui.control.Button
        DigitaltoanalogueconvertorButton  matlab.ui.control.Button
        AnaloguetodigitalconvertorButton  matlab.ui.control.Button
        Manyofthesefilesgofarbeyonda1sLabel  matlab.ui.control.Label
        DiscretemethodsButton           matlab.ui.control.Button
        DiscreteYouladesignButton       matlab.ui.control.Button
        YouladesignButton               matlab.ui.control.Button
        PIDviapolecancellationButton    matlab.ui.control.Button
        SmithpredictorButton            matlab.ui.control.Button
        DeadbeatcontrolButton           matlab.ui.control.Button
        DiscretePIDButton               matlab.ui.control.Button
        ControlstructuresButton         matlab.ui.control.Button
        AntiresetwindupButton           matlab.ui.control.Button
        StabilityofdiscreteButton       matlab.ui.control.Button
        DiscretisationandBodeButton     matlab.ui.control.Button
        DiscretemodelsButton            matlab.ui.control.Button
        TimeseriesandrecursionButton    matlab.ui.control.Button
        TimeseriesmodelsButton          matlab.ui.control.Button
        AdvancedcontrolanddiscretesystemsappsinlightercolourLabel  matlab.ui.control.Label
        ModelPredictiveControlTab       matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        MPCandfeedforwardButton         matlab.ui.control.Button
        MPCandconstrainthandlingButton  matlab.ui.control.Button
        MPCwithaTfilterButton           matlab.ui.control.Button
        MPCfailingButton                matlab.ui.control.Button
        MPCtuningButton                 matlab.ui.control.Button
        ConceptsofpredictionButton      matlab.ui.control.Button
        SourcefilesButton               matlab.ui.control.Button
        ModelpredictivecontrolanintroductionLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        GridLayout3                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        CONTROL101toolboxLabel          matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        SelectTABthemeandpressbuttontoselectfileLabel  matlab.ui.control.Label
        Thisisalauncherforthefilesincontrol101Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlottingwithmatlabButton
        function PlottingwithmatlabButtonPushed(app, event)
            plotting_with_matlab
        end

        % Button pushed function: ODEswithMATLABButton
        function ODEswithMATLABButtonPushed(app, event)
            ODEs_with_matlab
        end

        % Button pushed function: FirstordermodelsinmatlabButton
        function FirstordermodelsinmatlabButtonPushed(app, event)
            firstordermodels_in_matlab
        end

        % Button pushed function: FirstorderresponsesButton
        function FirstorderresponsesButtonPushed(app, event)
            firstordermodels_responses_analysis_and_feedback
        end

        % Button pushed function: SecondordermodelsButton
        function SecondordermodelsButtonPushed(app, event)
            secondordermodels_in_matlab
        end

        % Button pushed function: SecondorderresponsesButton
        function SecondorderresponsesButtonPushed(app, event)
            secondordermodels_and_underdamping
        end

        % Button pushed function: LaplaceTransformsButton
        function LaplaceTransformsButtonPushed(app, event)
            laplace_transforms_with_matlab
        end

        % Button pushed function: PartialfractionsButton
        function PartialfractionsButtonPushed(app, event)
            partial_fractions_with_matlab
        end

        % Button pushed function: StepresponsesButton
        function StepresponsesButtonPushed(app, event)
            step_responses_with_matlab
        end

        % Button pushed function: TransferfunctionsandbehaviourButton
        function TransferfunctionsandbehaviourButtonPushed(app, event)
            transferfunctions_and_behaviours
        end

        % Button pushed function: TransferfunctionsandpolesButton
        function TransferfunctionsandpolesButtonPushed(app, event)
            transferfunctions_and_poles
        end

        % Button pushed function: EffectsoffeedbackButton
        function EffectsoffeedbackButtonPushed(app, event)
            effects_of_negative_feedback
        end

        % Button pushed function: ClosedlooptransferfunctionsButton
        function ClosedlooptransferfunctionsButtonPushed(app, event)
            closedloop_transferfunctions_with_feedback
        end

        % Button pushed function: ClosedloopoffsetandpolesButton
        function ClosedloopoffsetandpolesButtonPushed(app, event)
            closedloop_offset_and_poles
        end

        % Button pushed function: ComparemultiplecompensatorsButton
        function ComparemultiplecompensatorsButtonPushed(app, event)
            closed_loop_compare_multiple_compensators
        end

        % Button pushed function: OverlayplotsfrommultiplescenariosButton
        function OverlayplotsfrommultiplescenariosButtonPushed(app, event)
            closed_vs_openloop_overlay
        end

        % Button pushed function: IntroductiontoPIDtuningButton
        function IntroductiontoPIDtuningButtonPushed(app, event)
            PID_tuning_methods
        end

        % Button pushed function: SimulationwithuncertaintyButton
        function SimulationwithuncertaintyButtonPushed(app, event)
            uncertainty_simulations_control101_tool
        end

        % Button pushed function: CarvelocityButton
        function CarvelocityButtonPushed(app, event)
            carmodel_and_behaviour
        end

        % Button pushed function: TankLevelButton
        function TankLevelButtonPushed(app, event)
            tanklevelbehaviour
        end

        % Button pushed function: HeatexchangerButton
        function HeatexchangerButtonPushed(app, event)
            HeatExchanger
        end

        % Button pushed function: HousetemperatureButton
        function HousetemperatureButtonPushed(app, event)
            housetemperature_and_behaviour
        end

        % Button pushed function: MixingtankButton
        function MixingtankButtonPushed(app, event)
            mixingtank_and_behaviour
        end

        % Button pushed function: RCcircuitbehaviourButton
        function RCcircuitbehaviourButtonPushed(app, event)
            rc_circuit_behaviour_control101
        end

        % Button pushed function: AeroplanelandingButton
        function AeroplanelandingButtonPushed(app, event)
            aeroplanelanding_behaviour
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            diabetes_control101
        end

        % Button pushed function: PredatorpreysystemsButton
        function PredatorpreysystemsButtonPushed(app, event)
            Predator_prey_model
        end

        % Button pushed function: NonlineartanksButton
        function NonlineartanksButtonPushed(app, event)
            nonlinear_tank_behaviour_control101
        end

        % Button pushed function: ConicaltanksButton
        function ConicaltanksButtonPushed(app, event)
            conical_tank_behaviour_control101
        end

        % Button pushed function: ConicaltankcontrolButton
        function ConicaltankcontrolButtonPushed(app, event)
            conical_tank_control_control101
        end

        % Button pushed function: storderbehavioursButton
        function storderbehavioursButtonPushed(app, event)
            firstordermodel_behaviour
        end

        % Button pushed function: stordermodellingButton
        function stordermodellingButtonPushed(app, event)
            firstordermodel_response_characterisation
        end

        % Button pushed function: ManualcontroloftanklevelButton
        function ManualcontroloftanklevelButtonPushed(app, event)
            tank_and_manual_vs_PIcontrol_delay
        end

        % Button pushed function: CruisecontrolwithPIButton
        function CruisecontrolwithPIButtonPushed(app, event)
            cruisecontrol
        end

        % Button pushed function: TanklevelcontrolButton
        function TanklevelcontrolButtonPushed(app, event)
            tanklevel_and_PIcontrol
        end

        % Button pushed function: TankleveldesignstepsButton
        function TankleveldesignstepsButtonPushed(app, event)
            tanklevel_story
        end

        % Button pushed function: SystembehavioursButton
        function SystembehavioursButtonPushed(app, event)
            behaviours
        end

        % Button pushed function: InvertedpendulumcontrolButton
        function InvertedpendulumcontrolButtonPushed(app, event)
            invertedpendulum
        end

        % Button pushed function: DCmotorcontrolButton
        function DCmotorcontrolButtonPushed(app, event)
            dcservo_and_position_control
        end

        % Button pushed function: OpenloopvsclosedloopButton
        function OpenloopvsclosedloopButtonPushed(app, event)
            open_vs_closed_control101
        end

        % Button pushed function: HousetemperaturecontrolButton
        function HousetemperaturecontrolButtonPushed(app, event)
            housetemperature_story
        end

        % Button pushed function: PItuningButton
        function PItuningButtonPushed(app, event)
            pi_tuning
        end

        % Button pushed function: PIDtuningButton
        function PIDtuningButtonPushed(app, event)
            pidtuningrules
        end

        % Button pushed function: NonlineartankcontrolButton
        function NonlineartankcontrolButtonPushed(app, event)
            nonlinear_tank_control_control101
        end

        % Button pushed function: StatespacemodelsButton
        function StatespacemodelsButtonPushed(app, event)
            statespace_models_control101
        end

        % Button pushed function: StatespacefromTFButton
        function StatespacefromTFButtonPushed(app, event)
            statespace_models_from_tf_control101
        end

        % Button pushed function: TFfromstatespaceButton
        function TFfromstatespaceButtonPushed(app, event)
            tf_models_from_statespace_control101
        end

        % Button pushed function: DiscreteSSmodelsButton
        function DiscreteSSmodelsButtonPushed(app, event)
            discrete_time_state_space_models_control101
        end

        % Button pushed function: BehaviourofSSmodelsButton
        function BehaviourofSSmodelsButtonPushed(app, event)
            openloop_behaviour_statespace_control101
        end

        % Button pushed function: PhaseplanebehaviourButton
        function PhaseplanebehaviourButtonPushed(app, event)
            phaseplane_behaviour_statespace_control101
        end

        % Button pushed function: EquivalentSSmodelsButton
        function EquivalentSSmodelsButtonPushed(app, event)
            equivalent_state_space_formulations_control101
        end

        % Button pushed function: ControllabilityObservabilityButton
        function ControllabilityObservabilityButtonPushed(app, event)
            controllability_and_observability_control101
        end

        % Button pushed function: StabilityanalysisButton
        function StabilityanalysisButtonPushed(app, event)
            stability_analysis_control101
        end

        % Button pushed function: EigenvaluesButton
        function EigenvaluesButtonPushed(app, event)
            stability_eigenvalues_control101
        end

        % Button pushed function: StatefeedbackButton
        function StatefeedbackButtonPushed(app, event)
            state_feedback_control101
        end

        % Button pushed function: OptimalstatefeedbackButton
        function OptimalstatefeedbackButtonPushed(app, event)
            optimal_state_feedback_control101
        end

        % Button pushed function: ObserverdesignButton
        function ObserverdesignButtonPushed(app, event)
            observer_design_control101
        end

        % Button pushed function: TrackingdesignButton
        function TrackingdesignButtonPushed(app, event)
            tracking_with_statespace_control101
        end

        % Button pushed function: FeedbackwithestimationButton
        function FeedbackwithestimationButtonPushed(app, event)
            state_feedback_and_estimation_cont_case_study
        end

        % Button pushed function: DiscreteimplementationsButton
        function DiscreteimplementationsButtonPushed(app, event)
            state_feedback_and_state_estimation_discrete_case_study
        end

        % Button pushed function: ImplementationonSIMULINK1Button
        function ImplementationonSIMULINK1ButtonPushed(app, event)
            state_space_and_simulinkfile1_control101
        end

        % Button pushed function: ImplementationonSIMULINK2Button
        function ImplementationonSIMULINK2ButtonPushed(app, event)
            state_space_and_simulinkfile2_control101
        end

        % Button pushed function: ImplementationonSIMULINK3Button
        function ImplementationonSIMULINK3ButtonPushed(app, event)
            state_space_and_simulinkfile3_control101
        end

        % Button pushed function: FrequencyresponsebasicsButton
        function FrequencyresponsebasicsButtonPushed(app, event)
            freq_responses_with_matlab
        end

        % Button pushed function: BodeasymptotesButton
        function BodeasymptotesButtonPushed(app, event)
            bode_asymptotes
        end

        % Button pushed function: ProportionaldesignBodeButton
        function ProportionaldesignBodeButtonPushed(app, event)
            proportional_design_with_bode
        end

        % Button pushed function: LagdesignwithBodeButton
        function LagdesignwithBodeButtonPushed(app, event)
            lag_design_with_bode
        end

        % Button pushed function: LeaddesignwithBodeButton
        function LeaddesignwithBodeButtonPushed(app, event)
            lead_design_with_bode
        end

        % Button pushed function: LeadlagdesignwithBodeButton
        function LeadlagdesignwithBodeButtonPushed(app, event)
            lead_lag_design_with_bode
        end

        % Button pushed function: ImpactofdelaysButton
        function ImpactofdelaysButtonPushed(app, event)
            delays_and_bode
        end

        % Button pushed function: StabilityanalysisButton_2
        function StabilityanalysisButton_2Pushed(app, event)
            Stability_Continuous
        end

        % Button pushed function: WhatisfrequencyresponseButton
        function WhatisfrequencyresponseButtonPushed(app, event)
            frequency_response_time_behaviour
        end

        % Button pushed function: ProportionaldesignButton
        function ProportionaldesignButtonPushed(app, event)
            proportional_design_phase_margin
        end

        % Button pushed function: LagdesignButton
        function LagdesignButtonPushed(app, event)
            lag_design_phase_margin
        end

        % Button pushed function: LeaddesignButton
        function LeaddesignButtonPushed(app, event)
            lead_design_phase_margin
        end

        % Button pushed function: DesignfromcriteriatheoryButton
        function DesignfromcriteriatheoryButtonPushed(app, event)
            lead_lag_design_with_criteria
        end

        % Button pushed function: DesignfromcriteriaButton
        function DesignfromcriteriaButtonPushed(app, event)
            lead_lag_design_criteria
        end

        % Button pushed function: AeroplanerollexampleButton
        function AeroplanerollexampleButtonPushed(app, event)
            aeroplane_roll_control
        end

        % Button pushed function: LeadlagdesignButton
        function LeadlagdesignButtonPushed(app, event)
            lead_lag_design_phase_margin
        end

        % Button pushed function: IntroductiontosimulinkmodelsButton
        function IntroductiontosimulinkmodelsButtonPushed(app, event)
            intro_to_simulink_for_system_behaviour
        end

        % Button pushed function: CarsuspensionButton
        function CarsuspensionButtonPushed(app, event)
            car_suspension_simulink_control101
        end

        % Button pushed function: HeatingsystemButton
        function HeatingsystemButtonPushed(app, event)
            heating_system_simulink_control101
        end

        % Button pushed function: SimpleexamplesButton
        function SimpleexamplesButtonPushed(app, event)
            uncertainty_simulations_control101_tool
        end

        % Button pushed function: HydraulicsystemButton
        function HydraulicsystemButtonPushed(app, event)
            hydraulic_system_simulink_control101
        end

        % Button pushed function: PositioncontrolButton
        function PositioncontrolButtonPushed(app, event)
            position_system_simulink_control101
        end

        % Button pushed function: SubmersibleButton
        function SubmersibleButtonPushed(app, event)
            submersible_system_simulink_control101
        end

        % Button pushed function: MyfirstsimulinkmodelButton
        function MyfirstsimulinkmodelButtonPushed(app, event)
            myfirstsimulink_control101
        end

        % Button pushed function: NonlineartankbehaviourButton
        function NonlineartankbehaviourButtonPushed(app, event)
            nonlinear_tank_behaviour_control101
        end

        % Button pushed function: LinearisationButton
        function LinearisationButtonPushed(app, event)
            linearisation_Benchmark_control101
        end

        % Button pushed function: NonlinearpositioncontrolButton
        function NonlinearpositioncontrolButtonPushed(app, event)
            nonlinear_position_control
        end

        % Button pushed function: NonlineartankcontrolButton_2
        function NonlineartankcontrolButton_2Pushed(app, event)
            nonlinear_tank_control_control101
        end

        % Button pushed function: TimeseriesmodelsButton
        function TimeseriesmodelsButtonPushed(app, event)
            time_series_models
        end

        % Button pushed function: TimeseriesandrecursionButton
        function TimeseriesandrecursionButtonPushed(app, event)
            time_series_models_and_recursion
        end

        % Button pushed function: DiscretemodelsButton
        function DiscretemodelsButtonPushed(app, event)
            discrete_models_and_usage
        end

        % Button pushed function: DiscretisationandBodeButton
        function DiscretisationandBodeButtonPushed(app, event)
            discretisation_and_bode
        end

        % Button pushed function: StabilityofdiscreteButton
        function StabilityofdiscreteButtonPushed(app, event)
            Stability_Discrete
        end

        % Button pushed function: AnaloguetodigitalconvertorButton
        function AnaloguetodigitalconvertorButtonPushed(app, event)
            Analog_to_Digital_Converter_Application_control101
        end

        % Button pushed function: DigitaltoanalogueconvertorButton
        function DigitaltoanalogueconvertorButtonPushed(app, event)
            Digital_to_Analog_Converter_Application_control101
        end

        % Button pushed function: ContinuousanddiscretemappingButton
        function ContinuousanddiscretemappingButtonPushed(app, event)
            Continuous_and_Discrete_time_Mapping_control101
        end

        % Button pushed function: ClosedloopundersamplingButton
        function ClosedloopundersamplingButtonPushed(app, event)
            Closed_Loop_Under_Sampling_control101
        end

        % Button pushed function: SignaltypeswithsamplingButton
        function SignaltypeswithsamplingButtonPushed(app, event)
            Signal_Types_in_Comp_Control_application_control101
        end

        % Button pushed function: SignalreconstructionButton
        function SignalreconstructionButtonPushed(app, event)
            Reconstruction_and_Signal_Holders_control101
        end

        % Button pushed function: SampleddatasystemsButton
        function SampleddatasystemsButtonPushed(app, event)
            Sampled_Data_Systems_control101
        end

        % Button pushed function: SamplingoperationButton
        function SamplingoperationButtonPushed(app, event)
            Sampling_Operation_control101
        end

        % Button pushed function: SignaltypesincomputercontrolButton
        function SignaltypesincomputercontrolButtonPushed(app, event)
            Signal_Types_in_Computer_Controlled_Systems_control101
        end

        % Button pushed function: PIDviapolecancellationButton
        function PIDviapolecancellationButtonPushed(app, event)
            PID_Cont_Controller_Design_with_Pole_Cancellation
        end

        % Button pushed function: YouladesignButton
        function YouladesignButtonPushed(app, event)
            Youla_continuous_controller_design
        end

        % Button pushed function: DiscreteYouladesignButton
        function DiscreteYouladesignButtonPushed(app, event)
            Youla_discrete_controller_design
        end

        % Button pushed function: DiscretemethodsButton
        function DiscretemethodsButtonPushed(app, event)
            discrete_controller_design_methods
        end

        % Button pushed function: SmithpredictorButton
        function SmithpredictorButtonPushed(app, event)
            Smith_predictor_controller_design
        end

        % Button pushed function: AntiresetwindupButton
        function AntiresetwindupButtonPushed(app, event)
            antireset_windup_control101
        end

        % Button pushed function: ControlstructuresButton
        function ControlstructuresButtonPushed(app, event)
            control_structures_control101
        end

        % Button pushed function: DiscretePIDButton
        function DiscretePIDButtonPushed(app, event)
            discrete_models_and_discrete_PID_controller_design
        end

        % Button pushed function: DeadbeatcontrolButton
        function DeadbeatcontrolButtonPushed(app, event)
            Dead_beat_controller_design_control101
        end

        % Button pushed function: SourcefilesButton
        function SourcefilesButtonPushed(app, event)
            mpcsourcecode_control101_tool
        end

        % Button pushed function: ConceptsofpredictionButton
        function ConceptsofpredictionButtonPushed(app, event)
           predictionconcepts_control101
        end

        % Button pushed function: MPCtuningButton
        function MPCtuningButtonPushed(app, event)
            mpctuning_control101   
        end

        % Button pushed function: MPCfailingButton
        function MPCfailingButtonPushed(app, event)
            mpcfailing_control101  
        end

        % Button pushed function: MPCwithaTfilterButton
        function MPCwithaTfilterButtonPushed(app, event)
            mpctfilter_control101   
        end

        % Button pushed function: MPCandconstrainthandlingButton
        function MPCandconstrainthandlingButtonPushed(app, event)
            mpcconstraints_lab_control101  
        end

        % Button pushed function: MPCandfeedforwardButton
        function MPCandfeedforwardButtonPushed(app, event)
            mpcfeedforward_control101
        end

        % Button pushed function: TwotankscontrolButton
        function TwotankscontrolButtonPushed(app, event)
            TwoTanks
        end

        % Button pushed function: TwotankssystemButton
        function TwotankssystemButtonPushed(app, event)
            twotanks_control101_run
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Thisisalauncherforthefilesincontrol101Label = uilabel(app.GridLayout2);
            app.Thisisalauncherforthefilesincontrol101Label.Text = 'This is a launcher for the files in control101.';
            app.SelectTABthemeandpressbuttontoselectfileLabel = uilabel(app.GridLayout2);
            app.SelectTABthemeandpressbuttontoselectfileLabel.Text = 'Select TAB/theme and press button to select file.';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CONTROL101toolboxLabel = uilabel(app.GridLayout3);
            app.CONTROL101toolboxLabel.Text = 'CONTROL101 toolbox';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.Hyperlink = uihyperlink(app.GridLayout);
            app.Hyperlink.URL = 'https://sites.google.com/sheffield.ac.uk/controleducation/matlabresources/section-6-11-community-control-toolbox';
            app.Hyperlink.Text = ' For more detailed summaries  view the webpage and documentation.';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'This interface provides only the links to run the files.';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlfundamentalsTab = uitab(app.TabGroup);
            app.ControlfundamentalsTab.Title = 'Control fundamentals';
            app.GridLayout7 = uigridlayout(app.ControlfundamentalsTab);
            app.PlottingwithmatlabButton = uibutton(app.GridLayout7, 'push');
            app.PlottingwithmatlabButton.ButtonPushedFcn = createCallbackFcn(app, @PlottingwithmatlabButtonPushed, true);
            app.PlottingwithmatlabButton.Text = 'Plotting with matlab';
            app.ControlfundamentalspleaseuseintheorderpresentedLabel = uilabel(app.GridLayout7);
            app.ControlfundamentalspleaseuseintheorderpresentedLabel.Text = 'Control fundamentals (please use in the order presented)';
            app.ODEswithMATLABButton = uibutton(app.GridLayout7, 'push');
            app.ODEswithMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @ODEswithMATLABButtonPushed, true);
            app.ODEswithMATLABButton.Text = 'ODEs with MATLAB';
            app.FirstordermodelsinmatlabButton = uibutton(app.GridLayout7, 'push');
            app.FirstordermodelsinmatlabButton.ButtonPushedFcn = createCallbackFcn(app, @FirstordermodelsinmatlabButtonPushed, true);
            app.FirstordermodelsinmatlabButton.Text = {'First order models in '; 'matlab'};
            app.FirstorderresponsesButton = uibutton(app.GridLayout7, 'push');
            app.FirstorderresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @FirstorderresponsesButtonPushed, true);
            app.FirstorderresponsesButton.Text = 'First order responses';
            app.SecondordermodelsButton = uibutton(app.GridLayout7, 'push');
            app.SecondordermodelsButton.ButtonPushedFcn = createCallbackFcn(app, @SecondordermodelsButtonPushed, true);
            app.SecondordermodelsButton.Text = 'Second order models';
            app.SecondorderresponsesButton = uibutton(app.GridLayout7, 'push');
            app.SecondorderresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @SecondorderresponsesButtonPushed, true);
            app.SecondorderresponsesButton.Text = 'Second order responses';
            app.LaplaceTransformsButton = uibutton(app.GridLayout7, 'push');
            app.LaplaceTransformsButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceTransformsButtonPushed, true);
            app.LaplaceTransformsButton.Text = 'Laplace Transforms';
            app.PartialfractionsButton = uibutton(app.GridLayout7, 'push');
            app.PartialfractionsButton.ButtonPushedFcn = createCallbackFcn(app, @PartialfractionsButtonPushed, true);
            app.PartialfractionsButton.Text = 'Partial fractions';
            app.StepresponsesButton = uibutton(app.GridLayout7, 'push');
            app.StepresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @StepresponsesButtonPushed, true);
            app.StepresponsesButton.Text = 'Step responses';
            app.TransferfunctionsandbehaviourButton = uibutton(app.GridLayout7, 'push');
            app.TransferfunctionsandbehaviourButton.ButtonPushedFcn = createCallbackFcn(app, @TransferfunctionsandbehaviourButtonPushed, true);
            app.TransferfunctionsandbehaviourButton.Text = 'Transfer functions and behaviour';
            app.TransferfunctionsandpolesButton = uibutton(app.GridLayout7, 'push');
            app.TransferfunctionsandpolesButton.ButtonPushedFcn = createCallbackFcn(app, @TransferfunctionsandpolesButtonPushed, true);
            app.TransferfunctionsandpolesButton.Text = 'Transfer functions and poles';
            app.EffectsoffeedbackButton = uibutton(app.GridLayout7, 'push');
            app.EffectsoffeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @EffectsoffeedbackButtonPushed, true);
            app.EffectsoffeedbackButton.Text = 'Effects of feedback';
            app.ClosedlooptransferfunctionsButton = uibutton(app.GridLayout7, 'push');
            app.ClosedlooptransferfunctionsButton.ButtonPushedFcn = createCallbackFcn(app, @ClosedlooptransferfunctionsButtonPushed, true);
            app.ClosedlooptransferfunctionsButton.Text = 'Closed-loop transfer functions';
            app.ClosedloopoffsetandpolesButton = uibutton(app.GridLayout7, 'push');
            app.ClosedloopoffsetandpolesButton.ButtonPushedFcn = createCallbackFcn(app, @ClosedloopoffsetandpolesButtonPushed, true);
            app.ClosedloopoffsetandpolesButton.Text = 'Closed-loop offset and poles';
            app.ComparemultiplecompensatorsButton = uibutton(app.GridLayout7, 'push');
            app.ComparemultiplecompensatorsButton.ButtonPushedFcn = createCallbackFcn(app, @ComparemultiplecompensatorsButtonPushed, true);
            app.ComparemultiplecompensatorsButton.Text = 'Compare multiple compensators';
            app.OverlayplotsfrommultiplescenariosButton = uibutton(app.GridLayout7, 'push');
            app.OverlayplotsfrommultiplescenariosButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayplotsfrommultiplescenariosButtonPushed, true);
            app.OverlayplotsfrommultiplescenariosButton.Text = 'Overlay plots from multiple scenarios';
            app.IntroductiontoPIDtuningButton = uibutton(app.GridLayout7, 'push');
            app.IntroductiontoPIDtuningButton.ButtonPushedFcn = createCallbackFcn(app, @IntroductiontoPIDtuningButtonPushed, true);
            app.IntroductiontoPIDtuningButton.Text = 'Introduction to PID tuning';
            app.ThesefilescombineMATLABexamplesandabel = uilabel(app.GridLayout7);
            app.ThesefilescombineMATLABexamplesandabel.Text = 'These files combine MATLAB examples and exemplar code with explanations.';
            app.SimulationwithuncertaintyButton = uibutton(app.GridLayout7, 'push');
            app.SimulationwithuncertaintyButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationwithuncertaintyButtonPushed, true);
            app.SimulationwithuncertaintyButton.Text = 'Simulation with uncertainty';
            app.VirtuallaboratoriesTab = uitab(app.TabGroup);
            app.VirtuallaboratoriesTab.Title = 'Virtual laboratories';
            app.GridLayout8 = uigridlayout(app.VirtuallaboratoriesTab);
            app.VirtualLaboratoriesbehavioursandcontrolLabel = uilabel(app.GridLayout8);
            app.VirtualLaboratoriesbehavioursandcontrolLabel.Text = 'Virtual Laboratories (behaviours and control)';
            app.CarvelocityButton = uibutton(app.GridLayout8, 'push');
            app.CarvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CarvelocityButtonPushed, true);
            app.CarvelocityButton.Text = 'Car velocity';
            app.TankLevelButton = uibutton(app.GridLayout8, 'push');
            app.TankLevelButton.ButtonPushedFcn = createCallbackFcn(app, @TankLevelButtonPushed, true);
            app.TankLevelButton.Text = 'Tank Level';
            app.HeatexchangerButton = uibutton(app.GridLayout8, 'push');
            app.HeatexchangerButton.ButtonPushedFcn = createCallbackFcn(app, @HeatexchangerButtonPushed, true);
            app.HeatexchangerButton.Text = 'Heat exchanger';
            app.MixingtankButton = uibutton(app.GridLayout8, 'push');
            app.MixingtankButton.ButtonPushedFcn = createCallbackFcn(app, @MixingtankButtonPushed, true);
            app.MixingtankButton.Text = 'Mixing tank';
            app.HousetemperatureButton = uibutton(app.GridLayout8, 'push');
            app.HousetemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @HousetemperatureButtonPushed, true);
            app.HousetemperatureButton.Text = 'House temperature';
            app.AeroplanelandingButton = uibutton(app.GridLayout8, 'push');
            app.AeroplanelandingButton.ButtonPushedFcn = createCallbackFcn(app, @AeroplanelandingButtonPushed, true);
            app.AeroplanelandingButton.Text = 'Aeroplane landing';
            app.DiabetesButton = uibutton(app.GridLayout8, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Text = 'Diabetes';
            app.PredatorpreysystemsButton = uibutton(app.GridLayout8, 'push');
            app.PredatorpreysystemsButton.ButtonPushedFcn = createCallbackFcn(app, @PredatorpreysystemsButtonPushed, true);
            app.PredatorpreysystemsButton.Text = 'Predator-prey systems';
            app.NonlineartanksButton = uibutton(app.GridLayout8, 'push');
            app.NonlineartanksButton.ButtonPushedFcn = createCallbackFcn(app, @NonlineartanksButtonPushed, true);
            app.NonlineartanksButton.Text = 'Non-linear tanks';
            app.ConicaltanksButton = uibutton(app.GridLayout8, 'push');
            app.ConicaltanksButton.ButtonPushedFcn = createCallbackFcn(app, @ConicaltanksButtonPushed, true);
            app.ConicaltanksButton.Text = 'Conical tanks';
            app.ManualcontroloftanklevelButton = uibutton(app.GridLayout8, 'push');
            app.ManualcontroloftanklevelButton.ButtonPushedFcn = createCallbackFcn(app, @ManualcontroloftanklevelButtonPushed, true);
            app.ManualcontroloftanklevelButton.Text = 'Manual control of tank level';
            app.stordermodellingButton = uibutton(app.GridLayout8, 'push');
            app.stordermodellingButton.ButtonPushedFcn = createCallbackFcn(app, @stordermodellingButtonPushed, true);
            app.stordermodellingButton.Text = '1st order modelling ';
            app.storderbehavioursButton = uibutton(app.GridLayout8, 'push');
            app.storderbehavioursButton.ButtonPushedFcn = createCallbackFcn(app, @storderbehavioursButtonPushed, true);
            app.storderbehavioursButton.Text = '1st order behaviours';
            app.CruisecontrolwithPIButton = uibutton(app.GridLayout8, 'push');
            app.CruisecontrolwithPIButton.ButtonPushedFcn = createCallbackFcn(app, @CruisecontrolwithPIButtonPushed, true);
            app.CruisecontrolwithPIButton.Text = 'Cruise control with PI';
            app.TanklevelcontrolButton = uibutton(app.GridLayout8, 'push');
            app.TanklevelcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @TanklevelcontrolButtonPushed, true);
            app.TanklevelcontrolButton.Text = 'Tank level control';
            app.HousetemperaturecontrolButton = uibutton(app.GridLayout8, 'push');
            app.HousetemperaturecontrolButton.ButtonPushedFcn = createCallbackFcn(app, @HousetemperaturecontrolButtonPushed, true);
            app.HousetemperaturecontrolButton.Text = 'House temperature control';
            app.InvertedpendulumcontrolButton = uibutton(app.GridLayout8, 'push');
            app.InvertedpendulumcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @InvertedpendulumcontrolButtonPushed, true);
            app.InvertedpendulumcontrolButton.Text = 'Inverted pendulum control';
            app.DCmotorcontrolButton = uibutton(app.GridLayout8, 'push');
            app.DCmotorcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @DCmotorcontrolButtonPushed, true);
            app.DCmotorcontrolButton.Text = 'DC motor control';
            app.OpenloopvsclosedloopButton = uibutton(app.GridLayout8, 'push');
            app.OpenloopvsclosedloopButton.ButtonPushedFcn = createCallbackFcn(app, @OpenloopvsclosedloopButtonPushed, true);
            app.OpenloopvsclosedloopButton.Text = 'Open-loop vs closed-loop';
            app.ConicaltankcontrolButton = uibutton(app.GridLayout8, 'push');
            app.ConicaltankcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @ConicaltankcontrolButtonPushed, true);
            app.ConicaltankcontrolButton.Text = 'Conical tank control';
            app.PItuningButton = uibutton(app.GridLayout8, 'push');
            app.PItuningButton.ButtonPushedFcn = createCallbackFcn(app, @PItuningButtonPushed, true);
            app.PItuningButton.Text = 'PI tuning';
            app.PIDtuningButton = uibutton(app.GridLayout8, 'push');
            app.PIDtuningButton.ButtonPushedFcn = createCallbackFcn(app, @PIDtuningButtonPushed, true);
            app.PIDtuningButton.Text = 'PID tuning';
            app.NonlineartankcontrolButton = uibutton(app.GridLayout8, 'push');
            app.NonlineartankcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @NonlineartankcontrolButtonPushed, true);
            app.NonlineartankcontrolButton.Text = 'Non-linear tank control';
            app.TankleveldesignstepsButton = uibutton(app.GridLayout8, 'push');
            app.TankleveldesignstepsButton.ButtonPushedFcn = createCallbackFcn(app, @TankleveldesignstepsButtonPushed, true);
            app.TankleveldesignstepsButton.Text = 'Tank level design steps';
            app.SystembehavioursButton = uibutton(app.GridLayout8, 'push');
            app.SystembehavioursButton.ButtonPushedFcn = createCallbackFcn(app, @SystembehavioursButtonPushed, true);
            app.SystembehavioursButton.Text = 'System behaviours';
            app.RCcircuitbehaviourButton = uibutton(app.GridLayout8, 'push');
            app.RCcircuitbehaviourButton.ButtonPushedFcn = createCallbackFcn(app, @RCcircuitbehaviourButtonPushed, true);
            app.RCcircuitbehaviourButton.Text = 'RC circuit behaviour';
            app.TwotankscontrolButton = uibutton(app.GridLayout8, 'push');
            app.TwotankscontrolButton.ButtonPushedFcn = createCallbackFcn(app, @TwotankscontrolButtonPushed, true);
            app.TwotankscontrolButton.Text = 'Two tanks control';
            app.StatespacemethodsTab = uitab(app.TabGroup);
            app.StatespacemethodsTab.Title = 'State space methods';
            app.GridLayout9 = uigridlayout(app.StatespacemethodsTab);
            app.StatespacemethodslivescriptfilesLabel = uilabel(app.GridLayout9);
            app.StatespacemethodslivescriptfilesLabel.Text = 'State space methods (livescript files)';
            app.StatespacemodelsButton = uibutton(app.GridLayout9, 'push');
            app.StatespacemodelsButton.ButtonPushedFcn = createCallbackFcn(app, @StatespacemodelsButtonPushed, true);
            app.StatespacemodelsButton.Text = 'State space models';
            app.StatespacefromTFButton = uibutton(app.GridLayout9, 'push');
            app.StatespacefromTFButton.ButtonPushedFcn = createCallbackFcn(app, @StatespacefromTFButtonPushed, true);
            app.StatespacefromTFButton.Text = 'State space from TF';
            app.TFfromstatespaceButton = uibutton(app.GridLayout9, 'push');
            app.TFfromstatespaceButton.ButtonPushedFcn = createCallbackFcn(app, @TFfromstatespaceButtonPushed, true);
            app.TFfromstatespaceButton.Text = 'TF from state space';
            app.DiscreteSSmodelsButton = uibutton(app.GridLayout9, 'push');
            app.DiscreteSSmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteSSmodelsButtonPushed, true);
            app.DiscreteSSmodelsButton.Text = 'Discrete SS models';
            app.BehaviourofSSmodelsButton = uibutton(app.GridLayout9, 'push');
            app.BehaviourofSSmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @BehaviourofSSmodelsButtonPushed, true);
            app.BehaviourofSSmodelsButton.Text = 'Behaviour of SS models';
            app.PhaseplanebehaviourButton = uibutton(app.GridLayout9, 'push');
            app.PhaseplanebehaviourButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseplanebehaviourButtonPushed, true);
            app.PhaseplanebehaviourButton.Text = 'Phase plane behaviour';
            app.EquivalentSSmodelsButton = uibutton(app.GridLayout9, 'push');
            app.EquivalentSSmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @EquivalentSSmodelsButtonPushed, true);
            app.EquivalentSSmodelsButton.Text = 'Equivalent SS models';
            app.ControllabilityObservabilityButton = uibutton(app.GridLayout9, 'push');
            app.ControllabilityObservabilityButton.ButtonPushedFcn = createCallbackFcn(app, @ControllabilityObservabilityButtonPushed, true);
            app.ControllabilityObservabilityButton.Text = 'Controllability & Observability';
            app.StabilityanalysisButton = uibutton(app.GridLayout9, 'push');
            app.StabilityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StabilityanalysisButtonPushed, true);
            app.StabilityanalysisButton.Text = 'Stability analysis';
            app.EigenvaluesButton = uibutton(app.GridLayout9, 'push');
            app.EigenvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenvaluesButtonPushed, true);
            app.EigenvaluesButton.Text = 'Eigenvalues';
            app.StatefeedbackButton = uibutton(app.GridLayout9, 'push');
            app.StatefeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @StatefeedbackButtonPushed, true);
            app.StatefeedbackButton.Text = 'State feedback';
            app.OptimalstatefeedbackButton = uibutton(app.GridLayout9, 'push');
            app.OptimalstatefeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @OptimalstatefeedbackButtonPushed, true);
            app.OptimalstatefeedbackButton.Text = 'Optimal state feedback';
            app.ObserverdesignButton = uibutton(app.GridLayout9, 'push');
            app.ObserverdesignButton.ButtonPushedFcn = createCallbackFcn(app, @ObserverdesignButtonPushed, true);
            app.ObserverdesignButton.Text = 'Observer design';
            app.TrackingdesignButton = uibutton(app.GridLayout9, 'push');
            app.TrackingdesignButton.ButtonPushedFcn = createCallbackFcn(app, @TrackingdesignButtonPushed, true);
            app.TrackingdesignButton.Text = 'Tracking design';
            app.FeedbackwithestimationButton = uibutton(app.GridLayout9, 'push');
            app.FeedbackwithestimationButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackwithestimationButtonPushed, true);
            app.FeedbackwithestimationButton.Text = {'Feedback with '; 'estimation'};
            app.DiscreteimplementationsButton = uibutton(app.GridLayout9, 'push');
            app.DiscreteimplementationsButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteimplementationsButtonPushed, true);
            app.DiscreteimplementationsButton.Text = 'Discrete implementations';
            app.ImplementationonSIMULINK1Button = uibutton(app.GridLayout9, 'push');
            app.ImplementationonSIMULINK1Button.ButtonPushedFcn = createCallbackFcn(app, @ImplementationonSIMULINK1ButtonPushed, true);
            app.ImplementationonSIMULINK1Button.Text = 'Implementation on SIMULINK 1';
            app.ImplementationonSIMULINK2Button = uibutton(app.GridLayout9, 'push');
            app.ImplementationonSIMULINK2Button.ButtonPushedFcn = createCallbackFcn(app, @ImplementationonSIMULINK2ButtonPushed, true);
            app.ImplementationonSIMULINK2Button.Text = 'Implementation on SIMULINK 2';
            app.ImplementationonSIMULINK3Button = uibutton(app.GridLayout9, 'push');
            app.ImplementationonSIMULINK3Button.ButtonPushedFcn = createCallbackFcn(app, @ImplementationonSIMULINK3ButtonPushed, true);
            app.ImplementationonSIMULINK3Button.Text = 'Implementation on SIMULINK 3';
            app.ThesefilescombineMATLABexampLabel = uilabel(app.GridLayout9);
            app.ThesefilescombineMATLABexampLabel.Text = 'These files combine MATLAB examples and exemplar code with explanations of the state space theory. ';
            app.FrequencyresponseTab = uitab(app.TabGroup);
            app.FrequencyresponseTab.Title = 'Frequency response';
            app.GridLayout12 = uigridlayout(app.FrequencyresponseTab);
            app.FrequencyresponselivescriptfilesLabel = uilabel(app.GridLayout12);
            app.FrequencyresponselivescriptfilesLabel.Text = 'Frequency response (livescript files)';
            app.FrequencyresponsevirtuallaboratoriesLabel = uilabel(app.GridLayout12);
            app.FrequencyresponsevirtuallaboratoriesLabel.Text = 'Frequency response (virtual laboratories)';
            app.FrequencyresponsebasicsButton = uibutton(app.GridLayout12, 'push');
            app.FrequencyresponsebasicsButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyresponsebasicsButtonPushed, true);
            app.FrequencyresponsebasicsButton.Text = 'Frequency response basics';
            app.BodeasymptotesButton = uibutton(app.GridLayout12, 'push');
            app.BodeasymptotesButton.ButtonPushedFcn = createCallbackFcn(app, @BodeasymptotesButtonPushed, true);
            app.BodeasymptotesButton.Text = 'Bode asymptotes';
            app.ProportionaldesignBodeButton = uibutton(app.GridLayout12, 'push');
            app.ProportionaldesignBodeButton.ButtonPushedFcn = createCallbackFcn(app, @ProportionaldesignBodeButtonPushed, true);
            app.ProportionaldesignBodeButton.Text = 'Proportional design & Bode';
            app.LagdesignwithBodeButton = uibutton(app.GridLayout12, 'push');
            app.LagdesignwithBodeButton.ButtonPushedFcn = createCallbackFcn(app, @LagdesignwithBodeButtonPushed, true);
            app.LagdesignwithBodeButton.Text = 'Lag design with Bode';
            app.LeaddesignwithBodeButton = uibutton(app.GridLayout12, 'push');
            app.LeaddesignwithBodeButton.ButtonPushedFcn = createCallbackFcn(app, @LeaddesignwithBodeButtonPushed, true);
            app.LeaddesignwithBodeButton.Text = 'Lead design with Bode';
            app.LeadlagdesignwithBodeButton = uibutton(app.GridLayout12, 'push');
            app.LeadlagdesignwithBodeButton.ButtonPushedFcn = createCallbackFcn(app, @LeadlagdesignwithBodeButtonPushed, true);
            app.LeadlagdesignwithBodeButton.Text = 'Lead-lag design with Bode';
            app.ImpactofdelaysButton = uibutton(app.GridLayout12, 'push');
            app.ImpactofdelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ImpactofdelaysButtonPushed, true);
            app.ImpactofdelaysButton.Text = 'Impact of delays';
            app.StabilityanalysisButton_2 = uibutton(app.GridLayout12, 'push');
            app.StabilityanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @StabilityanalysisButton_2Pushed, true);
            app.StabilityanalysisButton_2.Text = 'Stability analysis';
            app.WhatisfrequencyresponseButton = uibutton(app.GridLayout12, 'push');
            app.WhatisfrequencyresponseButton.ButtonPushedFcn = createCallbackFcn(app, @WhatisfrequencyresponseButtonPushed, true);
            app.WhatisfrequencyresponseButton.Text = 'What is frequency response?';
            app.ProportionaldesignButton = uibutton(app.GridLayout12, 'push');
            app.ProportionaldesignButton.ButtonPushedFcn = createCallbackFcn(app, @ProportionaldesignButtonPushed, true);
            app.ProportionaldesignButton.Text = 'Proportional design';
            app.LagdesignButton = uibutton(app.GridLayout12, 'push');
            app.LagdesignButton.ButtonPushedFcn = createCallbackFcn(app, @LagdesignButtonPushed, true);
            app.LagdesignButton.Text = 'Lag design';
            app.LeaddesignButton = uibutton(app.GridLayout12, 'push');
            app.LeaddesignButton.ButtonPushedFcn = createCallbackFcn(app, @LeaddesignButtonPushed, true);
            app.LeaddesignButton.Text = 'Lead design';
            app.LeadlagdesignButton = uibutton(app.GridLayout12, 'push');
            app.LeadlagdesignButton.ButtonPushedFcn = createCallbackFcn(app, @LeadlagdesignButtonPushed, true);
            app.LeadlagdesignButton.Text = 'Lead-lag design';
            app.DesignfromcriteriatheoryButton = uibutton(app.GridLayout12, 'push');
            app.DesignfromcriteriatheoryButton.ButtonPushedFcn = createCallbackFcn(app, @DesignfromcriteriatheoryButtonPushed, true);
            app.DesignfromcriteriatheoryButton.Text = 'Design from criteria (theory)';
            app.AeroplanerollexampleButton = uibutton(app.GridLayout12, 'push');
            app.AeroplanerollexampleButton.ButtonPushedFcn = createCallbackFcn(app, @AeroplanerollexampleButtonPushed, true);
            app.AeroplanerollexampleButton.Text = 'Aeroplane roll example';
            app.ThedesignmethodsgivenhereareformulaicLabel = uilabel(app.GridLayout12);
            app.ThedesignmethodsgivenhereareformulaicLabel.Text = 'The design methods given here are formulaic.';
            app.DesignfromcriteriaButton = uibutton(app.GridLayout12, 'push');
            app.DesignfromcriteriaButton.ButtonPushedFcn = createCallbackFcn(app, @DesignfromcriteriaButtonPushed, true);
            app.DesignfromcriteriaButton.Text = 'Design from criteria';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = 'They help students understand how to use Bode to meet performance criteria.';
            app.SimulinkTab = uitab(app.TabGroup);
            app.SimulinkTab.Title = 'Simulink';
            app.GridLayout10 = uigridlayout(app.SimulinkTab);
            app.SimulinkfilesandnonlinearsystemslivescriptfilesLabel = uilabel(app.GridLayout10);
            app.SimulinkfilesandnonlinearsystemslivescriptfilesLabel.Text = 'Simulink files and non-linear systems (livescript files)';
            app.SimulinkfilesandnonlinearsystemsvirtuallaboratoriesLabel = uilabel(app.GridLayout10);
            app.SimulinkfilesandnonlinearsystemsvirtuallaboratoriesLabel.Text = 'Simulink files and non-linear systems (virtual laboratories)';
            app.HydraulicsystemButton = uibutton(app.GridLayout10, 'push');
            app.HydraulicsystemButton.ButtonPushedFcn = createCallbackFcn(app, @HydraulicsystemButtonPushed, true);
            app.HydraulicsystemButton.Text = 'Hydraulic system';
            app.CarsuspensionButton = uibutton(app.GridLayout10, 'push');
            app.CarsuspensionButton.ButtonPushedFcn = createCallbackFcn(app, @CarsuspensionButtonPushed, true);
            app.CarsuspensionButton.Text = 'Car suspension';
            app.HeatingsystemButton = uibutton(app.GridLayout10, 'push');
            app.HeatingsystemButton.ButtonPushedFcn = createCallbackFcn(app, @HeatingsystemButtonPushed, true);
            app.HeatingsystemButton.Text = 'Heating system';
            app.SubmersibleButton = uibutton(app.GridLayout10, 'push');
            app.SubmersibleButton.ButtonPushedFcn = createCallbackFcn(app, @SubmersibleButtonPushed, true);
            app.SubmersibleButton.Text = 'Submersible';
            app.PositioncontrolButton = uibutton(app.GridLayout10, 'push');
            app.PositioncontrolButton.ButtonPushedFcn = createCallbackFcn(app, @PositioncontrolButtonPushed, true);
            app.PositioncontrolButton.Text = 'Position control';
            app.MyfirstsimulinkmodelButton = uibutton(app.GridLayout10, 'push');
            app.MyfirstsimulinkmodelButton.ButtonPushedFcn = createCallbackFcn(app, @MyfirstsimulinkmodelButtonPushed, true);
            app.MyfirstsimulinkmodelButton.Text = 'My first simulink model';
            app.IntroductiontosimulinkmodelsButton = uibutton(app.GridLayout10, 'push');
            app.IntroductiontosimulinkmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @IntroductiontosimulinkmodelsButtonPushed, true);
            app.IntroductiontosimulinkmodelsButton.Text = 'Introduction to simulink models';
            app.NonlinearpositioncontrolButton = uibutton(app.GridLayout10, 'push');
            app.NonlinearpositioncontrolButton.ButtonPushedFcn = createCallbackFcn(app, @NonlinearpositioncontrolButtonPushed, true);
            app.NonlinearpositioncontrolButton.Text = 'Non-linear position control';
            app.NonlineartankbehaviourButton = uibutton(app.GridLayout10, 'push');
            app.NonlineartankbehaviourButton.ButtonPushedFcn = createCallbackFcn(app, @NonlineartankbehaviourButtonPushed, true);
            app.NonlineartankbehaviourButton.Text = 'Non-linear tank behaviour';
            app.NonlineartankcontrolButton_2 = uibutton(app.GridLayout10, 'push');
            app.NonlineartankcontrolButton_2.ButtonPushedFcn = createCallbackFcn(app, @NonlineartankcontrolButton_2Pushed, true);
            app.NonlineartankcontrolButton_2.Text = 'Non-linear tank control';
            app.SeethefileswithinthestatespaceTABformoreexamplesLabel = uilabel(app.GridLayout10);
            app.SeethefileswithinthestatespaceTABformoreexamplesLabel.Text = 'See the files within the state space TAB for more examples';
            app.SeeseveralmorefilesinthevirtuallaboratoriesTABLabel = uilabel(app.GridLayout10);
            app.SeeseveralmorefilesinthevirtuallaboratoriesTABLabel.Text = {'See several more '; 'files in the virtual laboratories TAB'};
            app.ThesimulinkfilesLabel = uilabel(app.GridLayout10);
            app.ThesimulinkfilesLabel.Text = 'The simulink files and their usage are detailed within the livescripts.';
            app.LinearisationButton = uibutton(app.GridLayout10, 'push');
            app.LinearisationButton.ButtonPushedFcn = createCallbackFcn(app, @LinearisationButtonPushed, true);
            app.LinearisationButton.Text = 'Linearisation';
            app.SimpleexamplesButton = uibutton(app.GridLayout10, 'push');
            app.SimpleexamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleexamplesButtonPushed, true);
            app.SimpleexamplesButton.Text = 'Simple examples';
            app.TwotankssystemButton = uibutton(app.GridLayout10, 'push');
            app.TwotankssystemButton.ButtonPushedFcn = createCallbackFcn(app, @TwotankssystemButtonPushed, true);
            app.TwotankssystemButton.Text = 'Two tanks system';
            app.SampleddatasystemsTab = uitab(app.TabGroup);
            app.SampleddatasystemsTab.Title = 'Sampled data systems';
            app.GridLayout11 = uigridlayout(app.SampleddatasystemsTab);
            app.AdvancedcontrolanddiscretesystemsappsinlightercolourLabel = uilabel(app.GridLayout11);
            app.AdvancedcontrolanddiscretesystemsappsinlightercolourLabel.Text = 'Advanced control and discrete systems (apps in lighter colour)';
            app.TimeseriesmodelsButton = uibutton(app.GridLayout11, 'push');
            app.TimeseriesmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @TimeseriesmodelsButtonPushed, true);
            app.TimeseriesmodelsButton.Text = 'Time series models';
            app.TimeseriesandrecursionButton = uibutton(app.GridLayout11, 'push');
            app.TimeseriesandrecursionButton.ButtonPushedFcn = createCallbackFcn(app, @TimeseriesandrecursionButtonPushed, true);
            app.TimeseriesandrecursionButton.Text = 'Time series and recursion';
            app.DiscretemodelsButton = uibutton(app.GridLayout11, 'push');
            app.DiscretemodelsButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretemodelsButtonPushed, true);
            app.DiscretemodelsButton.Text = 'Discrete models';
            app.DiscretisationandBodeButton = uibutton(app.GridLayout11, 'push');
            app.DiscretisationandBodeButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretisationandBodeButtonPushed, true);
            app.DiscretisationandBodeButton.Text = 'Discretisation and Bode';
            app.StabilityofdiscreteButton = uibutton(app.GridLayout11, 'push');
            app.StabilityofdiscreteButton.ButtonPushedFcn = createCallbackFcn(app, @StabilityofdiscreteButtonPushed, true);
            app.StabilityofdiscreteButton.Text = 'Stability of discrete';
            app.AntiresetwindupButton = uibutton(app.GridLayout11, 'push');
            app.AntiresetwindupButton.ButtonPushedFcn = createCallbackFcn(app, @AntiresetwindupButtonPushed, true);
            app.AntiresetwindupButton.Text = 'Anti-reset windup';
            app.ControlstructuresButton = uibutton(app.GridLayout11, 'push');
            app.ControlstructuresButton.ButtonPushedFcn = createCallbackFcn(app, @ControlstructuresButtonPushed, true);
            app.ControlstructuresButton.Text = 'Control structures';
            app.DiscretePIDButton = uibutton(app.GridLayout11, 'push');
            app.DiscretePIDButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretePIDButtonPushed, true);
            app.DiscretePIDButton.Text = 'Discrete PID';
            app.DeadbeatcontrolButton = uibutton(app.GridLayout11, 'push');
            app.DeadbeatcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @DeadbeatcontrolButtonPushed, true);
            app.DeadbeatcontrolButton.Text = 'Dead-beat control';
            app.SmithpredictorButton = uibutton(app.GridLayout11, 'push');
            app.SmithpredictorButton.ButtonPushedFcn = createCallbackFcn(app, @SmithpredictorButtonPushed, true);
            app.SmithpredictorButton.Text = 'Smith predictor';
            app.PIDviapolecancellationButton = uibutton(app.GridLayout11, 'push');
            app.PIDviapolecancellationButton.ButtonPushedFcn = createCallbackFcn(app, @PIDviapolecancellationButtonPushed, true);
            app.PIDviapolecancellationButton.Text = 'PID via pole cancellation';
            app.YouladesignButton = uibutton(app.GridLayout11, 'push');
            app.YouladesignButton.ButtonPushedFcn = createCallbackFcn(app, @YouladesignButtonPushed, true);
            app.YouladesignButton.Text = 'Youla design';
            app.DiscreteYouladesignButton = uibutton(app.GridLayout11, 'push');
            app.DiscreteYouladesignButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteYouladesignButtonPushed, true);
            app.DiscreteYouladesignButton.Text = 'Discrete Youla design';
            app.DiscretemethodsButton = uibutton(app.GridLayout11, 'push');
            app.DiscretemethodsButton.ButtonPushedFcn = createCallbackFcn(app, @DiscretemethodsButtonPushed, true);
            app.DiscretemethodsButton.Text = 'Discrete methods';
            app.Manyofthesefilesgofarbeyonda1sLabel = uilabel(app.GridLayout11);
            app.Manyofthesefilesgofarbeyonda1sLabel.Text = 'Many of the files below go far beyond a 1st and 2nd course in control.';
            app.AnaloguetodigitalconvertorButton = uibutton(app.GridLayout11, 'push');
            app.AnaloguetodigitalconvertorButton.ButtonPushedFcn = createCallbackFcn(app, @AnaloguetodigitalconvertorButtonPushed, true);
            app.AnaloguetodigitalconvertorButton.Text = 'Analogue to digital convertor';
            app.DigitaltoanalogueconvertorButton = uibutton(app.GridLayout11, 'push');
            app.DigitaltoanalogueconvertorButton.ButtonPushedFcn = createCallbackFcn(app, @DigitaltoanalogueconvertorButtonPushed, true);
            app.DigitaltoanalogueconvertorButton.Text = 'Digital to analogue convertor';
            app.ContinuousanddiscretemappingButton = uibutton(app.GridLayout11, 'push');
            app.ContinuousanddiscretemappingButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousanddiscretemappingButtonPushed, true);
            app.ContinuousanddiscretemappingButton.Text = 'Continuous and discrete mapping';
            app.ClosedloopundersamplingButton = uibutton(app.GridLayout11, 'push');
            app.ClosedloopundersamplingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosedloopundersamplingButtonPushed, true);
            app.ClosedloopundersamplingButton.Text = 'Closed-loop under sampling';
            app.SignaltypeswithsamplingButton = uibutton(app.GridLayout11, 'push');
            app.SignaltypeswithsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @SignaltypeswithsamplingButtonPushed, true);
            app.SignaltypeswithsamplingButton.Text = 'Signal types with sampling';
            app.SignalreconstructionButton = uibutton(app.GridLayout11, 'push');
            app.SignalreconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @SignalreconstructionButtonPushed, true);
            app.SignalreconstructionButton.Text = 'Signal reconstruction';
            app.SampleddatasystemsButton = uibutton(app.GridLayout11, 'push');
            app.SampleddatasystemsButton.ButtonPushedFcn = createCallbackFcn(app, @SampleddatasystemsButtonPushed, true);
            app.SampleddatasystemsButton.Text = 'Sampled data systems';
            app.SamplingoperationButton = uibutton(app.GridLayout11, 'push');
            app.SamplingoperationButton.ButtonPushedFcn = createCallbackFcn(app, @SamplingoperationButtonPushed, true);
            app.SamplingoperationButton.Text = 'Sampling operation';
            app.SignaltypesincomputercontrolButton = uibutton(app.GridLayout11, 'push');
            app.SignaltypesincomputercontrolButton.ButtonPushedFcn = createCallbackFcn(app, @SignaltypesincomputercontrolButtonPushed, true);
            app.SignaltypesincomputercontrolButton.Text = 'Signal types in computer control';
            app.ModelPredictiveControlTab = uitab(app.TabGroup);
            app.ModelPredictiveControlTab.Title = 'Model Predictive Control';
            app.GridLayout13 = uigridlayout(app.ModelPredictiveControlTab);
            app.ModelpredictivecontrolanintroductionLabel = uilabel(app.GridLayout13);
            app.ModelpredictivecontrolanintroductionLabel.Text = 'Model predictive control (an introduction)';
            app.SourcefilesButton = uibutton(app.GridLayout13, 'push');
            app.SourcefilesButton.ButtonPushedFcn = createCallbackFcn(app, @SourcefilesButtonPushed, true);
            app.SourcefilesButton.Text = 'Source files';
            app.ConceptsofpredictionButton = uibutton(app.GridLayout13, 'push');
            app.ConceptsofpredictionButton.ButtonPushedFcn = createCallbackFcn(app, @ConceptsofpredictionButtonPushed, true);
            app.ConceptsofpredictionButton.Text = 'Concepts of prediction';
            app.MPCtuningButton = uibutton(app.GridLayout13, 'push');
            app.MPCtuningButton.ButtonPushedFcn = createCallbackFcn(app, @MPCtuningButtonPushed, true);
            app.MPCtuningButton.Text = 'MPC tuning';
            app.MPCfailingButton = uibutton(app.GridLayout13, 'push');
            app.MPCfailingButton.ButtonPushedFcn = createCallbackFcn(app, @MPCfailingButtonPushed, true);
            app.MPCfailingButton.Text = 'MPC failing';
            app.MPCwithaTfilterButton = uibutton(app.GridLayout13, 'push');
            app.MPCwithaTfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MPCwithaTfilterButtonPushed, true);
            app.MPCwithaTfilterButton.Text = 'MPC with a T-filter';
            app.MPCandconstrainthandlingButton = uibutton(app.GridLayout13, 'push');
            app.MPCandconstrainthandlingButton.ButtonPushedFcn = createCallbackFcn(app, @MPCandconstrainthandlingButtonPushed, true);
            app.MPCandconstrainthandlingButton.Text = 'MPC and constraint handling';
            app.MPCandfeedforwardButton = uibutton(app.GridLayout13, 'push');
            app.MPCandfeedforwardButton.ButtonPushedFcn = createCallbackFcn(app, @MPCandfeedforwardButtonPushed, true);
            app.MPCandfeedforwardButton.Text = 'MPC and feedforward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control101_master

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end