classdef lead_lag_design_criteria < matlab.apps.AppBase
    %LEAD_LAG_DESIGN_CRITERIA
    %   File written by J.A.Rossiter, University of Sheffield  
    %   See website for more files like this.
    %   https://sites.google.com/sheffield.ac.uk/controleducation/mainindex
    %   
    %   A simple interface to compare simple proportional, Lag, Lead and
    %   Lead-Lag compensation design using frequency response analysis.
    %   
    %   Users can select from a number of predefined systems:  2nd and
    %   3rd order. These have a variety of time constants and also some
    %   have under-damped modes to illustrate resonance. 
    %   
    %   Users must also select their design criteria: phase margin,
    %   bandwidth and offset. The compensator designs are then done
    %   automatically and the associated plots are shown. 
    %   
    %   The manual gives more guidance on what happens
    %   behind the scenes to help the user to understand the underlying
    %   designs.
    %   
    %   A group of similar files can be downloaded through MATLAB
    %   add-ons with the toolbox name:  control101

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout9                    matlab.ui.container.GridLayout
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LagzeropoleratioLabel          matlab.ui.control.Label
        LeadpolezeroratioLabel         matlab.ui.control.Label
        KpLabel                        matlab.ui.control.Label
        LeadlagControlparametersLabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        OffsetSlider                   matlab.ui.control.Slider
        OffsetSliderLabel              matlab.ui.control.Label
        BandwidthSlider                matlab.ui.control.Slider
        OffsetLabel_2                  matlab.ui.control.Label
        PMSlider                       matlab.ui.control.Slider
        PMSliderLabel                  matlab.ui.control.Label
        SpecificationsLabel            matlab.ui.control.Label
        DetailedanalysisoffirstordermodelparametersPanel  matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        RamperrorLabel                 matlab.ui.control.Label
        OffsetLabel                    matlab.ui.control.Label
        wcLabel                        matlab.ui.control.Label
        PhiLabel                       matlab.ui.control.Label
        GsLabel                        matlab.ui.control.Label
        CurrentmodelLabel              matlab.ui.control.Label
        ClosedloopoffsetrampsLabel     matlab.ui.control.Label
        ClosedloopoffsetstepsLabel     matlab.ui.control.Label
        GaincrossoverfrequencyLabel    matlab.ui.control.Label
        CurrentphasemarginLabel        matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        GridLayout4                    matlab.ui.container.GridLayout
        HighorderDropDown              matlab.ui.control.DropDown
        HighorderDropDownLabel         matlab.ui.control.Label
        SecondorderDropDown            matlab.ui.control.DropDown
        SecondorderDropDownLabel       matlab.ui.control.Label
        ChoosemodelLabel               matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        UpdateplotsButton              matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        CompensatordesignwithPMbandwidthandoffsetcriteriaLabel  matlab.ui.control.Label
    end


    % The next section defines all the core variables used in the code and
    % that it may be convenient to have available in the various
    % subfunctions
    properties (Access = private)
        originalsize     % product of width and height for font sizing

        G = tf(2,[1 1]);  % model no delay
        numExample =2   % system numerator
        denExample =[1 1]  % system denominator
        wlist = logspace(-2,1,200) ;  % frequency list
        u   % sinsoidal signal
        y   % response no delay
        yd  % response with delay
        omega % frequency
        tvec  % time vector for plotting
        endtime=5;  % default simulation tome
      
        PM =60 ;  % Target phase margin
        int =0;  % tag for whether an integrator included
        offset = 'step' % tag for ramp or step offset criteria
        Kp = 1;  % Proportional compensator
        MLEAD = 1;  % to store lead compensator
        MLAG = 1; % store lag component
        MLEADLAG =1; % Lead-lag compensator
        wg        % cross over frequency based on G*Kp

        warningtext = ' '% for error messsages

          
    end
    
    methods (Access = private)


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%  Down load data and create BODE
 %%%%%%%%% and simple data such as offsets and PM
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    function app = getbode_diagram(app)
       
       % collect data from sliders
       wgd = app.BandwidthSlider.Value;  % desired bandwith so use for pole/zero choices
  
       
       % get margins with just proportional
       warning('off','Control:analysis:MarginUnstable');
       [GM,PM,wp,wg] = margin(app.G*app.Kp);  % core frequency is wg
       wg = round(wg,2,'significant');
       [GMlag,PMlag,ww,wlag] = margin(app.G*app.MLAG);
       [GMd,PMd,wpl,wgl] = margin(app.G*app.MLEADLAG);
       [GMlead,PMlead,ww,wlead] = margin(app.G*app.MLEAD);
       [lagpole,lagzero]=pzmap(app.MLAG);

       % Set up plots
       app.PM = app.PMSlider.Value;
       offset = app.BandwidthSlider.Value;
       [gain,phase]=bode(app.G,app.wlist);
       [gaind,phased]=bode(app.G*app.MLEAD,app.wlist);
       [gaind2,phased2]=bode(app.G*app.MLEADLAG,app.wlist);
       [gaind3,phased3]=bode(app.G*app.MLAG,app.wlist);
 
       % plot gain
       hold(app.UIAxes,'off')
        
       semilogx(app.UIAxes,app.wlist,20*log10(gain(:)*app.Kp),'linewidth',2)
       hold(app.UIAxes,'on')
       grid(app.UIAxes,'on')
       semilogx(app.UIAxes,app.wlist,20*log10(gaind3(:)),'linewidth',2)
       semilogx(app.UIAxes,app.wlist,20*log10(gaind(:)),'linewidth',2)
       semilogx(app.UIAxes,app.wlist,20*log10(gaind2(:)),'linewidth',2)
       semilogx(app.UIAxes,app.wlist,20*log10(gain(:)),':','color',[0.7 .3 1],'linewidth',2)
       semilogx(app.UIAxes,[app.wlist(1),app.wlist(end)],[1 1]*(0),'k-','linewidth',3)
       %ylim(app.UIAxes,[-20,max(20*log10(gain(:)*2))]);
       plot(app.UIAxes,[wgd,wgd],[-20,20],'g--','linewidth',3);
       legend(app.UIAxes,'G*Kp','G*Lag','G*Lead','G*Lead-lag','G','0dB','Target bandwidth')
       text(app.UIAxes,app.wlist(4),-20,app.warningtext,'fontsize',18,'color','r')

       % plot phase
       hold(app.UIAxes_2,'off')
       semilogx(app.UIAxes_2,app.wlist,phase(:))
       hold(app.UIAxes_2,'on')
       semilogx(app.UIAxes_2,app.wlist,phased3(:))
       semilogx(app.UIAxes_2,app.wlist,phased(:))
       semilogx(app.UIAxes_2,app.wlist,phased2(:))
       semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(app.PM-180),'g--','linewidth',2)
       grid(app.UIAxes_2,'on')
       ylim(app.UIAxes_2,[-220,max(phase)]);
       %legend(app.UIAxes_2,'G*K_p','G*Lead','G*Lead-lag','Target PM/bandwidth','-180^o','PM')
       
       % add pole and zero positions
       a2  =sqrt(str2num(app.Label_3.Text));
       beta = str2num(app.Label_2.Text);
       plot(app.UIAxes_2,wgd/a2,-180,'ro','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,wgd*a2,-180,'rx','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,[wgd,wgd],[-200,-90],'g:','linewidth',3);       
       plot(app.UIAxes_2,wgd/10,-180,'rs','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,wgd/(10*beta),-180,'r*','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,-lagzero,-180,'bs','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,-lagpole,-180,'b*','linewidth',3,'markersize',16);
              semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(-180),'k-','linewidth',3)
       legend(app.UIAxes_2,'G*K_p','G*Lag','G*Lead','G*Lead-lag','Target PM','Lead zero','Lead pole','Target bandwidth',...
           'Lead-Lag zero','Lead-Lag pole','Lag zero','Lag pole','Location','Northwest')
       xlim(app.UIAxes_2,[app.wlist(1),app.wlist(end)]);
       
      % Update display of values
      app.PhiLabel.Text = [num2str(round(PM)),',  ',...
          num2str(round(PMlag)),',  ',num2str(round(PMlead)),',  ',num2str(round(PMd))];
      
      app.OffsetLabel.Text = [num2str(1/(1+dcgain(app.G)*app.Kp)),',   ', ...
          num2str(1/(1+dcgain(app.G)*dcgain(app.MLAG))),',   ', ...
          num2str(1/(1+dcgain(app.G)*dcgain(app.MLEAD))),',   ', ...
          num2str(1/(1+dcgain(app.G)*dcgain(app.MLEADLAG)))];
      
      app.wcLabel.Text = num2str([wg,wlag,wlead,wgl]);
        
        if any(roots(app.denExample)==0)
            val = dcgain(tf([1 0],1)*app.G*app.Kp);
            app.RamperrorLabel.Text = [num2str(1/(val)),',   ',...
                num2str(1/(dcgain(app.MLAG)*val)),',   ',num2str(a2/val),...
                ',   ',num2str(a2/(val*beta))];
        else
            app.RamperrorLabel.Text = 'NA';
        end

            
    end   % end of getresponses subfunction


    % PLOT THE SYSTEM RESPONSES for proportional and lead compensation and lead-lag 
     function app = plotresponses(app)

                        
            % Feedback
            G1 = feedback(app.G*app.Kp,1);
            G1u = feedback(app.Kp,app.G);
            G1lag = feedback(app.G*app.MLAG,1);
            G1ulag = feedback(app.MLAG,app.G);
            G1d = feedback(app.G*app.MLEAD,1);
            G1ud = feedback(app.MLEAD,app.G);
            G1d2 = feedback(app.G*app.MLEADLAG,1);
            G1ud2 = feedback(app.MLEADLAG,app.G);
            
            
            % Closed-loop responses either steps or ramps
            if app.int==0  % doing steps
                [y,t] = step(G1,app.endtime);
                [u] = step(G1u,t);
                [ulag] = step(G1ulag,t);
                [ylag] = step(G1lag,t);
                [yd] = step(G1d,t);
                [ud] = step(G1ud,t);
                [yd2] = step(G1d2,t);
                [ud2] = step(G1ud2,t);
                e = 1-y;
                elag = 1 - ylag;
                elead = 1 - yd;
                eleadlag=1-yd2;
            else % doing ramps
                t=linspace(0,app.endtime,101)';
                ylead = lsim(G1d,t,t);
                yleadlag = lsim(G1d2,t,t);
                ylag = lsim(G1lag,t,t);
                ykp = lsim(G1,t,t);
                ud = lsim(G1ud,t,t);
                ud2 = lsim(G1ud2,t,t);
                
                ulag = lsim(G1ulag,t,t);
                u = lsim(G1u,t,t);
                e=t-ykp;
                elag = t-ylag;
                elead= t - ylead;
                eleadlag = t - yleadlag;
            end

            % Output plot
            hold(app.UIAxes2_2,'off')
            plot(app.UIAxes2_2,t,e,t,elag,t,elead,t,eleadlag);
            hold(app.UIAxes2_2,'on')
            plot(app.UIAxes2_2,[t(1),t(end)],[1,1]*(app.OffsetSlider.Value/100),'g--','linewidth',2);
            legend(app.UIAxes2_2, 'G*K_p', 'G*Lag','G*Lead','G*Lead-lag',...
                'Target','Location','northeast');
            grid(app.UIAxes2_2,'on')
            y1 = min([0,min(e)]); y2 = max(e); d = y2-y1;
            ylim(app.UIAxes2_2,[y1-d/10 max([1.05,y2+d/10])]); % Always include unity
            % Input plot
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,t,u,t,ulag,t,ud,t,ud2);
            legend(app.UIAxes2, 'G*K_p','G*Lag','G*Lead','G*Lead-lag');
            grid(app.UIAxes2,'on')
            
 
        end

        % Display transfer function and control law in text boxes
        function updateTransferFunctionRender(app)
            n=app.numExample; 
            d=app.denExample;
            numerator_str = array2StringPolynomial(app,n);
            denumerator_str = array2StringPolynomial(app,d);
            % alpha = str2num(app.Label_3.Text);
            % beta = str2num(app.Label_2.Text);
            % a2 = sqrt(alpha);
            %delaystr = num2str(app.DelaySlider.Value);
            app.GsLabel.Text = "$\frac{"+numerator_str+"}"+"{"+denumerator_str+"}$";
             
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%% SUBFUNCTION TO CREATE A TEXT DISPLAY OF A POLYNOMIAL
        function auxmsgNum = array2StringPolynomial(app,n)
            lenNum = length(n);
            auxmsgNum= "";
            ind = 1;
            for i=lenNum:-1:1
                ncoef = n(ind);
                if n(ind)<0
                    prefix = '';
                else
                    prefix = '+';
                end

                if abs(n(ind))==1 && ind~=lenNum
                    ncoef=[];
                end

                if lenNum==1
                    auxmsgNum = auxmsgNum + num2str(ncoef);
                else
                    if i==lenNum && lenNum~=2
                        auxmsgNum = auxmsgNum + num2str(ncoef) +"s^"+num2str(i-1);

                    elseif i==lenNum && lenNum==2
                        auxmsgNum = auxmsgNum + num2str(ncoef) +"s";

                    elseif i == 1

                        auxmsgNum = auxmsgNum + prefix+ num2str(ncoef);

                    elseif i==2
                        auxmsgNum = auxmsgNum + prefix+ num2str(ncoef) +"s";

                    else
                        auxmsgNum = auxmsgNum + prefix +num2str(ncoef) +"s^"+num2str(i-1);
                    end
                end
                ind = ind + 1;
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Illustrates a range of Kp choices on a single figure

        % update plots and data when something is changed
        function updateallplots(app)
            
            app = definecompensators(app);
            app = getbode_diagram(app);
            app = plotresponses(app); 
            updateTransferFunctionRender(app);
            
        end


        % use the mechanistic rules to define all the required compensators
        % if integrator use ramp error offset
        % if no integrator use step offset

        function app = definecompensators(app)
            
            [n,d]= tfdata(app.G,'v');
            if app.int ==1  % Check for integrator
                G2 = tf(n,d(1:end-1));
            end 
            desiredPM =app.PMSlider.Value;  % target PM
            offset = app.OffsetSlider.Value*0.01;  % target offset
            wc =app.BandwidthSlider.Value; % target bandwidth
            app.warningtext=' ';
            
                        
            % proportional design
            wmin=wc/100;
            wmax=wc*10;
            [gain,phase,wlist] = bode(app.G,{wmin,wmax});
            [Gm,Pm,wcp,wcg] = margin(gain(:)',phase(:)'-desiredPM,wlist);
            app.Kp = Gm;wclag=wcp;
            app.Label.Text = num2str(Gm);
            
            % lag design
            if app.int==1
               plag =1/(dcgain(G2)*app.Kp*offset);
            else
                plag =((1/offset)-1)/(dcgain(app.G)*app.Kp);
            end
            if plag>10
                plag =10;                        
               app.warningtext ='Offset not achievable by Lag. ';
               % if app.int==1;
               %     offset = 1/(dcgain(G2*app.Kp*10));
               % else
               %     offset = 1/(1+dcgain(app.G*app.Kp*10));
               % end
               % app.OffsetSlider.Value=offset*100;
            elseif plag<1
                plag =1;
            end
            app.MLAG = minreal(app.Kp*tf([1,wclag/10],[1,wclag/(10*plag)]));          
            
            
            % lead design
            [gainGwc,phaseGwc] = bode(app.G,wc);
            uplift = -180 - phaseGwc +desiredPM;
            if uplift>55
                app.warningtext=[app.warningtext,'Bandwidth not achievable. '];
                wc = wclag*2;
                %app.BandwidthSlider.Value=wc;
                %[gainGwc,phaseGwc] = bode(app.G,wc);
                uplift = 54.9;
            elseif uplift<0
                uplift=0;
                app.warningtext=[app.warningtext,'Lead not needed. '];
            end
            upliftrad=uplift*pi/180;
            r = (sin(upliftrad)+1)/(1-sin(upliftrad));
            sr=sqrt(r);
            K= sr/gainGwc;
            zero = wc/sr;
            pole = wc*sr;
            app.MLEAD = K*tf([1,zero],[1,pole]);
            app.Label_3.Text = num2str(r);
            
            % lead-lag design
            if app.int==1
                p= 1/(dcgain(app.MLEAD)*dcgain(G2)*offset);   %gainrecovery
            else
                p=((1/offset)-1)/(dcgain(app.MLEAD)*dcgain(app.G));
            end
            
            if p<1;p=1;
                app.warningtext=[app.warningtext,'Lag of Lead-lag not needed.'];
            elseif p>10 
                p=10;
                app.warningtext=[app.warningtext,'Offset not achievable by Lead-lag.'];
            end
            app.Label_2.Text = num2str(p);
            app.MLEADLAG=minreal(app.MLEAD*tf([1,wc/10],[1,wc/(p*10)]));
            

        end


        end  % end of sub functions section
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % resize to be in middle of  the window
            movegui(app.UIFigure)

            % Open the manual
            if exist('lead_lag_design_criteria_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open lead_lag_design_criteria_mycopy.mlx
            else           
                p1 = which('lead_lag_design_criteria_manual.mlx');
                p2 = strcat(pwd,'/lead_lag_design_criteria_mycopy.mlx');
                copyfile(p1,p2)           
                open lead_lag_design_criteria_mycopy.mlx
            end

            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);

            app.SecondorderDropDown.Value = '8/[(s+1)(s+2)]';
            SecondorderDropDownValueChanged(app);
            
            updateallplots(app);
            try
                if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                else
                whitebg(app.UIFigure)
                end
            end


        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
           % 
          updateallplots(app)          

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
        end

        % Value changed function: SecondorderDropDown
        function SecondorderDropDownValueChanged(app, event)
            % Select desired second order system and update plots
            value = app.SecondorderDropDown.Value;
            switch value
                case '2/[s(s+1)]' 
                    app.G =tf(2,[1 1 0]);
                    app.numExample =2 ;
                    app.denExample = [1 1 0];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 1;
                    app.offset = 'ramp';
                case '8/[(s+1)(s+2)]' 
                    app.G = tf(8,[1 3 2]);
                    app.numExample =8;
                    app.denExample = [1 3 2];
                    app.endtime =6;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 0;
                    app.offset = 'step';
                
                case '0.4/[(s+0.2)(s+0.1)]' 
                    app.G = tf(0.4,[1 0.3 0.02]);
                    app.numExample = 0.4;
                    app.denExample = [1 0.3 0.02];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '1/[(s^2+0.2s+1)]' 
                    app.G = tf(1,[1 0.2 1]);
                    app.numExample = 1;
                    app.denExample = [1 0.2 1];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '20/[(s^2+s+10)]' 
                    app.G = tf(20,[1 1 10]);
                    app.numExample = 20;
                    app.denExample = [1 1 10];
                    app.endtime =5;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '0.1/[(s^2+0.2s+0.03)]' 
                    app.G = tf(0.1,[1 0.2 0.03]);
                    app.numExample = .1;
                    app.denExample = [1 .2 0.03];
                    app.endtime =55;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                    
            end
            updateallplots(app)

        end

        % Value changed function: HighorderDropDown
        function HighorderDropDownValueChanged(app, event)
            % Select desired high order system and update plots
            value = app.HighorderDropDown.Value;
            
            switch value
                case '2/[s(s+1)(s+2)]' 
                    app.G =tf(2,[1 3 2 0]);
                    app.numExample =2;
                    app.denExample = [1 3 2 0];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200);
                    app.int = 1;
                    app.offset = 'ramp';
                case '20/[(s+1)(s+2)(s+4)]' 
                    app.G = tf(20,[1 7 14 8]);
                    app.numExample =20;
                    app.denExample = [1 7 14 8];
                    app.endtime =5;
                    app.wlist = logspace(-2,2,200);
                    app.int = 0;
                    app.offset = 'step';
                case '5(s+2)/[s(s+1)(s+4)]' 
                    app.G = tf(5*[1 2],[1 5 4 0]);
                    app.numExample =5*[1 2];
                    app.denExample = [1 5 4 0];
                    app.endtime =6;
                    app.wlist = logspace(-3,1,200);
                    app.int = 1;
                    app.offset = 'ramp';
                case '5(s+4)/[(s+1)(s+1)(s+2)]' 
                    app.G = tf(5*[1 4],[1 4 5 2]);
                    app.numExample = 5*[1 4];
                    app.denExample = [1 4 5 2];
                    app.endtime =4;
                    app.wlist = logspace(-3,1,200);
                    app.int = 0;
                    app.offset = 'step';

               case '5(s+2)/[(s+1)(s^2+0.4s+16)]' 
                    app.G = tf(5*[1 2],[1 1.4 16.4 16]);
                    app.numExample = 5*[1 2];
                    app.denExample = [1 1.4 16.4 16];
                    app.endtime =10;
                    app.wlist = logspace(-2,2,200);
                    app.int = 0;
                    app.offset = 'step';

            end
            
            updateallplots(app)


        end

        % Clicked callback: SecondorderDropDown
        function SecondorderDropDownClicked(app, event)
            % item = event.InteractionInformation.Item;
            SecondorderDropDownValueChanged(app);
            updateallplots(app)
            
        end

        % Clicked callback: HighorderDropDown
        function HighorderDropDownClicked(app, event)
            % item = event.InteractionInformation.Item;
            HighorderDropDownValueChanged(app);
            updateallplots(app)
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
            value = app.BandwidthSlider.Value;
            value = round(value,1);
            app.BandwidthSlider.Value = value;
            updateallplots(app)
            
        end

        % Value changed function: PMSlider
        function PMSliderValueChanged(app, event)
            value = app.PMSlider.Value;
            value = round(value*2,-1)/2;
            app.PMSlider.Value=value;
            app.PM=value;
            updateallplots(app)
           
            
        end

        % Value changed function: OffsetSlider
        function OffsetSliderValueChanged(app, event)
            value = app.OffsetSlider.Value;
            value = round(value,0);
            app.OffsetSlider.Value = value;
            updateallplots(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CompensatordesignwithPMbandwidthandoffsetcriteriaLabel = uilabel(app.GridLayout2);
            app.CompensatordesignwithPMbandwidthandoffsetcriteriaLabel.Text = 'Compensator design with PM, bandwidth and offset criteria';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UpdateplotsButton = uibutton(app.GridLayout3, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ChoosemodelLabel = uilabel(app.GridLayout4);
            app.ChoosemodelLabel.Text = 'Choose model';
            app.SecondorderDropDownLabel = uilabel(app.GridLayout4);
            app.SecondorderDropDownLabel.Text = 'Second order';
            app.SecondorderDropDown = uidropdown(app.GridLayout4);
            app.SecondorderDropDown.Items = {'2/[s(s+1)]', '8/[(s+1)(s+2)]', '0.4/[(s+0.2)(s+0.1)]', '1/[(s^2+0.2s+1)]', '20/[(s^2+s+10)]', '0.1/[(s^2+0.2s+0.03)]'};
            app.SecondorderDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondorderDropDownValueChanged, true);
            app.SecondorderDropDown.ClickedFcn = createCallbackFcn(app, @SecondorderDropDownClicked, true);
            app.SecondorderDropDown.Value = '2/[s(s+1)]';
            app.HighorderDropDownLabel = uilabel(app.GridLayout4);
            app.HighorderDropDownLabel.Text = 'High order';
            app.HighorderDropDown = uidropdown(app.GridLayout4);
            app.HighorderDropDown.Items = {'2/[s(s+1)(s+2)]', '20/[(s+1)(s+2)(s+4)]', '5(s+2)/[s(s+1)(s+4)]', '5(s+4)/[(s+1)(s+1)(s+2)]', '5(s+2)/[(s+1)(s^2+0.4s+16)]'};
            app.HighorderDropDown.ValueChangedFcn = createCallbackFcn(app, @HighorderDropDownValueChanged, true);
            app.HighorderDropDown.ClickedFcn = createCallbackFcn(app, @HighorderDropDownClicked, true);
            app.HighorderDropDown.Value = '2/[s(s+1)(s+2)]';
            app.DetailedanalysisoffirstordermodelparametersPanel = uipanel(app.GridLayout);
            app.DetailedanalysisoffirstordermodelparametersPanel.Title = 'Detailed analysis of margins, offset  and closed-loop behaviour (Proportional, Lag, Lead and Lead-lag)  [Ramp targets used if integrators]';
            app.GridLayout5 = uigridlayout(app.DetailedanalysisoffirstordermodelparametersPanel);
            app.UIAxes2_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2_2, 'Closed-loop errors')
            xlabel(app.UIAxes2_2, 'Time (s)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2, 'Closed-loop input')
            xlabel(app.UIAxes2, 'Time (s)')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.CurrentphasemarginLabel = uilabel(app.GridLayout7);
            app.CurrentphasemarginLabel.Text = 'Current phase margin';
            app.GaincrossoverfrequencyLabel = uilabel(app.GridLayout7);
            app.GaincrossoverfrequencyLabel.Text = 'Gain cross over frequency';
            app.ClosedloopoffsetstepsLabel = uilabel(app.GridLayout7);
            app.ClosedloopoffsetstepsLabel.Text = 'Closed-loop offset (steps)';
            app.ClosedloopoffsetrampsLabel = uilabel(app.GridLayout7);
            app.ClosedloopoffsetrampsLabel.Text = 'Closed-loop offset (ramps)';
            app.CurrentmodelLabel = uilabel(app.GridLayout7);
            app.CurrentmodelLabel.Interpreter = 'latex';
            app.CurrentmodelLabel.Text = 'Current model ';
            app.GsLabel = uilabel(app.GridLayout7);
            app.GsLabel.Interpreter = 'latex';
            app.GsLabel.Text = 'G(s)';
            app.PhiLabel = uilabel(app.GridLayout7);
            app.PhiLabel.Interpreter = 'latex';
            app.PhiLabel.Text = 'Phi =';
            app.wcLabel = uilabel(app.GridLayout7);
            app.wcLabel.Text = 'wc =';
            app.OffsetLabel = uilabel(app.GridLayout7);
            app.OffsetLabel.Text = 'Offset = ';
            app.RamperrorLabel = uilabel(app.GridLayout7);
            app.RamperrorLabel.Text = 'Ramp error = ';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SpecificationsLabel = uilabel(app.GridLayout6);
            app.SpecificationsLabel.Text = 'Specifications';
            app.PMSliderLabel = uilabel(app.GridLayout6);
            app.PMSliderLabel.Text = 'PM';
            app.PMSlider = uislider(app.GridLayout6);
            app.PMSlider.Limits = [30 80];
            app.PMSlider.ValueChangedFcn = createCallbackFcn(app, @PMSliderValueChanged, true);
            app.PMSlider.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.PMSlider.Value = 60;
            app.OffsetLabel_2 = uilabel(app.GridLayout6);
            app.OffsetLabel_2.Text = 'Bandwidth';
            app.BandwidthSlider = uislider(app.GridLayout6);
            app.BandwidthSlider.Limits = [0 6];
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSlider.Value = 1;
            app.OffsetSliderLabel = uilabel(app.GridLayout6);
            app.OffsetSliderLabel.Text = 'Offset (%)';
            app.OffsetSlider = uislider(app.GridLayout6);
            app.OffsetSlider.Limits = [0 30];
            app.OffsetSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetSliderValueChanged, true);
            app.OffsetSlider.Value = 10;
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.LeadlagControlparametersLabel = uilabel(app.GridLayout9);
            app.LeadlagControlparametersLabel.Text = 'Lead-lag Control parameters';
            app.KpLabel = uilabel(app.GridLayout9);
            app.KpLabel.Text = 'Kp';
            app.LeadpolezeroratioLabel = uilabel(app.GridLayout9);
            app.LeadpolezeroratioLabel.Text = 'Lead pole/zero ratio';
            app.LagzeropoleratioLabel = uilabel(app.GridLayout9);
            app.LagzeropoleratioLabel.Text = 'Lag zero/pole ratio';
            app.Label = uilabel(app.GridLayout9);
            app.Label.Text = '1';
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = '1';
            app.Label_2 = uilabel(app.GridLayout9);
            app.Label_2.Text = '1';
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout11);
            title(app.UIAxes, 'Bode gain diagrams')
            xlabel(app.UIAxes, 'Frequency (rad/s)')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout11);
            title(app.UIAxes_2, 'Phase diagram and phase margin (PM)')
            xlabel(app.UIAxes_2, 'Frequency (rad/s)')
            ylabel(app.UIAxes_2, 'Degrees')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_lag_design_criteria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end