classdef lead_lag_design_phase_margin < matlab.apps.AppBase
    %LEAD_LAG_DESIGN_PHASE_MARGIN
    %   File written by J.A.Rossiter, University of Sheffield  
    %   See website for more files like this.
    %   https://sites.google.com/sheffield.ac.uk/controleducation/mainindex
    %   
    %   A simple interface to illustrate the concept of simple Lead-Lag
    %   compensation design using frequency response analysis.
    %   
    %   Users can select from a number of predefined systems: 1st , 2nd and
    %   3rd order. These have a variety of time constants and also some
    %   have under-damped modes to illustrate resonance. Secondly they can
    %   change the proportional gain and pole/zero ratio of both the lead
    %   and lag components and see how the phase margin and closed-loop
    %   behaviour changes. The manula gives more guidance on what happens
    %   behind the scenes to help guide the user to a good design.
    %   
    %   Some notes and engineering background are in the partner
    %   livescript file:    lead_design_phase_margin_manual.mlx
    %   A group of similar files can be downloaded through MATLAB
    %   add-ons with the toolbox name:  control101

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        GridLayout9                   matlab.ui.container.GridLayout
        polezeroratiolagSlider        matlab.ui.control.Slider
        polezeroratiolagSliderLabel   matlab.ui.control.Label
        polezeroratioleadSlider       matlab.ui.control.Slider
        polezeroratioleadSliderLabel  matlab.ui.control.Label
        KpSlider                      matlab.ui.control.Slider
        KpSliderLabel                 matlab.ui.control.Label
        ControlparametersLabel        matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        OffsetSlider                  matlab.ui.control.Slider
        OffsetSliderLabel             matlab.ui.control.Label
        BandwidthSlider               matlab.ui.control.Slider
        OffsetLabel_2                 matlab.ui.control.Label
        PMSlider                      matlab.ui.control.Slider
        PMSliderLabel                 matlab.ui.control.Label
        SpecificationsLabel           matlab.ui.control.Label
        DetailedanalysisoffirstordermodelparametersPanel  matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        MsLabel                       matlab.ui.control.Label
        ControlLawLabel               matlab.ui.control.Label
        RamperrorLabel                matlab.ui.control.Label
        OffsetLabel                   matlab.ui.control.Label
        wcLabel                       matlab.ui.control.Label
        PhiLabel                      matlab.ui.control.Label
        GsLabel                       matlab.ui.control.Label
        CurrentmodelLabel             matlab.ui.control.Label
        ClosedloopoffsetrampsLabel    matlab.ui.control.Label
        ClosedloopoffsetstepsLabel    matlab.ui.control.Label
        GaincrossoverfrequencyLabel   matlab.ui.control.Label
        CurrentphasemarginLabel       matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        GridLayout4                   matlab.ui.container.GridLayout
        HighorderDropDown             matlab.ui.control.DropDown
        HighorderDropDownLabel        matlab.ui.control.Label
        SecondorderDropDown           matlab.ui.control.DropDown
        SecondorderDropDownLabel      matlab.ui.control.Label
        FirstorderDropDown            matlab.ui.control.DropDown
        FirstorderDropDownLabel       matlab.ui.control.Label
        ChoosemodelLabel              matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        OverlayseveralLeadButton      matlab.ui.control.Button
        OverlayseveralKpButton        matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        LeadLagdesignwithaphasemarginbandwidthandoffsetcriteriaLabel  matlab.ui.control.Label
    end


    % The next section defines all the core variables used in the code and
    % that it may be convenient to have available in the various
    % subfunctions
    properties (Access = private)
        originalsize     % product of width and height for font sizing

        G = tf(2,[1 1]);  % model no delay
        numExample =2   % system numerator
        denExample =[1 1]  % system denominator
        wlist = logspace(-2,1,200) ;  % frequency list
        Gd  % Model with delay
        u   % sinsoidal signal
        y   % response no delay
        yd  % response with delay
        omega % frequency
        tvec  % time vector for plotting
        endtime=5;  % default simulation tome
      
        PM =60 ;  % Target phase margin
        int =0;  % tag for whether an integrator included
        offset = 'step' % tag for ramp or step offset criteria
        LEAD = 1;  % to store lead compensator
        LAG = 1; % store lag component
        wg        % cross over frequency based on G*Kp

          
    end
    
    methods (Access = private)


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%  Down load data from sliders and create responses AND BODE
 %%%%%%%%% and simple data such as offsets and PM
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    function app = getbode_diagram(app)
       
       % collect data from sliders
       Kp = app.KpSlider.Value;
       alpha = app.polezeroratioleadSlider.Value;
       beta = app.polezeroratiolagSlider.Value;
       a2 = sqrt(alpha);
       wgd = app.BandwidthSlider.Value;  % desired bandwith so use for pole/zero choices
  
       
       % get margins with just proportional
       warning('off','Control:analysis:MarginUnstable');
       [GM,PM,wp,wg] = margin(app.G*Kp);  % core frequency is wg
       wg = round(wg,2,'significant');
       
       % check that cross over frequency is meaningful
       if isnan(wg);
           app.wg=1;
           alpha=1;
           LEAD=1;
           PM=0;
           PMd=0;
       else
           % Lead-lag design
           LEAD = Kp*tf([1,wgd/a2],[1,wgd*a2])*a2;
           LAG = tf([1,wgd/10],[1,wgd/(10*beta)]);
           [GMd,PMd,wpl,wgl] = margin(app.G*LEAD*LAG);
           [GMlead,PMlead] = margin(app.G*LEAD);
           
       end
       app.LEAD = LEAD;
       app.LAG = LAG;
       

       % Set up plots
       app.PM = app.PMSlider.Value;
       offset = app.BandwidthSlider.Value;
       [gain,phase]=bode(app.G,app.wlist);
       [gaind,phased]=bode(app.G*LEAD,app.wlist);
       [gaind2,phased2]=bode(app.G*LEAD*LAG,app.wlist);
      
       % plot gain
       hold(app.UIAxes,'off')
       semilogx(app.UIAxes,app.wlist,20*log10(gain(:)),':','color',[0.7 .3 1],'linewidth',2)
       hold(app.UIAxes,'on')
       grid(app.UIAxes,'on')
       semilogx(app.UIAxes,app.wlist,20*log10(gain(:)*Kp),'b','linewidth',2)
       semilogx(app.UIAxes,app.wlist,20*log10(gaind(:)),'r','linewidth',2)
       semilogx(app.UIAxes,app.wlist,20*log10(gaind2(:)),'c','linewidth',2)

       semilogx(app.UIAxes,[app.wlist(1),app.wlist(end)],[1 1]*(0),'k-','linewidth',3)
       ylim(app.UIAxes,[-20,max(20*log10(gain(:)*2))]);
       plot(app.UIAxes,[wgd,wgd],[-20,20],'g--','linewidth',3);
       legend(app.UIAxes,'G','G*Kp','G*Lead','G*Lead-lag,','0dB','Target bandwidth')
       text(app.UIAxes,app.wlist(10),-10,['K_p = ',num2str(Kp), ',   \alpha = ',num2str(alpha),...
           ',   \beta = ',num2str(beta),',  w_g = ',num2str(wgd)],'fontsize',24)

       % plot phase
       hold(app.UIAxes_2,'off')
       semilogx(app.UIAxes_2,app.wlist,phase(:),'b')
       hold(app.UIAxes_2,'on')
       semilogx(app.UIAxes_2,app.wlist,phased(:),'r')
       semilogx(app.UIAxes_2,app.wlist,phased2(:),'c')
       semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(app.PM-180),'g--','linewidth',2)
       semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(-180),'k-','linewidth',3)
       grid(app.UIAxes_2,'on')
       ylim(app.UIAxes_2,[-220,max(phase)]);
       plot(app.UIAxes_2,[wgl,wgl],[-180,-180+PMd],'m:','linewidth',3);
       %legend(app.UIAxes_2,'G*K_p','G*Lead','G*Lead-lag','Target PM/bandwidth','-180^o','PM')
       
       % add pole and zero positions
       plot(app.UIAxes_2,wgd/a2,[-180],'ro','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,wgd*a2,[-180],'rx','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,[wgd,wgd],[-200,-90],'g:','linewidth',3);       
       plot(app.UIAxes_2,wgd/10,[-180],'rs','linewidth',3,'markersize',16);
       plot(app.UIAxes_2,wgd/(10*beta),[-180],'r*','linewidth',3,'markersize',16);
       legend(app.UIAxes_2,'G*K_p','G*Lead','G*Lead-lag','Target PM','-180^o','PM','Lead zero','Lead pole','Target bandwidth',...
           'Lag zero','Lag pole')
       
      % Update display of values
      app.PhiLabel.Text = [num2str(round(PM)),',  ',num2str(round(PMlead)),',  ',num2str(round(PMd))];
      app.OffsetLabel.Text = [num2str(1/(1+dcgain(app.G)*Kp)),',   ', ...
          num2str(1/(1+dcgain(app.G)*dcgain(LEAD))),',   ', ...
          num2str(1/(1+dcgain(app.G)*dcgain(LEAD*LAG)))];
      app.wcLabel.Text = num2str(wg);
        
        if any(roots(app.denExample)==0);
            val = dcgain(tf([1 0],1)*app.G*Kp);
            app.RamperrorLabel.Text = [num2str(1/(val)),',   ',num2str(a2/val),',   ',num2str(a2/(val*beta))];
        else
            app.RamperrorLabel.Text = 'NA';
        end

            
    end   % end of getresponses subfunction


    % PLOT THE SYSTEM RESPONSES for proportional and lead compensation and lead-lag 
     function app = plotresponses(app)

            Kp = app.KpSlider.Value;
            
            % Feedback
            G1 = feedback(app.G*Kp,1);
            G1u = feedback(Kp,app.G);
            G1d = feedback(app.G*app.LEAD,1);
            G1ud = feedback(app.LEAD,app.G);
            G1d2 = feedback(app.G*app.LEAD*app.LAG,1);
            G1ud2 = feedback(app.LEAD*app.LAG,app.G);
            % Closed-loop responses
            [y,t] = step(G1,app.endtime);
            [u] = step(G1u,t);
            [yd] = step(G1d,t);
            [ud] = step(G1ud,t);
            [yd2] = step(G1d2,t);
            [ud2] = step(G1ud2,t);

            % Output plot
            hold(app.UIAxes2_2,'off')
            plot(app.UIAxes2_2,t,y,'b',t,yd,'r-',t,yd2,'c');
            hold(app.UIAxes2_2,'on')
            plot(app.UIAxes2_2,[t(1),t(end)],[1,1]*(1-app.OffsetSlider.Value/100),'g--','linewidth',2);
            legend(app.UIAxes2_2, 'G*K_p', 'G*Lead','G*Lead-lag','Location','southeast');
            grid(app.UIAxes2_2,'on')
            y1 = min(y); y2 = max(y); d = y2-y1;
            ylim(app.UIAxes2_2,[y1-d/10 max([1.05,y2+d/10])]); % Always include unity
            % Input plot
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,t,u,'b',t,ud,'r-',t,ud2,'c');
            legend(app.UIAxes2, 'G*K_p', 'G*Lead','G*Lead-lag');
            grid(app.UIAxes2,'on')
            
 
        end

        % Display transfer function and control law in text boxes
        function updateTransferFunctionRender(app)
            n=app.numExample; 
            d=app.denExample;
            numerator_str = array2StringPolynomial(app,n);
            denumerator_str = array2StringPolynomial(app,d);
            alpha = app.polezeroratioleadSlider.Value;
            beta = app.polezeroratiolagSlider.Value;
            a2 = sqrt(alpha);
            %delaystr = num2str(app.DelaySlider.Value);
            app.GsLabel.Text = "$\frac{"+numerator_str+"}"+"{"+denumerator_str+"}$";
            wgd = app.BandwidthSlider.Value;  % desired bandwith so use for pole/zero choices

            % text for lead component
            if isnumeric(app.LEAD);
                numerator_str=num2str(app.KpSlider.Value);
                denumerator_str ='1';
            else
                
                n=round([1,wgd/a2]*a2,2,'significant');
                d=[1,round(wgd*a2,2,'significant')];
            
                numerator_str = strcat(num2str(app.KpSlider.Value),"(",array2StringPolynomial(app,n),")");
                denumerator_str = strcat("(",array2StringPolynomial(app,d),")");
                
            end
           
            % Text for Lag component
            if not(isnumeric(app.LAG));
                n=round([1,wgd/10],2,'significant');
                d=[1,round(wgd/(10*beta),2,'significant')];
                numerator_str = strcat(numerator_str,"(",array2StringPolynomial(app,n),")");
                denumerator_str = strcat(denumerator_str,"(",array2StringPolynomial(app,d),")");
            end

            app.MsLabel.Text = "$\frac{"+numerator_str+"}"+"{"+denumerator_str+"}$";

        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%% SUBFUNCTION TO CREATE A TEXT DISPLAY OF A POLYNOMIAL
        function auxmsgNum = array2StringPolynomial(app,n)
            lenNum = length(n);
            auxmsgNum= "";
            ind = 1;
            for i=lenNum:-1:1
                ncoef = n(ind);
                if n(ind)<0
                    prefix = '';
                else
                    prefix = '+';
                end

                if abs(n(ind))==1 && ind~=lenNum
                    ncoef=[];
                end

                if lenNum==1
                    auxmsgNum = auxmsgNum + num2str(ncoef);
                else
                    if i==lenNum && lenNum~=2
                        auxmsgNum = auxmsgNum + num2str(ncoef) +"s^"+num2str(i-1);

                    elseif i==lenNum && lenNum==2
                        auxmsgNum = auxmsgNum + num2str(ncoef) +"s";

                    elseif i == 1

                        auxmsgNum = auxmsgNum + prefix+ num2str(ncoef);

                    elseif i==2
                        auxmsgNum = auxmsgNum + prefix+ num2str(ncoef) +"s";

                    else
                        auxmsgNum = auxmsgNum + prefix +num2str(ncoef) +"s^"+num2str(i-1);
                    end
                end
                ind = ind + 1;
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Illustrates a range of Kp choices on a single figure
        function results = rangeofkp(app)

            Kp=[0.1,0.2,0.4,0.7,1,1.4,2]*2;
            wgd = app.BandwidthSlider.Value;  % desired bandwith so use for pole/zero choices
            app.PM = app.PMSlider.Value;
            [gain,phase]=bode(app.G,app.wlist);
            hold(app.UIAxes,'off')
            hold(app.UIAxes_2,'off')
            hold(app.UIAxes2,'off')
            hold(app.UIAxes2_2,'off')
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes2_2)
            cla(app.UIAxes2)
           hold(app.UIAxes,'on')
           hold(app.UIAxes_2,'on')
           hold(app.UIAxes2,'on')
           hold(app.UIAxes2_2,'on')


           for k=1:length(Kp);
                  
               % Frequency domain plots
               semilogx(app.UIAxes,app.wlist,20*log10(gain(:)*Kp(k)));
               legstring{k}=['K_p = ',num2str(Kp(k))];

               [GM,PM,wp,wg] = margin(app.G*Kp(k)); 
               if PM == Inf; PM=1;end   % to manage plotting colours    
               plot(app.UIAxes_2,[wg,wg],[-180,-180+PM],'linewidth',3);

                % Closed-loop responses
                G1 = feedback(app.G*Kp(k),1);
                G1u = feedback(Kp(k),app.G);
                [y,t] = step(G1,app.endtime);
                [u] = step(G1u,t);
                plot(app.UIAxes2_2,t,y);
                plot(app.UIAxes2,t,u);
            
               
           end
            plot(app.UIAxes_2,[wgd,wgd],[-200,-90],'g--','linewidth',3);
            plot(app.UIAxes,[wgd,wgd],[-20,20],'g--','linewidth',3);
            

           % Augment plots with useful lines
            semilogx(app.UIAxes,[app.wlist(1),app.wlist(end)],[1 1]*(0),'k-','linewidth',3)
            ylim(app.UIAxes,[-20,max(20*log10(gain(:)*2))]);
            legend(app.UIAxes2,legstring)
            legstring{k+1}=['Target bandwidth/PM'];
            legend(app.UIAxes,legstring)
            
            semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(-180),'k-','linewidth',3)
            semilogx(app.UIAxes_2,app.wlist,phase(:),'k')
            semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(app.PM-180),'g--','linewidth',2)
            ylim(app.UIAxes_2,[-220,max(phase)]);
            legend(app.UIAxes_2,'off')
            legend(app.UIAxes2_2,'off')

            
        end

        % update plots and data when something is changed
        function updateallplots(app)
            
            app = getbode_diagram(app);
            app = plotresponses(app); 
            updateTransferFunctionRender(app);
            
        end


        end  % end of sub functions section
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % resize to be in middle of  the window
            movegui(app.UIFigure)

            % Open the manual
            if exist('lead_lag_design_phase_margin_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open lead_lag_design_phase_margin_mycopy.mlx
            else           
                p1 = which('lead_lag_design_phase_margin_manual.mlx');
                p2 = strcat(pwd,'/lead_lag_design_phase_margin_mycopy.mlx');
                copyfile(p1,p2)           
                open lead_lag_design_phase_margin_mycopy.mlx
            end

            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);

            app.SecondorderDropDown.Value = '8/[(s+1)(s+2)]';
            SecondorderDropDownValueChanged(app);
            
            updateTransferFunctionRender(app);
            app = getbode_diagram(app);
            app = plotresponses(app);
            try
                if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                else
                whitebg(app.UIFigure)
                end
            end


        end

        % Button pushed function: OverlayseveralKpButton
        function OverlayseveralKpButtonPushed(app, event)
           % Dsiplays  the impact of a range of Kp for the current choice
           % of system
           rangeofkp(app);          

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0;
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
        end

        % Value changed function: FirstorderDropDown
        function FirstorderDropDownValueChanged(app, event)
            % Select desired first order system and update plots
            value = app.FirstorderDropDown.Value;
            switch value
                case '1/s'; 
                    app.G =tf(1,[1 0]);
                    app.numExample =1;
                    app.denExample = [1 0];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200);
                    app.endtime = 5;
                    app.int = 1;
                    app.offset = 'ramp';
                case '2/(s+1)'; 
                    app.G = tf(2,[1 1]);
                    app.numExample =2;
                    app.denExample = [1 1];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.endtime = 3;
                    app.int = 0;
                    app.offset = 'step';
                
                case '0.5/(s+0.1)'; 
                    app.G = tf([0.5],[1 0.1]);
                    app.numExample = [0.5];
                    app.denExample = [1 0.1];
                    app.endtime =55;
                    app.wlist = logspace(-3,1,200); 
                    app.endtime = 10;
                    app.int = 0;
                    app.offset = 'step';
                    
            end
            updateallplots(app)

        end

        % Value changed function: SecondorderDropDown
        function SecondorderDropDownValueChanged(app, event)
            % Select desired second order system and update plots
            value = app.SecondorderDropDown.Value;
            switch value
                case '2/[s(s+1)]'; 
                    app.G =tf(2,[1 1 0]);
                    app.numExample =2 ;
                    app.denExample = [1 1 0];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 1;
                    app.offset = 'ramp';
                case '8/[(s+1)(s+2)]'; 
                    app.G = tf(8,[1 3 2]);
                    app.numExample =8;
                    app.denExample = [1 3 2];
                    app.endtime =6;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '6(s+1)/[s(s+3)]'; 
                    app.G = tf(6*[1 1],[1 3 0]);
                    app.numExample =6*[1 1];
                    app.denExample = [1 3 0];
                    app.endtime =10;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 1;
                    app.offset = 'ramp';
                case '(s+1)/[(s+0.3)(s+0.5)]'; 
                    app.G = tf([1 1],[1 0.8 0.15]);
                    app.numExample = [1 1];
                    app.denExample = [1 .8 .15];
                    app.endtime =5;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '0.4/[(s+0.2)(s+0.1)]'; 
                    app.G = tf([0.4],[1 0.3 0.02]);
                    app.numExample = 0.4;
                    app.denExample = [1 0.3 0.02];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '1/[(s^2+0.2s+1)]'; 
                    app.G = tf([1],[1 0.2 1]);
                    app.numExample = 1;
                    app.denExample = [1 0.2 1];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '20/[(s^2+s+10)]'; 
                    app.G = tf([20],[1 1 10]);
                    app.numExample = 20;
                    app.denExample = [1 1 10];
                    app.endtime =5;
                    app.wlist = logspace(-2,2,200); 
                    app.int = 0;
                    app.offset = 'step';
                case '0.1/[(s^2+0.2s+0.03)]'; 
                    app.G = tf([0.1],[1 0.2 0.03]);
                    app.numExample = .1;
                    app.denExample = [1 .2 0.03];
                    app.endtime =55;
                    app.wlist = logspace(-3,1,200); 
                    app.int = 0;
                    app.offset = 'step';
                    
            end
            updateallplots(app)

        end

        % Value changed function: HighorderDropDown
        function HighorderDropDownValueChanged(app, event)
            % Select desired high order system and update plots
            value = app.HighorderDropDown.Value;
            
            switch value
                case '2/[s(s+1)(s+2)]'; 
                    app.G =tf(2,[1 3 2 0]);
                    app.numExample =2;
                    app.denExample = [1 3 2 0];
                    app.endtime =15;
                    app.wlist = logspace(-3,1,200);
                    app.int = 1;
                    app.offset = 'ramp';
                case '20/[(s+1)(s+2)(s+4)]'; 
                    app.G = tf(20,[1 7 14 8]);
                    app.numExample =20;
                    app.denExample = [1 7 14 8];
                    app.endtime =5;
                    app.wlist = logspace(-2,2,200);
                    app.int = 0;
                    app.offset = 'step';
                case '5(s+2)/[s(s+1)(s+4)]'; 
                    app.G = tf(5*[1 2],[1 5 4 0]);
                    app.numExample =5*[1 2];
                    app.denExample = [1 5 4 0];
                    app.endtime =6;
                    app.wlist = logspace(-3,1,200);
                    app.int = 1;
                    app.offset = 'ramp';
                case '5(s+4)/[(s+1)(s+1)(s+2)]'; 
                    app.G = tf(5*[1 4],[1 4 5 2]);
                    app.numExample = 5*[1 4];
                    app.denExample = [1 4 5 2];
                    app.endtime =4;
                    app.wlist = logspace(-3,1,200);
                    app.int = 0;
                    app.offset = 'step';

               case '5(s+2)/[(s+1)(s^2+0.4s+16)]'; 
                    app.G = tf(5*[1 2],[1 1.4 16.4 16]);
                    app.numExample = 5*[1 2];
                    app.denExample = [1 1.4 16.4 16];
                    app.endtime =10;
                    app.wlist = logspace(-2,2,200);
                    app.int = 0;
                    app.offset = 'step';

            end
            
            updateallplots(app)


        end

        % Clicked callback: SecondorderDropDown
        function SecondorderDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            SecondorderDropDownValueChanged(app);
            updateallplots(app)
            
        end

        % Clicked callback: FirstorderDropDown
        function FirstorderDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            FirstorderDropDownValueChanged(app);
            updateallplots(app)
        end

        % Clicked callback: HighorderDropDown
        function HighorderDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            HighorderDropDownValueChanged(app);
            updateallplots(app)
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            value = app.KpSlider.Value;
            value = round(value,1);
            app.KpSlider.Value = value;
            updateallplots(app)

        end

        % Value changed function: polezeroratioleadSlider
        function polezeroratioleadSliderValueChanged(app, event)
            value = app.polezeroratioleadSlider.Value;
            value = round(value,0);
            app.polezeroratioleadSlider.Value = value;
            updateallplots(app) 
            
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
            value = app.BandwidthSlider.Value;
            value = round(value,1);
            app.BandwidthSlider.Value = value;
            updateallplots(app)
            
        end

        % Value changed function: PMSlider
        function PMSliderValueChanged(app, event)
            value = app.PMSlider.Value;
            value = round(value*2,-1)/2;
            app.PMSlider.Value=value;
            app.PM=value;
            updateallplots(app)
           
            
        end

        % Button pushed function: OverlayseveralLeadButton
        function OverlayseveralLeadButtonPushed(app, event)
            % Change the pole zero ratio for a fixed Kp
            % and overlay the Bode and step response plots for all these
            % choices of lead compensation

            % Define all lead compensators
            alpha=[1,2,3,4,5,7,10];  % pole/zero ratio choices
            Lead = cell(1);gain=cell(1); phase = cell(1);
            wgd = app.BandwidthSlider.Value;  % desired bandwith so use for pole/zero choices

            for k=1:length(alpha);
                a2 = sqrt(alpha(k));
                Lead{k} = app.KpSlider.Value*tf([1,wgd/a2],[1,wgd*a2])*a2;
                [gain{k},phase{k}]=bode(app.G*Lead{k},app.wlist);
            end
            app.PM = app.PMSlider.Value;
            
            hold(app.UIAxes,'off')
            hold(app.UIAxes_2,'off')
            hold(app.UIAxes2,'off')
            hold(app.UIAxes2_2,'off')
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes2_2)
            cla(app.UIAxes2)
           hold(app.UIAxes,'on')
           hold(app.UIAxes_2,'on')
           hold(app.UIAxes2,'on')
           hold(app.UIAxes2_2,'on')

            % use a loop to create Bode and step responses for all the choices
           for k=1:length(alpha);
                  
               % Frequency domain plots
               semilogx(app.UIAxes,app.wlist,20*log10(gain{k}(:)));
               legstring{k}=['\alpha = ',num2str(alpha(k))];
               semilogx(app.UIAxes_2,app.wlist,phase{k}(:));

               [GM,PM,wp,wg] = margin(app.G*Lead{k}); 
               if PM == Inf; PM=1;end   % to manage plotting colours    
               

                % Closed-loop responses
                G1 = feedback(app.G*Lead{k},1);
                G1u = feedback(Lead{k},app.G);
                [y,t] = step(G1,app.endtime);
                [u] = step(G1u,t);
                plot(app.UIAxes2_2,t,y);
                plot(app.UIAxes2,t,u);
            
               
           end
               plot(app.UIAxes_2,[wgd,wgd],[-200,-90],'g--','linewidth',3);
               plot(app.UIAxes,[wgd,wgd],[-20,20],'g--','linewidth',3);
               legstring{k+1}=['Target bandwidth/PM'];


           % Augment plots with useful lines
            semilogx(app.UIAxes,[app.wlist(1),app.wlist(end)],[1 1]*(0),'k-','linewidth',3)
            ylim(app.UIAxes,[-20,max(20*log10(gain{4}(:)*2))]);
            legend(app.UIAxes,legstring)
            legend(app.UIAxes2,legstring{1:length(alpha)})
            
            semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(-180),'k-','linewidth',3)
            semilogx(app.UIAxes_2,app.wlist,phase{1}(:),'k')
            semilogx(app.UIAxes_2,[app.wlist(1),app.wlist(end)],[1 1]*(app.PM-180),'g--','linewidth',2)
            ylim(app.UIAxes_2,[-220,max(phase{1})]);
            legend(app.UIAxes_2,'off')
            legend(app.UIAxes2_2,'off')

            
        end

        % Value changed function: polezeroratiolagSlider
        function polezeroratiolagSliderValueChanged(app, event)
            value = app.polezeroratiolagSlider.Value;
            value = round(value,0);
            app.polezeroratiolagSlider.Value = value;
            updateallplots(app) 
        end

        % Value changed function: OffsetSlider
        function OffsetSliderValueChanged(app, event)
            value = app.OffsetSlider.Value;
            value = round(value,0);
            app.OffsetSlider.Value = value;
            updateallplots(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LeadLagdesignwithaphasemarginbandwidthandoffsetcriteriaLabel = uilabel(app.GridLayout2);
            app.LeadLagdesignwithaphasemarginbandwidthandoffsetcriteriaLabel.Text = 'Lead-Lag design with a phase margin, bandwidth and offset criteria';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.OverlayseveralKpButton = uibutton(app.GridLayout10, 'push');
            app.OverlayseveralKpButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayseveralKpButtonPushed, true);
            app.OverlayseveralKpButton.Text = 'Overlay several Kp';
            app.OverlayseveralLeadButton = uibutton(app.GridLayout10, 'push');
            app.OverlayseveralLeadButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayseveralLeadButtonPushed, true);
            app.OverlayseveralLeadButton.Text = 'Overlay several Lead';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ChoosemodelLabel = uilabel(app.GridLayout4);
            app.ChoosemodelLabel.Text = 'Choose model';
            app.FirstorderDropDownLabel = uilabel(app.GridLayout4);
            app.FirstorderDropDownLabel.Text = 'First order';
            app.FirstorderDropDown = uidropdown(app.GridLayout4);
            app.FirstorderDropDown.Items = {'2/(s+1)', '0.5/(s+0.1)', '1/s'};
            app.FirstorderDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstorderDropDownValueChanged, true);
            app.FirstorderDropDown.ClickedFcn = createCallbackFcn(app, @FirstorderDropDownClicked, true);
            app.FirstorderDropDown.Value = '2/(s+1)';
            app.SecondorderDropDownLabel = uilabel(app.GridLayout4);
            app.SecondorderDropDownLabel.Text = 'Second order';
            app.SecondorderDropDown = uidropdown(app.GridLayout4);
            app.SecondorderDropDown.Items = {'2/[s(s+1)]', '8/[(s+1)(s+2)]', '6(s+1)/[s(s+3)]', '(s+1)/[(s+0.3)(s+0.5)]', '0.4/[(s+0.2)(s+0.1)]', '1/[(s^2+0.2s+1)]', '20/[(s^2+s+10)]', '0.1/[(s^2+0.2s+0.03)]'};
            app.SecondorderDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondorderDropDownValueChanged, true);
            app.SecondorderDropDown.ClickedFcn = createCallbackFcn(app, @SecondorderDropDownClicked, true);
            app.SecondorderDropDown.Value = '2/[s(s+1)]';
            app.HighorderDropDownLabel = uilabel(app.GridLayout4);
            app.HighorderDropDownLabel.Text = 'High order';
            app.HighorderDropDown = uidropdown(app.GridLayout4);
            app.HighorderDropDown.Items = {'2/[s(s+1)(s+2)]', '20/[(s+1)(s+2)(s+4)]', '5(s+2)/[s(s+1)(s+4)]', '5(s+4)/[(s+1)(s+1)(s+2)]', '5(s+2)/[(s+1)(s^2+0.4s+16)]'};
            app.HighorderDropDown.ValueChangedFcn = createCallbackFcn(app, @HighorderDropDownValueChanged, true);
            app.HighorderDropDown.ClickedFcn = createCallbackFcn(app, @HighorderDropDownClicked, true);
            app.HighorderDropDown.Value = '2/[s(s+1)(s+2)]';
            app.DetailedanalysisoffirstordermodelparametersPanel = uipanel(app.GridLayout);
            app.DetailedanalysisoffirstordermodelparametersPanel.Title = 'Detailed analysis of margins, offset  and closed-loop behaviour (Proportional, Lead and Lead-lag) ';
            app.GridLayout5 = uigridlayout(app.DetailedanalysisoffirstordermodelparametersPanel);
            app.UIAxes2_2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2_2, 'Closed-loop output')
            xlabel(app.UIAxes2_2, 'Time (s)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2, 'Closed-loop input')
            xlabel(app.UIAxes2, 'Time (s)')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.CurrentphasemarginLabel = uilabel(app.GridLayout7);
            app.CurrentphasemarginLabel.Text = 'Current phase margin';
            app.GaincrossoverfrequencyLabel = uilabel(app.GridLayout7);
            app.GaincrossoverfrequencyLabel.Text = 'Gain cross over frequency';
            app.ClosedloopoffsetstepsLabel = uilabel(app.GridLayout7);
            app.ClosedloopoffsetstepsLabel.Text = 'Closed-loop offset (steps)';
            app.ClosedloopoffsetrampsLabel = uilabel(app.GridLayout7);
            app.ClosedloopoffsetrampsLabel.Text = 'Closed-loop offset (ramps)';
            app.CurrentmodelLabel = uilabel(app.GridLayout7);
            app.CurrentmodelLabel.Interpreter = 'latex';
            app.CurrentmodelLabel.Text = 'Current model ';
            app.GsLabel = uilabel(app.GridLayout7);
            app.GsLabel.Interpreter = 'latex';
            app.GsLabel.Text = 'G(s)';
            app.PhiLabel = uilabel(app.GridLayout7);
            app.PhiLabel.Interpreter = 'latex';
            app.PhiLabel.Text = 'Phi =';
            app.wcLabel = uilabel(app.GridLayout7);
            app.wcLabel.Text = 'wc =';
            app.OffsetLabel = uilabel(app.GridLayout7);
            app.OffsetLabel.Text = 'Offset = ';
            app.RamperrorLabel = uilabel(app.GridLayout7);
            app.RamperrorLabel.Text = 'Ramp error = ';
            app.ControlLawLabel = uilabel(app.GridLayout7);
            app.ControlLawLabel.Interpreter = 'latex';
            app.ControlLawLabel.Text = 'Control Law';
            app.MsLabel = uilabel(app.GridLayout7);
            app.MsLabel.Interpreter = 'latex';
            app.MsLabel.Text = 'M(s)';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SpecificationsLabel = uilabel(app.GridLayout6);
            app.SpecificationsLabel.Text = 'Specifications';
            app.PMSliderLabel = uilabel(app.GridLayout6);
            app.PMSliderLabel.Text = 'PM';
            app.PMSlider = uislider(app.GridLayout6);
            app.PMSlider.Limits = [30 80];
            app.PMSlider.ValueChangedFcn = createCallbackFcn(app, @PMSliderValueChanged, true);
            app.PMSlider.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.PMSlider.Value = 60;
            app.OffsetLabel_2 = uilabel(app.GridLayout6);
            app.OffsetLabel_2.Text = 'Bandwidth';
            app.BandwidthSlider = uislider(app.GridLayout6);
            app.BandwidthSlider.Limits = [0 6];
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSlider.Value = 1;
            app.OffsetSliderLabel = uilabel(app.GridLayout6);
            app.OffsetSliderLabel.Text = 'Offset (%)';
            app.OffsetSlider = uislider(app.GridLayout6);
            app.OffsetSlider.Limits = [0 30];
            app.OffsetSlider.ValueChangedFcn = createCallbackFcn(app, @OffsetSliderValueChanged, true);
            app.OffsetSlider.Value = 10;
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.ControlparametersLabel = uilabel(app.GridLayout9);
            app.ControlparametersLabel.Text = 'Control parameters';
            app.KpSliderLabel = uilabel(app.GridLayout9);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.GridLayout9);
            app.KpSlider.Limits = [0 5];
            app.KpSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpSlider.MinorTicks = [];
            app.KpSlider.Value = 1;
            app.polezeroratioleadSliderLabel = uilabel(app.GridLayout9);
            app.polezeroratioleadSliderLabel.Text = 'pole/zero ratio (lead)';
            app.polezeroratioleadSlider = uislider(app.GridLayout9);
            app.polezeroratioleadSlider.Limits = [0 10];
            app.polezeroratioleadSlider.ValueChangedFcn = createCallbackFcn(app, @polezeroratioleadSliderValueChanged, true);
            app.polezeroratioleadSlider.MinorTicks = [];
            app.polezeroratioleadSlider.Value = 1;
            app.polezeroratiolagSliderLabel = uilabel(app.GridLayout9);
            app.polezeroratiolagSliderLabel.Text = 'pole/zero ratio (lag)';
            app.polezeroratiolagSlider = uislider(app.GridLayout9);
            app.polezeroratiolagSlider.Limits = [0 10];
            app.polezeroratiolagSlider.ValueChangedFcn = createCallbackFcn(app, @polezeroratiolagSliderValueChanged, true);
            app.polezeroratiolagSlider.MinorTicks = [];
            app.polezeroratiolagSlider.Value = 1;
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout11);
            title(app.UIAxes, 'Bode gain diagrams')
            xlabel(app.UIAxes, 'Frequency (rad/s)')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout11);
            title(app.UIAxes_2, 'Phase diagram and phase margin (PM)')
            xlabel(app.UIAxes_2, 'Frequency (rad/s)')
            ylabel(app.UIAxes_2, 'Degrees')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_lag_design_phase_margin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end