classdef linearisation_Benchmark_control101 < matlab.apps.AppBase
    %LINEARISATION_BENCHMARK_CONTROL101
    %   This virtual laboratory allows users to investigate linearisation
    %   based on two 4th order models of non-linear mechatronic systems.
    %   Linearisation is done around a user specified operating point and
    %   behaviour is compared with the full nonlinear system.
    %   
    %   More detailed information is in the supporting livescript
    %   linearisation_Benchmark_control101_manual.mlx

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        GridLayout5                   matlab.ui.container.GridLayout
        DeletehistoryButton           matlab.ui.control.Button
        SimulateButton                matlab.ui.control.Button
        GridLayout4                   matlab.ui.container.GridLayout
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        PlantlinearisationapplicationLabel  matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        SignaltypeDropDown            matlab.ui.control.DropDown
        SignaltypeDropDownLabel       matlab.ui.control.Label
        BiasVSlider                   matlab.ui.control.Slider
        BiasVSliderLabel              matlab.ui.control.Label
        FundamentalfrequencyHzSlider  matlab.ui.control.Slider
        FundamentalfrequencyHzSliderLabel  matlab.ui.control.Label
        AmplitudeVSlider              matlab.ui.control.Slider
        AmplitudeVSliderLabel         matlab.ui.control.Label
        StimuliconfigurationLabel     matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        ComponentspeedrpmSlider       matlab.ui.control.Slider
        ComponentspeedrpmSliderLabel  matlab.ui.control.Label
        ComponentangledegreesSlider   matlab.ui.control.Slider
        ComponentangledegreesSliderLabel  matlab.ui.control.Label
        MotorspeedrpmSlider           matlab.ui.control.Slider
        MotorspeedrpmSliderLabel      matlab.ui.control.Label
        MotorcurrentASlider           matlab.ui.control.Slider
        MotorcurrentASliderLabel      matlab.ui.control.Label
        LinearisationandtrimconfigurationsLabel  matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        SimulationtimesSlider         matlab.ui.control.Slider
        SimulationtimesSliderLabel    matlab.ui.control.Label
        SamplingtimemsSlider          matlab.ui.control.Slider
        SamplingtimemsSliderLabel     matlab.ui.control.Label
        SelectedplantDropDown         matlab.ui.control.DropDown
        SelectedplantDropDownLabel    matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        SimulationpanelLabel          matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalsize % Description
        Experimenttext=cell(1)
        experimentCounter = 0
    end
    
    methods (Access = private)
        
        
        function  disablebuttons(app)
            set(app.SignaltypeDropDown, 'Enable', 'off');
            set(app.BiasVSlider, 'Enable', 'off');
            set(app.FundamentalfrequencyHzSlider, 'Enable', 'off');
            set(app.AmplitudeVSlider, 'Enable', 'off');
            set(app.ComponentangledegreesSlider, 'Enable', 'off');
            set(app.ComponentspeedrpmSlider, 'Enable', 'off');
            set(app.MotorcurrentASlider, 'Enable', 'off');
            set(app.MotorspeedrpmSlider, 'Enable', 'off');
            set(app.SimulationtimesSlider, 'Enable', 'off');
            set(app.SamplingtimemsSlider, 'Enable', 'off');
            set(app.SelectedplantDropDown, 'Enable', 'off');
            set(app.SimulateButton, 'Enable', 'off');
        end
        
        function enablebuttons(app)
            set(app.SignaltypeDropDown, 'Enable', 'on');
            set(app.BiasVSlider, 'Enable', 'on');
            set(app.FundamentalfrequencyHzSlider, 'Enable', 'on');
            set(app.AmplitudeVSlider, 'Enable', 'on');
            set(app.ComponentangledegreesSlider, 'Enable', 'on');
            set(app.ComponentspeedrpmSlider, 'Enable', 'on');
            set(app.MotorcurrentASlider, 'Enable', 'on');
            set(app.MotorspeedrpmSlider, 'Enable', 'on');
            set(app.SimulationtimesSlider, 'Enable', 'on');
            set(app.SamplingtimemsSlider, 'Enable', 'on');
            set(app.SelectedplantDropDown, 'Enable', 'on');
            set(app.SimulateButton, 'Enable', 'on');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.AutoResizeChildren='off';   
            movegui(app.UIFigure)
            
            % Open the manual
            if exist('linearisation_Benchmark_control101_manual_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open linearisation_Benchmark_control101_manual_mycopy.mlx
            else
                p1 = which('linearisation_Benchmark_control101_manual.mlx');
                p2 = strcat(pwd,'/linearisation_Benchmark_control101_manual_mycopy.mlx');
                copyfile(p1,p2)                
                open linearisation_Benchmark_control101_manual_mycopy.mlx
                clear p1 p2
            end

            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);
          

            try
                if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                else
                whitebg(app.UIFigure)
                end
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
           position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0;
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
            
        end

        % Button pushed function: DeletehistoryButton
        function DeletehistoryButtonPushed(app, event)
            app.Experimenttext = [];
            cla(app.UIAxes2);
            cla(app.UIAxes);
            cla(app.UIAxes3);
            app.experimentCounter = 0;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            disablebuttons(app)
            
            
                value = app.SignaltypeDropDown.Value;
                amplitude = app.AmplitudeVSlider.Value;
                frequency = app.FundamentalfrequencyHzSlider.Value;
                bias = app.BiasVSlider.Value;
    
                % reminder that sampling time is in ms
                steps = 1000*app.SimulationtimesSlider.Value/app.SamplingtimemsSlider.Value;
                time = linspace(0,app.SimulationtimesSlider.Value,steps+1);
                step_value = time(2)-time(1);
                
    
                control_input = 0;
                sim_output = [];

                switch value    % Ensure always greater than zero
                    case "Sine"
                    control_input = (amplitude/2) * sin( 2 * pi * frequency * time ) + ...
                        amplitude/2+bias;
                    case "Pulse"
                    control_input = amplitude * square( 2 * pi * frequency * time )  + bias;
                    case "Sawtooth" 
                    control_input = (amplitude/2) * sawtooth( 2 * pi * frequency * time ) + ...
                        +amplitude/2 + bias;
                end

                % Define the operating point
                x0 = [ app.MotorcurrentASlider.Value; app.MotorspeedrpmSlider.Value*2*pi/60; ...
                    app.ComponentspeedrpmSlider.Value*2*pi/60; deg2rad(app.ComponentangledegreesSlider.Value)];  % Example operating point for x

                switch app.SelectedplantDropDown.Value
                    case "Fan & Plate"          
                   % Parameter set and linear model
                   FanAndPlate_Data_control101;  % defines parameter values
                   sys=FanAndPlate_model_control101(x0);     
                   simIn = Simulink.SimulationInput('FanAndPlate_control101.slx');
                    
                    case "QNET VTOL"

                    % Parameter set and linear model
                    OneDofAeroVTOL_Data_control101; % defines parameter values
                    sys=OneDofAeroVTOL_model_control101(x0);
                    simIn = Simulink.SimulationInput('OneDofAeroVTOL_control101.slx');
                end
                      
                % Set up values and simulate
                    simIn = simIn.setVariable('Stoptime',app.SimulationtimesSlider.Value);
                    simIn = simIn.setVariable('SampleTime',step_value);
                    simIn = simIn.setVariable('initialcurrent',app.MotorcurrentASlider.Value);
                    simIn = simIn.setVariable('fanspeed',app.MotorspeedrpmSlider.Value*2*pi/60);
                    simIn = simIn.setVariable('initialplatespeed',app.ComponentspeedrpmSlider.Value*2*pi/60);
                    simIn = simIn.setVariable('initialplateangle',app.ComponentangledegreesSlider.Value*2*pi/360);
                    simIn = simIn.setVariable('input_data_gl',[time', control_input']);
                    nl_output = sim(simIn);
                    nl_output.continuous_output.signals.values = rad2deg(nl_output.continuous_output.signals.values);
                   

                % linear model simulation
                sim_output = lsim(sys,control_input,time,x0);
                sim_output = rad2deg(sim_output);


                % Save data
                app.experimentCounter = app.experimentCounter + 1;
                app.Experimenttext{app.experimentCounter} = ['Experiment ',num2str(app.experimentCounter)];
              
                % set default to keep history
                hold(app.UIAxes2, "on");
                hold(app.UIAxes, "on");

                % input plot
                b=plot(app.UIAxes, time, control_input);
                grid(app.UIAxes, "on");
                grid(app.UIAxes, "minor");
                                
                % overlay linear and non-linear
                a=stairs(app.UIAxes3, time, sim_output,'--','Color',b.Color);
                hold(app.UIAxes3, "on");
                plot(app.UIAxes3,nl_output.continuous_output.time,...
                     nl_output.continuous_output.signals.values,...
                     'Color',b.Color)
                legend(app.UIAxes3,'Linearised model','Non-linear model')
                hold(app.UIAxes3, "off")
                
                % Plot non-linear responses with history
                plot(app.UIAxes2,nl_output.continuous_output.time,...
                     nl_output.continuous_output.signals.values,...
                     'Color',b.Color);
                grid(app.UIAxes2, "on");
                grid(app.UIAxes2, "minor");
                legend(app.UIAxes2,app.Experimenttext);

     
                enablebuttons(app);
        end

        % Value changed function: MotorcurrentASlider
        function MotorcurrentASliderValueChanged(app, event)
            value = app.MotorcurrentASlider.Value;
            if value==0;
                app.MotorcurrentASlider.Value=0;
            end
        end

        % Value changed function: MotorspeedrpmSlider
        function MotorspeedrpmSliderValueChanged(app, event)
            value = app.MotorspeedrpmSlider.Value;
            value = app.MotorspeedrpmSlider.Value;
            if value==0;
                app.MotorspeedrpmSlider.Value=100;
            end
        end

        % Value changed function: ComponentspeedrpmSlider
        function ComponentspeedrpmSliderValueChanged(app, event)
            value = app.ComponentspeedrpmSlider.Value;
            value = app.ComponentspeedrpmSlider.Value;
            if value==0;
                app.ComponentspeedrpmSlider.Value=0;
            end
        end

        % Value changed function: AmplitudeVSlider
        function AmplitudeVSliderValueChanged(app, event)
            value = app.AmplitudeVSlider.Value;
             value = app.AmplitudeVSlider.Value;
            if value==0;
                app.AmplitudeVSlider.Value=1;
            end
        end

        % Value changed function: FundamentalfrequencyHzSlider
        function FundamentalfrequencyHzSliderValueChanged(app, event)
            value = app.FundamentalfrequencyHzSlider.Value;
            value = app.FundamentalfrequencyHzSlider.Value;
            if value==0;
                app.FundamentalfrequencyHzSlider.Value=0.1;
            end
        end

        % Value changed function: SamplingtimemsSlider
        function SamplingtimemsSliderValueChanged(app, event)
            value = app.SamplingtimemsSlider.Value;
            value = app.SamplingtimemsSlider.Value;
              if value==0; app.SamplingtimemsSlider.Value=5;end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Output signal')
            xlabel(app.UIAxes2, 'time (sec)')
            ylabel(app.UIAxes2, 'Position')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SimulationpanelLabel = uilabel(app.GridLayout3);
            app.SimulationpanelLabel.Text = 'Simulation panel';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'FanandPlate_small_size.jpg');
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'QNET-VTOL-Board.jpg');
            app.SelectedplantDropDownLabel = uilabel(app.GridLayout3);
            app.SelectedplantDropDownLabel.Text = 'Selected plant';
            app.SelectedplantDropDown = uidropdown(app.GridLayout3);
            app.SelectedplantDropDown.Items = {'Fan & Plate', 'QNET VTOL'};
            app.SelectedplantDropDown.Value = 'Fan & Plate';
            app.SamplingtimemsSliderLabel = uilabel(app.GridLayout3);
            app.SamplingtimemsSliderLabel.Text = 'Sampling time (ms)';
            app.SamplingtimemsSlider = uislider(app.GridLayout3);
            app.SamplingtimemsSlider.ValueChangedFcn = createCallbackFcn(app, @SamplingtimemsSliderValueChanged, true);
            app.SamplingtimemsSlider.Value = 10;
            app.SimulationtimesSliderLabel = uilabel(app.GridLayout3);
            app.SimulationtimesSliderLabel.Text = 'Simulation time (s)';
            app.SimulationtimesSlider = uislider(app.GridLayout3);
            app.SimulationtimesSlider.Limits = [5 20];
            app.SimulationtimesSlider.Value = 10;
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.LinearisationandtrimconfigurationsLabel = uilabel(app.GridLayout6);
            app.LinearisationandtrimconfigurationsLabel.Text = 'Linearisation and trim configurations';
            app.MotorcurrentASliderLabel = uilabel(app.GridLayout6);
            app.MotorcurrentASliderLabel.Text = 'Motor current (A)';
            app.MotorcurrentASlider = uislider(app.GridLayout6);
            app.MotorcurrentASlider.Limits = [0 2];
            app.MotorcurrentASlider.ValueChangedFcn = createCallbackFcn(app, @MotorcurrentASliderValueChanged, true);
            app.MotorspeedrpmSliderLabel = uilabel(app.GridLayout6);
            app.MotorspeedrpmSliderLabel.Text = 'Motor speed (rpm)';
            app.MotorspeedrpmSlider = uislider(app.GridLayout6);
            app.MotorspeedrpmSlider.Limits = [0 1000];
            app.MotorspeedrpmSlider.ValueChangedFcn = createCallbackFcn(app, @MotorspeedrpmSliderValueChanged, true);
            app.MotorspeedrpmSlider.Value = 500;
            app.ComponentangledegreesSliderLabel = uilabel(app.GridLayout6);
            app.ComponentangledegreesSliderLabel.Text = 'Component angle (degrees)';
            app.ComponentangledegreesSlider = uislider(app.GridLayout6);
            app.ComponentspeedrpmSliderLabel = uilabel(app.GridLayout6);
            app.ComponentspeedrpmSliderLabel.Text = 'Component speed (rpm)';
            app.ComponentspeedrpmSlider = uislider(app.GridLayout6);
            app.ComponentspeedrpmSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentspeedrpmSliderValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.StimuliconfigurationLabel = uilabel(app.GridLayout7);
            app.StimuliconfigurationLabel.Text = 'Stimuli configuration';
            app.AmplitudeVSliderLabel = uilabel(app.GridLayout7);
            app.AmplitudeVSliderLabel.Text = 'Amplitude [V]';
            app.AmplitudeVSlider = uislider(app.GridLayout7);
            app.AmplitudeVSlider.Limits = [0 5];
            app.AmplitudeVSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVSliderValueChanged, true);
            app.AmplitudeVSlider.Value = 3;
            app.FundamentalfrequencyHzSliderLabel = uilabel(app.GridLayout7);
            app.FundamentalfrequencyHzSliderLabel.Text = 'Fundamental frequency [Hz]';
            app.FundamentalfrequencyHzSlider = uislider(app.GridLayout7);
            app.FundamentalfrequencyHzSlider.Limits = [0 1];
            app.FundamentalfrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @FundamentalfrequencyHzSliderValueChanged, true);
            app.FundamentalfrequencyHzSlider.Value = 0.2;
            app.BiasVSliderLabel = uilabel(app.GridLayout7);
            app.BiasVSliderLabel.Text = 'Bias [V]';
            app.BiasVSlider = uislider(app.GridLayout7);
            app.BiasVSlider.Limits = [0 0.1];
            app.SignaltypeDropDownLabel = uilabel(app.GridLayout7);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.SignaltypeDropDown = uidropdown(app.GridLayout7);
            app.SignaltypeDropDown.Items = {'Sine', 'Pulse', 'Sawtooth'};
            app.SignaltypeDropDown.Value = 'Sine';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PlantlinearisationapplicationLabel = uilabel(app.GridLayout4);
            app.PlantlinearisationapplicationLabel.Text = 'Plant linearisation application';
            app.Image3 = uiimage(app.GridLayout4);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.Image4 = uiimage(app.GridLayout4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo_ITU.png');
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SimulateButton = uibutton(app.GridLayout5, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.DeletehistoryButton = uibutton(app.GridLayout5, 'push');
            app.DeletehistoryButton.ButtonPushedFcn = createCallbackFcn(app, @DeletehistoryButtonPushed, true);
            app.DeletehistoryButton.Text = 'Delete history';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Input signal')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'u(t)  volts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout8);
            title(app.UIAxes3, 'Linear and non-linear overlay')
            xlabel(app.UIAxes3, 'time (sec)')
            ylabel(app.UIAxes3, 'Position')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linearisation_Benchmark_control101

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end