classdef tank_and_manual_vs_PIcontrol_delay < matlab.apps.AppBase
    %TANK_AND_MANUAL_VS_PICONTROL_DELAY
    %   File written by J.A.Rossiter, University of Sheffield
    %   See website for more files like this.
    %   https://sites.google.com/sheffield.ac.uk/controleducation/mainindex
    %   A simple interface to practice behaviour of a simple tank
    %   level model subject to changes in in flow, disturbance flow and
    %   outlet blockage. The main intention here is to explore the
    %   differences between manual and automatic feedback.
    %       
    %   Some notes and engineering background are in the partner
    %   livescript  file:    tanklevel_and_manual_vs_PIcontrol_manual.mlx
    %   A group of similar files can be downloaded through MATLAB
    %   add-ons with the toolbox name:  control101
    %      
    %   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        ControlDropDown                matlab.ui.control.DropDown
        ControlDropDownLabel           matlab.ui.control.Label
        ManualSlider                   matlab.ui.control.Slider
        ManualSliderLabel              matlab.ui.control.Label
        GridLayout2_2                  matlab.ui.container.GridLayout
        IntegralKiSlider               matlab.ui.control.Slider
        IntegralKiSliderLabel          matlab.ui.control.Label
        ProportionalKpSlider           matlab.ui.control.Slider
        ProportionalKpSliderLabel      matlab.ui.control.Label
        ControlparametersCsKpfracKisLabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        TimingofdisturbancesDropDown   matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        OutletpipeblockageSlider       matlab.ui.control.Slider
        OutletpipeblockageSliderLabel  matlab.ui.control.Label
        Disturbanceflowm3sSlider       matlab.ui.control.Slider
        Disturbanceflowm3sSliderLabel  matlab.ui.control.Label
        EnvironmentparametersLabel     matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        DelaySlider                    matlab.ui.control.Slider
        DelaySliderLabel               matlab.ui.control.Label
        TankparametersAfracdhdtRhf_inLabel  matlab.ui.control.Label
        Rm2sSlider                     matlab.ui.control.Slider
        Rm2sSliderLabel                matlab.ui.control.Label
        Aream2Slider                   matlab.ui.control.Slider
        Aream2SliderLabel              matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        AnimationOnButton              matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        ControloftanklevelwithPILabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
    end

    
    % The next section defines all the core variables used in the code 
    % that it may be convenient to have available in the various
    % subfunctions
    properties (Access = private)
        originalsize     % product of width and height for font sizing

        legstring=cell(1); % To store legends
        A;     % tank area
        R;     % conductance of outflow pipe
        Rblock % effective R with blockage
        flow;  % inlet flow
        block  % to represent blockage component
        blockvalue % precentage blockage
        dist   % magnitude of disturbance in flow
        target = 8 % nominal target depth
        targetline1; % handle for target line on tank diagram
        targetline2; % handle for target line on output plots
        targetline3; % handle for target flow line
        flowinu % handle for flow-in in tank picture
        tankfill % blue colour to show depth in tank
        
        
        disturbanceflow;  % addtional unexpected flow in  
        distvec % stores time variation
        blockvec % stores time variation for blockage
        runtime = 50; % for simulation the runtime
        time;  % variable to store time instants for simulation plots
        samples = 601;
        animationtime = 6; % how long animation will take
        Tsamp=0.5;  % update rate for animation
        G;   % system transfer function (input to output)
        Kp   % proportional gain
        Ki   % integral gain
        
        ytotal % output due to target and disturbances
        utotal % input
        ucontrol =0 % from PI
        umanual =1 % from user
        umanualnew=1 % history
        
    end
    
    methods (Access = private)


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%  PLOT OF TOP FIGURE - THE CAR ANIMATION
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 function app = createtankoutline(app)

      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Set up basic picture of the tank in the top plot
     
        % tank parameters and initial plot
        app.A=app.Aream2Slider.Value;
        app.R=app.Rm2sSlider.Value;
        dist = app.Disturbanceflowm3sSlider.Value;
        A=app.A;
        R=app.R;
        depth = 0.3;
        
        % Tank outline and initial depth close to zero
        hold(app.UIAxes,'off')
        x2 = [0:11]; y2 = x2; 
        plot(app.UIAxes,x2,x2,'w');
        hold(app.UIAxes,'on')
        x=[0.5,0.5,0.5+A,0.5+A,1.5+A];
        x3 = [1.5+A,0.5+A,0.5+A];
        y=[10,1,1,1.02,1.02];
        y3 = [1.02+3*R,1.02+3*R,10];
        plot(app.UIAxes,x,y,'k-','linewidth',4);
        plot(app.UIAxes,x3,y3,'k-','linewidth',4);
        
        % outlet pipe fill
        xout = [0.5+A,0.5+A,1.5+A,1.7+A,1.8+A,1.5+A];
        yout = [1,1+3*R,1+3*R,1+2.5*R,0.9,1];
        outfill=fill(app.UIAxes,[xout],[yout],'b');
        
        % main tank fill
        xtankfill=[0.5,0.5,0.5+A,0.5+A];
        ytankfill=[1,1+depth,1+depth,1];
        app.tankfill=fill(app.UIAxes,[xtankfill],[ytankfill],'b');
        
                                
        % Disturbance in flow component
        xpipein = [1.5+app.A,0.4+app.A];
        ypipein = [10.4,10.4];
        xflowin1 = [1.5+app.A,0.4+app.A,0.4+app.A,1.5+app.A];
        yflowin1 = [10.4,10.4,10.8,10.8];
        xflowin2 = [0.1+app.A,0.1+app.A,0.4+app.A,0.4+app.A]; %% Use A as x distance
        yflowin2 = [10.5-dist*2,10.5+dist*2,10.8,10.4];
        pipein=plot(app.UIAxes,xpipein,ypipein,'k','linewidth',4);
        pipein2=plot(app.UIAxes,xpipein,ypipein+0.4,'k','linewidth',4);
        flowintop = fill(app.UIAxes,xflowin1,yflowin1,'b');
        flowinbottom = fill(app.UIAxes,xflowin2,yflowin2,'b');
        text(app.UIAxes,0.8+app.A,9.8,'Disturbance flow','fontsize',16)

        % main input flow from pipe above tank
        vertpipeinx1 = [0.3+app.A/2,0.3+app.A/2];
        vertpipeinx2 = [0.7+app.A/2,0.7+app.A/2];
        vertpipeiny1 = [11,12];
        plot(app.UIAxes,vertpipeinx1,vertpipeiny1,'k','linewidth',5);
        plot(app.UIAxes,vertpipeinx2,vertpipeiny1,'k','linewidth',5);
        fill(app.UIAxes,[vertpipeinx1,vertpipeinx2],...
            [vertpipeiny1,fliplr(vertpipeiny1)],'b');
        flowinux=[.3+app.A/2,0.3+app.A/2-app.flow*1.2+0.2,...
            0.7+app.A/2+app.flow*1.2-0.2,0.7+app.A/2];
        
        flowinuy=[11,10,10,11];
        app.flowinu=fill(app.UIAxes,[flowinux],flowinuy,'b');
        text(app.UIAxes,0.8+app.A/2,11.5,'Main in-flow','fontsize',16)

        % Define the blockage shape       
        % define height to cover relevant % of the outlet
        gap = 3*R*(100-app.OutletpipeblockageSlider.Value)/100;
        x3=1.5+app.A; % right position of tank
        blockx=[x3+0.8,x3-0.05,x3-0.05,x3+0.8];
        blocky=[1+gap,1+gap,3+gap,3+gap];
        app.block=fill(app.UIAxes,blockx,blocky,'r');
        text(app.UIAxes,x3,0.4,'Blockage','fontsize',16)
        
        ylim(app.UIAxes,[0,12])

        % target line in output plot
        app.targetline1 = plot(app.UIAxes,[0,2+A],[1,1]*(1+app.target),'g--','linewidth',2);
                    
    end      %%% END OF TANKOUTLINE SUBFUNCTION

 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%  Down load data from sliders and create models and responses
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function app = createmodels(app)

            % Collect values from sliders and ensure rounded sensibly
            app.A=app.Aream2Slider.Value;
            app.R=app.Rm2sSlider.Value;
            app.blockvalue=app.OutletpipeblockageSlider.Value;
            app.Rblock=app.R*(100-app.blockvalue)/100; % effective R

            app.runtime=round(app.A/app.Rblock,0)*10;
            app.Tsamp = app.runtime/(app.samples-1);
            app.time=linspace(0,app.runtime,app.samples);
                   
        end   % end of create models subfunction


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%  Manage the animation and legends
%%%%%%%%%  Animation can only be switched of in PI mode
%%%
%%%  note the delay means the display is always behind the actual
%%%  simulation
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     
        function app = animationoflineplots(app)
            
             delay=app.DelaySlider.Value;
             
            %  Set up legend only if starting from scratch
            if isempty(app.legstring{1});
                nlegstring=1;
                app.targetline2=plot(app.UIAxes2,[0,app.runtime],[1 1]*app.target,'g--','linewidth',2);
                app.legstring{1} = 'Target depth and expected steady-state flow';
                hold(app.UIAxes2,'on');
                app.targetline3 = plot(app.UIAxes3,[0,app.runtime],[1 1]*app.target*app.R,'g--','linewidth',2);
                hold(app.UIAxes3,'on');
                else
                nlegstring = length(app.legstring);
            end
            
            app.legstring{nlegstring+1}=['A = ',num2str(app.A),', R = ',num2str(app.R),...
                 ', Blockage (%) = ',num2str(app.blockvalue),', Disturbance = ',num2str(app.dist),...
                 ', Kp = ', num2str(app.Kp),', Ki = ',num2str(app.Ki),', Delay = ',num2str(delay)];
            switch app.ControlDropDown.Value
                    case 'PI';
                        app.legstring{nlegstring+1}=[app.legstring{nlegstring+1},', PI'];
                    case 'Manual'
                         app.legstring{nlegstring+1}=[app.legstring{nlegstring+1},', Manual'];
            end
            
            % initialise figure for depth response and update target lines
            hold(app.UIAxes3_2,'off') % Clear disturbance figure
            plot(app.UIAxes3_2,0,0)
            yplot = plot(app.UIAxes2,0,0);
            xlabel(app.UIAxes2,'time (sec)')
            xlim(app.UIAxes2,[0,app.runtime]);
            kk=legend(app.UIAxes2,app.legstring,'Location','southeast');
            set(kk,'fontsize',8);
            grid(app.UIAxes2,'on')
            set(app.targetline2,'Xdata',[0,app.runtime],'Ydata',[1 1]*app.target);
            %set(app.targetline3,'Xdata',[0,app.runtime],'Ydata',[1 1]*app.target*app.Rblock);
            set(app.targetline1,'Ydata',[1,1]*(1+app.target));

            % initialise figure for flow response
            uplot = plot(app.UIAxes3,0,0);          
            xlabel(app.UIAxes3,'time (sec)')
            hold(app.UIAxes3,'on')
            distplot = plot(app.UIAxes3_2,0,0,'r');
            hold(app.UIAxes3_2,'on')
            blockplot = plot(app.UIAxes3_2,0,0,'b');
            xlim(app.UIAxes3,[0,app.runtime]);
            grid(app.UIAxes3,'on')
            %legend(app.UIAxes3,'Expected ss','Controlled flow')
            legend(app.UIAxes3_2,'Disturbance flow','Blockage')
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%% animation of tank and level plot
            
           
            inte=zeros(1,delay+1);
            e=inte;  % integral counter 1 out of sync
            app.ytotal=inte;
            

            for k=delay+1:length(app.time);  % Begin later than zero to allow for delay

                % update model due to blockage changes
                Rblock=app.R*(100-app.blockvec(k))/100;
                alpha = 1-(app.Tsamp*Rblock/app.A);

                % update control law
                e(k)=app.target-app.ytotal(k-delay);  % note use of delayed output
                inte(k+1)=inte(k)+e(k)*app.Tsamp;  % integral of error
                app.ucontrol(k)=app.Kp*e(k) + app.Ki*inte(k+1);  % PI law
                
                % update manual control
                app.umanualnew;
                app.umanual(k) = app.umanualnew;

                switch app.ControlDropDown.Value
                    case 'PI';
                        app.utotal(k)=app.ucontrol(k);
                    case 'Manual'
                        app.utotal(k)=app.umanualnew;
                end

                % Update depth using a simple discrete integration
                d(k)=app.dist;
                app.ytotal(k+1)=alpha*app.ytotal(k)+(app.utotal(k)+app.distvec(k))*app.Tsamp/app.A;

                % update line plot and tank picture
                set(yplot,'Ydata',app.ytotal(1:k-delay),'Xdata',app.time(1:k-delay));
                set(uplot,'Ydata',app.utotal(1:k),'Xdata',app.time(1:k));
                set(distplot,'Ydata',app.distvec(1:k-delay),'Xdata',app.time(1:k-delay));
                set(blockplot,'Ydata',app.blockvec(1:k-delay)/100,'Xdata',app.time(1:k-delay));
                ytankfill = [1,1+app.ytotal(k-delay),1+app.ytotal(k-delay),1];
                set(app.tankfill,'Ydata',[ytankfill]);
                flowinux=[.3+app.A/2,0.3+app.A/2-app.utotal(k)*0.4+0.2,...
                0.7+app.A/2+app.utotal(k)*0.4-0.2,0.7+app.A/2];
                set(app.flowinu,'Xdata',flowinux);
                gap = 3*app.R*(100-app.blockvec(k-delay))/100;  
                blocky=[1+gap,1+gap,3+gap,3+gap];
                set(app.block,'Ydata',blocky);
               
                % manage the animation
                if or(strcmp(app.AnimationOnButton.Text,'Animation On'),strcmp(app.ControlDropDown.Value,'Manual'));
                pause(0.05);
                end
                drawnow limitrate
                
                
            end
            
        end  % end of animation


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % This function determines all the closed-loop UNIT step responses
            % Based on current values of the sliders

            function app = closed_loop_responses(app)
      
            % collect compensator and tank values from the sliders
            % round values to avoid unnecessary decimal places
            app.Kp=app.ProportionalKpSlider.Value;
            app.Ki=app.IntegralKiSlider.Value;
            % PI =tf([app.Kp,app.Ki],[1 0]);
            
            
            % Collect disturbance data from sliders
            app.dist=app.Disturbanceflowm3sSlider.Value;
            app.blockvalue = app.OutletpipeblockageSlider.Value;
 
            % define the disturbance and blockage profiles
            switch app.TimingofdisturbancesDropDown.Value
                case 'At t=0';
                     app.distvec=ones(1,app.samples)*app.dist;
                     app.blockvec=ones(1,app.samples)*app.blockvalue;
                case 'Mid-simulation disturbance'
                    tt = round(app.samples/2);
                    tt1 = tt:app.samples; 
                    tt2 = 1:(app.samples-tt+1); 
                    app.distvec=[zeros(1,tt),ones(1,app.samples-tt+1)*app.dist];
                    tt = round(app.samples/3);
                    tt1 = tt:app.samples; 
                    tt2 = 1:(app.samples-tt+1); 
                    app.blockvec=[zeros(1,tt),ones(1,app.samples-tt+1)*app.blockvalue];
                case 'Multiple'
                    tt = round(app.samples/4);
                    tt1 = tt:app.samples; 
                    tt2 = 1:(app.samples-tt+1); 
                    app.distvec=[zeros(1,tt),ones(1,tt)*app.dist,...
                        ones(1,tt)*app.dist*0.2,ones(1,app.samples-3*tt+1)*app.dist*1.1];
                    tt = round(app.samples/5);
                    tt1 = tt:app.samples; 
                    tt2 = 1:(app.samples-tt+1); 
                    app.blockvec=[zeros(1,tt),ones(1,tt)*app.blockvalue*0.5,...
                        ones(1,tt)*app.blockvalue*0.8,ones(1,app.samples-3*tt+1)*app.blockvalue*0.4];
            end
            
            end   % end of responses subfunction


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%  Control interaction during the animation
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function  disablebuttons(app)
            set(app.SimulateButton, 'Enable', 'off');
            set(app.RefreshButton, 'Enable', 'off');
            set(app.AnimationOnButton, 'Enable', 'off');
            set(app.OutletpipeblockageSlider, 'Enable', 'off');
            set(app.Disturbanceflowm3sSlider, 'Enable', 'off');
            set(app.ProportionalKpSlider, 'Enable', 'off')
            set(app.IntegralKiSlider, 'Enable', 'off')
            set(app.Rm2sSlider, 'Enable', 'off');
            set(app.Aream2Slider, 'Enable', 'off');
            set(app.TimingofdisturbancesDropDown,'Enable','off')
        end
        
        function enablebuttons(app)
            set(app.SimulateButton, 'Enable', 'on');
            set(app.RefreshButton, 'Enable', 'on')
            set(app.AnimationOnButton, 'Enable', 'on')
            set(app.OutletpipeblockageSlider, 'Enable', 'on');
            set(app.Disturbanceflowm3sSlider, 'Enable', 'on');
            set(app.Rm2sSlider, 'Enable', 'on');
            set(app.Aream2Slider, 'Enable', 'on');
            set(app.ProportionalKpSlider, 'Enable', 'on')
            set(app.IntegralKiSlider, 'Enable', 'on')
            set(app.TimingofdisturbancesDropDown,'Enable','on')
        end

        
        function app = refreshmanual(app,data)
            app.umanualnew=data;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % resize to be in middle of  the window
            movegui(app.UIFigure)
            
            % Open the manual
            if exist('tank_and_manual_vs_PIcontrol_delay_manual_mycopy.mlx','file')
                disp('Opening existing copy - delete or rename this copy to access toolbox orginal')
                open tank_and_manual_vs_PIcontrol_delay_manual_mycopy.mlx
            else
                p1 = which('tank_and_manual_vs_PIcontrol_delay_manual.mlx');
                p2 = strcat(pwd,'/tank_and_manual_vs_PIcontrol_delay_manual_mycopy.mlx');
                copyfile(p1,p2)                
                open tank_and_manual_vs_PIcontrol_delay_manual_mycopy.mlx
                clear p1 p2
            end

            % variables to manage re-sizing. Save values at start up
            position = app.UIFigure.Position;
            app.originalsize = position(3)*position(4);

            app.flow=0;  % to initialise plots
            app = createtankoutline(app); % define car outline for animation
            try
                if ~isMATLABReleaseOlderThan('R2025a') %%(as in it is newer than 25a)
                theme(app.UIFigure,"light")
                else
                whitebg(app.UIFigure)
                end
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            disablebuttons(app);

            app = createmodels(app); % define responses
            app = createtankoutline(app); % define car outline for animation
            app = closed_loop_responses(app); % create responses
            app = animationoflineplots(app); % manage the animations

            enablebuttons(app);

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %% Clean all the figures and legend so user can begin afresh
             hold(app.UIAxes3,'off')
             hold(app.UIAxes2,'off')
             hold(app.UIAxes3_2,'off')
             plot(app.UIAxes3,0,0)
             plot(app.UIAxes2,0,0)
             plot(app.UIAxes3_2,0,0)
             app.legstring=cell(1);
        end

        % Callback function
        function AnimationOnButtonPushed(app, event)
            val = app.AnimationOnButton.Text;
            if strcmp(val,'Animation On');
                val = 'Animation Off';
            else
                val = 'Animation On';
            end
            app.AnimationOnButton.Text=val;
        end

        % Callback function
        function Flowinm3sSliderValueChanged(app, event)
            value = app.Flowinm3sSlider.Value;
            value = round(value,1);
            if value<0.1;value=0.1;end
            app.Flowinm3sSlider.Value=value;
            app = createtankoutline(app);

        end

        % Value changed function: Rm2sSlider
        function Rm2sSliderValueChanged(app, event)
            value = app.Rm2sSlider.Value;
            value=round(value,1); 
            if value<0.1;value=0.1;end
            app.Rm2sSlider.Value=value;
            app = createtankoutline(app);
        end

        % Value changed function: Aream2Slider
        function Aream2SliderValueChanged(app, event)
            value = app.Aream2Slider.Value;
            value=round(value,0);
            if value<1;value=1;end
            app.Aream2Slider.Value=value;
            app = createtankoutline(app);
           
        end

        % Value changed function: OutletpipeblockageSlider
        function OutletpipeblockageSliderValueChanged(app, event)
            value = app.OutletpipeblockageSlider.Value;
            value=round(value,-1);
            if value>90;value=90;end
            app.OutletpipeblockageSlider.Value=value;
            app = createtankoutline(app);
            
        end

        % Value changed function: Disturbanceflowm3sSlider
        function Disturbanceflowm3sSliderValueChanged(app, event)
            value = app.Disturbanceflowm3sSlider.Value;
            value = round(value*2,1)/2;
            app.Disturbanceflowm3sSlider.Value=value;
            app = createtankoutline(app);
            
        end

        % Value changed function: ProportionalKpSlider
        function ProportionalKpSliderValueChanged(app, event)
            value = app.ProportionalKpSlider.Value;
            value=round(value,2);
            app.ProportionalKpSlider.Value=value;
        end

        % Value changed function: IntegralKiSlider
        function IntegralKiSliderValueChanged(app, event)
            value = app.IntegralKiSlider.Value;
            value = round(value*500)/500;
            app.IntegralKiSlider.Value=value;
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
                                 
           % Determine the current size
           newSize = position(3) * position(4);
           numberofchanges = length(app.originalsize);
           
           % Do not use these lines at start up
           if numberofchanges>0;
           app.originalsize(numberofchanges+1)=newSize;
           currentsize = app.originalsize(numberofchanges);
           % get the resize factor using root
           resizeFactor = sqrt(newSize/currentsize);
          
           % get the handles of object, which haven the property about font size
           h = findobj(app.UIFigure, '-property', 'FontSize');
           hFontSize = cell2mat(get(h, 'FontSize'));
           % get the current font size and change the font size using resize factor
           newFontSize =  hFontSize * resizeFactor;
           % set the new font size back
           set(h,{'FontSize'}, num2cell(newFontSize));
           end
        end

        % Value changing function: ManualSlider
        function ManualSliderValueChanging(app, event)
            changingValue = event.Value;
            app=refreshmanual(app,changingValue);
        end

        % Button pushed function: AnimationOnButton
        function AnimationOnButtonPushed2(app, event)
            val = app.AnimationOnButton.Text;
            if strcmp(val,'Animation On');
                val = 'Animation Off';
            else
                val = 'Animation On';
            end
            app.AnimationOnButton.Text=val;
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            value = app.DelaySlider.Value;
            value = round(value);  % round to nearest sample (Delay measured in samples)
            app.DelaySlider.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3_2 = uiaxes(app.GridLayout);
            title(app.UIAxes3_2, 'Disturbances')
            xlabel(app.UIAxes3_2, 'Time (s)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Input flow responses')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Flow (m^3/s)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Tank level responses')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Depth (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Animation of tank level')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ControloftanklevelwithPILabel = uilabel(app.GridLayout2);
            app.ControloftanklevelwithPILabel.Text = 'Control of tank level with PI';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UoSlogo.png');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.AnimationOnButton = uibutton(app.GridLayout3, 'push');
            app.AnimationOnButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationOnButtonPushed2, true);
            app.AnimationOnButton.Text = 'Animation On';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Aream2SliderLabel = uilabel(app.GridLayout4);
            app.Aream2SliderLabel.Text = 'Area (m^2)';
            app.Aream2Slider = uislider(app.GridLayout4);
            app.Aream2Slider.Limits = [0 10];
            app.Aream2Slider.ValueChangedFcn = createCallbackFcn(app, @Aream2SliderValueChanged, true);
            app.Aream2Slider.MinorTicks = [500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000];
            app.Aream2Slider.Value = 5;
            app.Rm2sSliderLabel = uilabel(app.GridLayout4);
            app.Rm2sSliderLabel.Text = 'R (m^2/s)';
            app.Rm2sSlider = uislider(app.GridLayout4);
            app.Rm2sSlider.Limits = [0 0.5];
            app.Rm2sSlider.ValueChangedFcn = createCallbackFcn(app, @Rm2sSliderValueChanged, true);
            app.Rm2sSlider.MinorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Rm2sSlider.Value = 0.2;
            app.TankparametersAfracdhdtRhf_inLabel = uilabel(app.GridLayout4);
            app.TankparametersAfracdhdtRhf_inLabel.Interpreter = 'latex';
            app.TankparametersAfracdhdtRhf_inLabel.Text = 'Tank parameters: A\frac{dh}{dt} + R h =  f_{in}';
            app.DelaySliderLabel = uilabel(app.GridLayout4);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.GridLayout4);
            app.DelaySlider.Limits = [0 10];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.DelaySlider.MinorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.DelaySlider.Value = 5;
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.EnvironmentparametersLabel = uilabel(app.GridLayout6);
            app.EnvironmentparametersLabel.Text = 'Environment parameters';
            app.Disturbanceflowm3sSliderLabel = uilabel(app.GridLayout6);
            app.Disturbanceflowm3sSliderLabel.Text = 'Disturbance flow (m^3/s)';
            app.Disturbanceflowm3sSlider = uislider(app.GridLayout6);
            app.Disturbanceflowm3sSlider.Limits = [0 0.6];
            app.Disturbanceflowm3sSlider.ValueChangedFcn = createCallbackFcn(app, @Disturbanceflowm3sSliderValueChanged, true);
            app.Disturbanceflowm3sSlider.MinorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Disturbanceflowm3sSlider.Value = 0.4;
            app.OutletpipeblockageSliderLabel = uilabel(app.GridLayout6);
            app.OutletpipeblockageSliderLabel.Text = 'Outlet pipe blockage (%)';
            app.OutletpipeblockageSlider = uislider(app.GridLayout6);
            app.OutletpipeblockageSlider.ValueChangedFcn = createCallbackFcn(app, @OutletpipeblockageSliderValueChanged, true);
            app.OutletpipeblockageSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.OutletpipeblockageSlider.Value = 60;
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = 'Timing of disturbance';
            app.TimingofdisturbancesDropDown = uidropdown(app.GridLayout6);
            app.TimingofdisturbancesDropDown.Items = {'At t=0', 'Mid-simulation disturbance', 'Multiple'};
            app.TimingofdisturbancesDropDown.Value = 'Multiple';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.ControlparametersCsKpfracKisLabel = uilabel(app.GridLayout2_2);
            app.ControlparametersCsKpfracKisLabel.Interpreter = 'latex';
            app.ControlparametersCsKpfracKisLabel.Text = 'Control parameters: C(s) = Kp + \frac{Ki}{s}';
            app.ProportionalKpSliderLabel = uilabel(app.GridLayout2_2);
            app.ProportionalKpSliderLabel.Text = 'Proportional (Kp)';
            app.ProportionalKpSlider = uislider(app.GridLayout2_2);
            app.ProportionalKpSlider.Limits = [0 1];
            app.ProportionalKpSlider.ValueChangedFcn = createCallbackFcn(app, @ProportionalKpSliderValueChanged, true);
            app.ProportionalKpSlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.ProportionalKpSlider.Value = 0.2;
            app.IntegralKiSliderLabel = uilabel(app.GridLayout2_2);
            app.IntegralKiSliderLabel.Text = 'Integral (Ki)';
            app.IntegralKiSlider = uislider(app.GridLayout2_2);
            app.IntegralKiSlider.Limits = [0 0.2];
            app.IntegralKiSlider.ValueChangedFcn = createCallbackFcn(app, @IntegralKiSliderValueChanged, true);
            app.IntegralKiSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145 0.15 0.155 0.16 0.165 0.17 0.175 0.18 0.185 0.19 0.195 0.2];
            app.IntegralKiSlider.Value = 0.02;
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.ManualSliderLabel = uilabel(app.GridLayout7);
            app.ManualSliderLabel.Text = 'Manual';
            app.ManualSlider = uislider(app.GridLayout7);
            app.ManualSlider.Limits = [0 4];
            app.ManualSlider.ValueChangingFcn = createCallbackFcn(app, @ManualSliderValueChanging, true);
            app.ManualSlider.Value = 1;
            app.ControlDropDownLabel = uilabel(app.GridLayout7);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.GridLayout7);
            app.ControlDropDown.Items = {'PI', 'Manual'};
            app.ControlDropDown.Value = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tank_and_manual_vs_PIcontrol_delay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end